classdef retrospective < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RetrospectiveUIFigure       matlab.ui.Figure
        RetrospectiveCreditLabel    matlab.ui.control.Label
        Hyperlink                   matlab.ui.control.Hyperlink
        TabGroup0                   matlab.ui.container.TabGroup
        DashboardTab                matlab.ui.container.Tab
        AutoRecoLabel               matlab.ui.control.Label
        AutoRecoCheckBox            matlab.ui.control.CheckBox
        Lamp3                       matlab.ui.control.Lamp
        BusyImage3                  matlab.ui.control.Image
        CinePanel                   matlab.ui.container.Panel
        nrDynamicsLabel             matlab.ui.control.Label
        DynamicsEditField           matlab.ui.control.NumericEditField
        RecoTypeDropDown            matlab.ui.control.DropDown
        movieDropDownLabel          matlab.ui.control.Label
        FramesEditField             matlab.ui.control.NumericEditField
        CINEframesLabel             matlab.ui.control.Label
        SortKspaceButton            matlab.ui.control.Button
        Lamp7                       matlab.ui.control.Lamp
        BusyImage7                  matlab.ui.control.Image
        ExportMRDButton             matlab.ui.control.Button
        Lamp6                       matlab.ui.control.Lamp
        BusyImage6                  matlab.ui.control.Image
        Lamp5                       matlab.ui.control.Lamp
        BusyImage5                  matlab.ui.control.Image
        Lamp2                       matlab.ui.control.Lamp
        BusyImage2                  matlab.ui.control.Image
        Lamp1                       matlab.ui.control.Lamp
        BusyImage1                  matlab.ui.control.Image
        Lamp4                       matlab.ui.control.Lamp
        BusyImage4                  matlab.ui.control.Image
        ExportGIFButton             matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        ExportDCMButton             matlab.ui.control.Button
        CSrecoparametersPanel       matlab.ui.container.Panel
        LLRxyzEditField             matlab.ui.control.NumericEditField
        LLRxyzEditFieldLabel        matlab.ui.control.Label
        TVdynEditField              matlab.ui.control.NumericEditField
        TVdynEditFieldLabel         matlab.ui.control.Label
        ESPIRiTLabel                matlab.ui.control.Label
        ESPIRiTCheckBox             matlab.ui.control.CheckBox
        WVxyzEditField              matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel         matlab.ui.control.Label
        ProgressGauge               matlab.ui.control.LinearGauge
        TVxyzEditField              matlab.ui.control.NumericEditField
        TVxyzEditFieldLabel         matlab.ui.control.Label
        TVcineEditField             matlab.ui.control.NumericEditField
        TVcineEditFieldLabel        matlab.ui.control.Label
        ReconstructButton           matlab.ui.control.Button
        FilterButton                matlab.ui.control.Button
        DetectedratesBPMPanel       matlab.ui.container.Panel
        ResetFilterValuesButton     matlab.ui.control.Button
        WidthLabel                  matlab.ui.control.Label
        CenterLabel                 matlab.ui.control.Label
        HeartWidthEditField         matlab.ui.control.NumericEditField
        RespirationWidthEditField   matlab.ui.control.NumericEditField
        RespirationWindowEditField  matlab.ui.control.NumericEditField
        windowLabel                 matlab.ui.control.Label
        HeartEditField              matlab.ui.control.NumericEditField
        heartbpmLabel_3             matlab.ui.control.Label
        RespirationEditField        matlab.ui.control.NumericEditField
        respbpmLabel_3              matlab.ui.control.Label
        LoadMRDButton               matlab.ui.control.Button
        TabGroup1                   matlab.ui.container.TabGroup
        NavigatorTab                matlab.ui.container.Tab
        NavigatorFlipSwitch         matlab.ui.control.ToggleSwitch
        FlipSwitchLabel             matlab.ui.control.Label
        LogLinButton                matlab.ui.control.Button
        CoilLabel                   matlab.ui.control.Label
        CoilSpinner                 matlab.ui.control.Spinner
        SpectrumFig                 matlab.ui.control.UIAxes
        RawNavFig                   matlab.ui.control.UIAxes
        AdvancedsettingsTab         matlab.ui.container.Tab
        InExpirationToggleLabel     matlab.ui.control.Label
        RespirationToggleCheckBox   matlab.ui.control.CheckBox
        RespirationLabel            matlab.ui.control.Label
        SharingLabel                matlab.ui.control.Label
        SharingEditField            matlab.ui.control.NumericEditField
        framesLabel                 matlab.ui.control.Label
        PresetsLabel2               matlab.ui.control.Label
        SpeciesButton2              matlab.ui.container.ButtonGroup
        RatButton2                  matlab.ui.control.RadioButton
        MouseButton2                matlab.ui.control.RadioButton
        NavigatorNRPointsEditField  matlab.ui.control.NumericEditField
        pointsLabel                 matlab.ui.control.Label
        NavigatorEndEditField       matlab.ui.control.NumericEditField
        primaryLabel                matlab.ui.control.Label
        NavigatorLabel              matlab.ui.control.Label
        FreqNxdiscardLabel          matlab.ui.control.Label
        DiscardEditField            matlab.ui.control.NumericEditField
        FilterOrderEditField        matlab.ui.control.NumericEditField
        filterorderEditFieldLabel   matlab.ui.control.Label
        MaxHeartRateEditField       matlab.ui.control.NumericEditField
        maxheartrateEditFieldLabel  matlab.ui.control.Label
        MinHeartRateEditField       matlab.ui.control.NumericEditField
        minheartrateEditFieldLabel  matlab.ui.control.Label
        MaxRespRateEditField        matlab.ui.control.NumericEditField
        maxresprateEditFieldLabel   matlab.ui.control.Label
        MinRespRateEditField        matlab.ui.control.NumericEditField
        minresprateEditFieldLabel   matlab.ui.control.Label
        PhysioFilteringLabel        matlab.ui.control.Label
        TabGroup2                   matlab.ui.container.TabGroup
        NavigatoranalysisTab        matlab.ui.container.Tab
        RangeEditField              matlab.ui.control.NumericEditField
        rangeEditFieldLabel         matlab.ui.control.Label
        StartEditField              matlab.ui.control.NumericEditField
        startEditFieldLabel         matlab.ui.control.Label
        NavFastSlowSwitch           matlab.ui.control.RockerSwitch
        NavStopButton               matlab.ui.control.Button
        NavStartButton              matlab.ui.control.Button
        ExcludeLabel                matlab.ui.control.Label
        ExcludeEndSlider            matlab.ui.control.Slider
        ExcludeStartSlider          matlab.ui.control.Slider
        ExcludeCheckBox             matlab.ui.control.CheckBox
        IncludeLabel                matlab.ui.control.Label
        IncludeEndSlider            matlab.ui.control.Slider
        IncludeStartSlider          matlab.ui.control.Slider
        IncludeCheckBox             matlab.ui.control.CheckBox
        RangeSlider                 matlab.ui.control.Slider
        StartSlider                 matlab.ui.control.Slider
        DisplayRangeLabel           matlab.ui.control.Label
        RespRateTimeFig             matlab.ui.control.UIAxes
        HeartRateTimeFig            matlab.ui.control.UIAxes
        RespirationNavFig           matlab.ui.control.UIAxes
        CardiacNavFig               matlab.ui.control.UIAxes
        TabGroup3                   matlab.ui.container.TabGroup
        AcquisitionparametersTab    matlab.ui.container.Tab
        FAViewField                 matlab.ui.control.NumericEditField
        flipanglesLabel             matlab.ui.control.Label
        TimeDynViewField            matlab.ui.control.EditField
        timedynamicLabel            matlab.ui.control.Label
        ScanTimeViewField           matlab.ui.control.EditField
        scantimeEditFieldLabel      matlab.ui.control.Label
        NcViewField                 matlab.ui.control.NumericEditField
        coilsNcLabel                matlab.ui.control.Label
        NzViewField                 matlab.ui.control.NumericEditField
        slicesNzLabel               matlab.ui.control.Label
        TrajectoryViewField         matlab.ui.control.EditField
        trajectoryEditFieldLabel    matlab.ui.control.Label
        TRViewField                 matlab.ui.control.NumericEditField
        TRmsLabel                   matlab.ui.control.Label
        FillingViewField            matlab.ui.control.NumericEditField
        fillingLabel                matlab.ui.control.Label
        AveragesViewField           matlab.ui.control.NumericEditField
        averagesEditFieldLabel      matlab.ui.control.Label
        NrKlinesViewField           matlab.ui.control.NumericEditField
        klinesLabel                 matlab.ui.control.Label
        RepetitionsViewField        matlab.ui.control.NumericEditField
        repetitionsLabel            matlab.ui.control.Label
        kLinesRepetitionViewField   matlab.ui.control.NumericEditField
        sizeLabel                   matlab.ui.control.Label
        NxViewField                 matlab.ui.control.NumericEditField
        freqNxLabel                 matlab.ui.control.Label
        NyViewField                 matlab.ui.control.NumericEditField
        phaseNyLabel                matlab.ui.control.Label
        DatafileLabel               matlab.ui.control.Label
        DataFile                    matlab.ui.control.TextArea
        TrajectoryFig               matlab.ui.control.UIAxes
        PresetsTab                  matlab.ui.container.Tab
        AutoSoundsCheckBox          matlab.ui.control.CheckBox
        AutoGuessRecoCheckBox       matlab.ui.control.CheckBox
        OtherLabel                  matlab.ui.control.Label
        ReconstructionpresetsLabel  matlab.ui.control.Label
        RecoPresetsButtonGroup      matlab.ui.container.ButtonGroup
        VfaFunctionButton           matlab.ui.control.RadioButton
        RealTimeFunctionButton      matlab.ui.control.RadioButton
        RespiratoryFunctionButton   matlab.ui.control.RadioButton
        DiastolicFunctionButton     matlab.ui.control.RadioButton
        SystolicFunctionButton      matlab.ui.control.RadioButton
        ScoutButton                 matlab.ui.control.RadioButton
        SpeciesLabel                matlab.ui.control.Label
        SpeciesButton               matlab.ui.container.ButtonGroup
        RatButton                   matlab.ui.control.RadioButton
        MouseButton                 matlab.ui.control.RadioButton
        TabGroup4                   matlab.ui.container.TabGroup
        MovieTab                    matlab.ui.container.Tab
        DynamicLabel                matlab.ui.control.Label
        MovieDynEditField           matlab.ui.control.NumericEditField
        LevelScaleSlider            matlab.ui.control.Slider
        LLabel                      matlab.ui.control.Label
        WindowScaleSlider           matlab.ui.control.Slider
        WLabel                      matlab.ui.control.Label
        MovieSliceEditField         matlab.ui.control.NumericEditField
        sliceLabel                  matlab.ui.control.Label
        FinalRespirationViewField   matlab.ui.control.NumericEditField
        respbpmLabel_2              matlab.ui.control.Label
        FinalHeartViewField         matlab.ui.control.NumericEditField
        heartbpmLabel_2             matlab.ui.control.Label
        MovieFrameEditField         matlab.ui.control.NumericEditField
        frameLabel                  matlab.ui.control.Label
        AutoButton                  matlab.ui.control.Button
        MovieCheckBox3              matlab.ui.control.CheckBox
        MovieCheckBox2              matlab.ui.control.CheckBox
        MovieCheckBox1              matlab.ui.control.CheckBox
        MovieDynDecrButton          matlab.ui.control.Button
        MovieDynLabel               matlab.ui.control.Label
        MovieDynIncrButton          matlab.ui.control.Button
        SenseMapSpinner2            matlab.ui.control.Spinner
        SenseMapSpinner1            matlab.ui.control.Spinner
        MovieCoilSpinner            matlab.ui.control.Spinner
        ButtonGroup                 matlab.ui.container.ButtonGroup
        SenseMapButton              matlab.ui.control.RadioButton
        kSpaceButton                matlab.ui.control.RadioButton
        AveragesButton              matlab.ui.control.RadioButton
        MovieButton                 matlab.ui.control.RadioButton
        MovieSliceDecrButton        matlab.ui.control.Button
        MovieSliceLabel             matlab.ui.control.Label
        MovieSliceIncrButton        matlab.ui.control.Button
        MovieFrameDecrButton        matlab.ui.control.Button
        MovieFrameLabel             matlab.ui.control.Label
        MovieFrameIncrButton        matlab.ui.control.Button
        MovieRotateLabel            matlab.ui.control.Label
        ccwButton                   matlab.ui.control.Button
        cwButton                    matlab.ui.control.Button
        intensityLabel              matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        MovieFig                    matlab.ui.control.UIAxes
        LcurveTab                   matlab.ui.container.Tab
        LcurveLRxyzCheckBox         matlab.ui.control.CheckBox
        LRxyzLabel2                 matlab.ui.control.Label
        RegularizationLabel         matlab.ui.control.Label
        TVdynLabel                  matlab.ui.control.Label
        TVxyzLabel                  matlab.ui.control.Label
        TVcineLabel                 matlab.ui.control.Label
        WVxyzLabel                  matlab.ui.control.Label
        LcurveTVdynCheckBox         matlab.ui.control.CheckBox
        LcurveTVxyzCheckBox         matlab.ui.control.CheckBox
        LcurveTVcineCheckBox        matlab.ui.control.CheckBox
        LcurveWVxyzCheckBox         matlab.ui.control.CheckBox
        LcurveStopButton            matlab.ui.control.Button
        LambdaLabel                 matlab.ui.control.Label
        LambdaIterationsEditField   matlab.ui.control.NumericEditField
        iterLabel                   matlab.ui.control.Label
        LambdaStepsEditField        matlab.ui.control.NumericEditField
        stepsEditFieldLabel         matlab.ui.control.Label
        Lambda2EditField            matlab.ui.control.NumericEditField
        upperEditFieldLabel         matlab.ui.control.Label
        Lambda1EditField            matlab.ui.control.NumericEditField
        lowerEditFieldLabel         matlab.ui.control.Label
        LcurveStartButton           matlab.ui.control.Button
        LcurveModelErrorFig         matlab.ui.control.UIAxes
        LcurveFig                   matlab.ui.control.UIAxes
        RadialTab                   matlab.ui.container.Tab
        UnderConstructionLabel      matlab.ui.control.Label
        RadialConstructionImage     matlab.ui.control.Image
        StatusLabel                 matlab.ui.control.Label
        StatusImage                 matlab.ui.control.Image
        BackgroundBox               matlab.ui.control.TextArea
        MRSImage                    matlab.ui.control.Image
        MessageBox                  matlab.ui.control.TextArea
        TulipImage                  matlab.ui.control.Image
        Credits                     matlab.ui.control.Label
        RetrospectiveLabel          matlab.ui.control.Label
    end


    % RETROSPECTIVE TRIGGERING RECONSTRUCTION
    %
    % Gustav Strijkers
    % Amsterdam UMC, Academic Medical Center (AMC)
    % Amsterdam, the Netherlands
    % g.j.strijkers@amsterdamumc.nl
    %
    %
    %
    % Version: May, 2022
    % Matlab 2022a
    %
    %
    % Version 1.0
    % - initial commit
    %
    % Version 2.0 changes:
    % - app designer GUI
    %
    % Version 2.1 changes:
    % - Cleaned-up the code
    % - GIF export file name now includes the number of frames
    % - Automatically start sorting k-space when the number of frames is changed
    % - Added buttons to rotate movie
    % - Added buttons to shift movie in the frame/time dimension
    % - Animated gif export image intensity is scaled by the intensity scaling slider
    % - Trajectory figure added
    % - Full x-scale in plot windows
    % - Added sliders to adjust navigator view range
    % - Parameter no_effective_views is now obsolete
    %
    % Version 3.0 changes:
    % - Added a matlab Conjugadient Gradient method for CS-reconstruction without Bart toolbox
    %
    % Version 3.1 changes:
    % - Added the possibility to reconstruction a respiratory CINE
    % - Corrected a bug in the calculation of the total acquisition time
    % - Added the possibility to reconstruct only part of the data
    % - Added detected respiration and heart rate markers and values in navigator power spectrum
    % - Bug fixed number_of_cardiac frames
    % - Improved check on correct loading of MRD file
    % - Cleaned up the code
    %
    % Version 3.2 changes:
    % - Added edit fields for cardiac and respiratory filter values
    % - Fixed an issue with the image size of the movie in the app
    % - Added a reconstruction progress bar
    %
    % Version 3.3 changes:
    % - Bug fixes
    %
    % Version 3.4 changes:
    % - Major clean up of the code to accomodate future versions
    % - Added app message box
    % - Bug fixes
    % - Simplification of how to switch the buttons and lights on and off
    %
    % Version 3.5 changes:
    % - Bug fix for movie frame circular rotation DICOM export
    % - Fix a crash when respiratory or heart filter width > center value
    %
    % Version 3.6 changes:
    % - Added Total Variation in space regularization - Only works with Bart toolbox
    % - Now reading DICOM information from scanner reconstruction, including correct orientations
    % - VNSA weighting added
    % - Added additional search for DICOM information, compatible with PET/MRI systems
    %
    % Version 4.0 changes:
    % - Bug fixes
    % - Reconstructions of 3D+time data implemented
    % - Cleaned-up Matlab conjugate-gradient reconstruction code
    % - Added busy icons
    %
    % Version 5.0 changes:
    % - Added averages and k-space view
    % - Reconstruction of multi-coil data, tested with 2 receiver coils
    % - PCA analysis of navigator data and multi-coil data
    % - ESPIRiT coil sensitivity maps for multi-coil data
    % - Cosmetic changes
    % - Window/Level intensity scaling
    % - Respiration filtering with main peak + 1 harmonic
    % - Image orientation corrected for phase-encoding-orientation direction
    %
    % Version 5.1 changes:
    % - Added MRD export button, not functional yet
    % - Added cardiac absolute binning sorting option
    %
    % Version 5.2 changes:
    % - Added average navigator window (not very useful it seems)
    %
    % Version 5.3 changes:
    % - Radial import, first attempts
    % - Cleaned up code to include radial option
    % - Data window includes now an 'include' and 'exclude' data option
    % - Cleaned up code to make it easier to include more navigator types in the future
    %
    % Version 6.0 changes:
    % - Now supports reconstructions of dynamics
    % - VNSA removed (does not seem very useful)
    % - Average navigator removed (idem)
    % - MRD export and reconstruction by scanner reconstructor
    % - Improved check for loading unique dataset in loadMRD
    %
    % Version 6.1 changes:
    % - Implemented 1 frame reconstruction for 2D and 3D recos
    % - Corrected sequential and centric k-space filling for views2
    % - SUR file export to /1 directory on MRI/PET systems
    % - Multi-slice 2D acquisitions supported
    % - If on pc, check for system MRD and data directories
    %
    % Version 6.2 changes:
    % - Correction on 3D navigator code / bug fixes
    % - Discard points parameter added to prevent zipper artifact
    %
    % Version 6.3 changes:
    % - 2D and 3D Tukey filter of k-space, prevents zipper artifact
    % - Added autointensity button
    % - Navigator movie function added
    % - 3D P2ROUD trajectory import implemented, with pe1_order = 4
    %
    % Version 7.0 changes:
    % - Saves textfile with reconstruction setting parameters
    % - L-curve analysis of single slice 2D cine data
    % - Working on radial reconstruction
    % - Zoom of movie possible
    %
    % Version 7.1 changes:
    % - Advanced navigator panel with presets for mouse and rat
    % - Preset panel with presets for different reconstructions
    %
    % Version 7.2 changes:
    % - Bug fix in initial values navigator sliders
    % - Improved respiration detection for multi-slice acquistions
    %
    % Version 7.3 changes:
    % - Reconstruction of realtime = 1 dynamic per heart beat added
    % - Improved export of dynamic series
    % - Added Locally Low-Rank regularization parameter
    %
    % Version 7.3.1 changes:
    % - Import for B-type scanners
    %
    % Version 7.4
    % - Removed CLEAR option - does not seem to do much
    % - Weighted k-line sharing option
    % - Bug fix in L-curve analysis
    %
    % Version 7.5
    % - Support for non-square FOV
    %
    % Version 8.0
    % - VFA T1 mapping import added
    % - 3D gif data is exported as collage
    %
    % Version 8.1
    % - Cleaned up the code
    % - Software version added
    % - Introduced retroDataPars, retroNavPars, retrokSpacePars, and retroRecoPars classes for better maintainablity
    %  
    % Version 8.2 & 8.3
    % - Several bug fixes
    % - TR = 0 bug
    % 
    % Version 8.4
    % - RescaleSlope & RescaleIntercept output in dicom file
    % 
    % Version 8.5
    % - Added button to toggle reconstruction to inspiration or expiration
    % - Fixed a bug with MRD export
    % - Fixed a bug in the image orientation with DICOM export
    %


    % Private variables within the app
    properties (Access = public)

        % Software version
        appVersion = "8.5.1"                                            % software version

        % Classes
        retroDataPars               retroData                           % data and meta data
        retroNavPars                retroNav                            % navigator data and meta data
        retroKspacePars             retroKspace                         % k-space and meta data
        retroRecoPars               retroReco                           % reconstruction and movie data
  
        % Directories & filenames
        appDir                                                          % application directory
        oldDir                                                          % starting directory
        currentDir                                                      % search dir for mrdpath.txt
        mrdImportPath                                                   % mrd import path
        mrdAutoImportPath                                               % auto loading mrd import path
        dicomExportPath                                                 % dicom export path
        gifExportPath                                                   % gif export path
        mrdExportPath                                                   % mrd export path
        tag                                                             % filename tag for dicom export
        mrdFile                                                         % mrd data file
        mrdAutoFile                                                     % auto loading mrd file
    
        % Acquisition
        acqDur                                                          % total acquisition time
       
        % Movie parameters
        nrFrames                                                        % number of movie frames
        nrDynamics                                                      % number of dynamics of flip-angles
        nrSlices                                                        % number of slices
        nrCardFrames                                                    % number of cardiac frames
        nrRespFrames                                                    % number of respiratory frames
   
        % Flags
        bartDetectedFlag                                                % bart toolbox available true or false
        validFilterFlag                                                 % navigator data filtered true or false
        validSortFlag                                                   % valid sorting of the data true or false
        validRecoFlag                                                   % valid reconstruction true or false
        validSenseMapFlag                                               % sense maps exists, true or false
        stopMovieFlag                                                   % flag to stop the movie (true / false)
        stopLcurveFlag                                                  % flag to stop Lcurve calculation (true/false)
        stopNavMovieFlag                                                % flag to stop the navigator movie (true / false)
       
        % Plot and movie elements
        progress                                                        % reconstruction progress bar
        cbar                                                            % color bar
        level                                                           % movie intensity level
        window                                                          % movie intensity window
        navMovieSpeed                                                   % speed of the navigator movie
        sliceNr                                                         % slice shown in the movie
        dynamicNr                                                       % dynamic shown in the movie
        dWin1                                                           % inclusion datawindow in heartrate plot
        dWin2                                                           % inclusion datawindow in respirationrate plot
        dWin3                                                           % exclusion datawindow in heartrate plot
        dWin4                                                           % exclusion datawindow in respirationrate plot
        dPlot1                                                          % dynamics indication in the heartrate plot
        dPlot2                                                          % dynamics indication in the respirationrate plot
        dPlot1Txt                                                       % dynamics indication text
        dPlot2Txt                                                       % dynamics indication text
        msPlot1                                                         % multislice indication in the heartrate plot
        msPlot2                                                         % multislice indication in the respirationrate plot
        msPlot1Txt                                                      % multislice indication text
        msPlot2Txt                                                      % multislice indication text
        moviePlot                                                       % movie plot
        cardRateWindow                                                  % start-end range indicator in time-heartrate figure
        respRateWindow                                                  % start-end range indicator in time-respirationrate figure
        
        % Colors and sounds
        green                                                           % other shade of green
        brightgreen                                                     % green
        red                                                             % other shade of red
        brightred                                                       % red
        blue                                                            % blue
        lightblue                                                       % lightblue
        lightgrey                                                       % lightgray
        yellow                                                          % yellow
        orange                                                          % orange
        black                                                           % black
        warningSound                                                    % warning sound
        errorSound                                                      % error sound

    end % properties


    methods (Access = private)

        % -------------------------------------------------
        % Buttons and lights controls
        % -------------------------------------------------


        % Traffic lights
        function SetLightsFcn(app,bitmap)

            code = de2bi(bitmap,7);

            if code(1) == 0 app.Lamp1.Color = app.brightred; else app.Lamp1.Color = app.brightgreen; end
            if code(2) == 0 app.Lamp2.Color = app.brightred; else app.Lamp2.Color = app.brightgreen; end
            if code(3) == 0 app.Lamp3.Color = app.brightred; else app.Lamp3.Color = app.brightgreen; end
            if code(4) == 0 app.Lamp4.Color = app.brightred; else app.Lamp4.Color = app.brightgreen; end
            if code(5) == 0 app.Lamp5.Color = app.brightred; else app.Lamp5.Color = app.brightgreen; end
            if code(6) == 0 app.Lamp6.Color = app.brightred; else app.Lamp6.Color = app.brightgreen; end
            if code(7) == 0 app.Lamp7.Color = app.brightred; else app.Lamp7.Color = app.brightgreen; end

            drawnow;

        end % Traffic lights


        % Dashboard buttons
        function SetDashboardButtonsFcn(app,bitmap)

            code = de2bi(bitmap,8);

            if code(1) == 0 app.LoadMRDButton.Enable = 'off'; else app.LoadMRDButton.Enable = 'on'; end
            if code(2) == 0 app.FilterButton.Enable = 'off'; else app.FilterButton.Enable = 'on'; end
            if code(3) == 0 app.SortKspaceButton.Enable = 'off'; else app.SortKspaceButton.Enable = 'on'; end
            if code(4) == 0 app.ReconstructButton.Enable = 'off'; else app.ReconstructButton.Enable = 'on'; end
            if code(5) == 0 app.ExportDCMButton.Enable = 'off'; else app.ExportDCMButton.Enable = 'on'; end
            if code(6) == 0 app.ExportGIFButton.Enable = 'off'; else app.ExportGIFButton.Enable = 'on'; end
            if code(7) == 0 app.ExportMRDButton.Enable = 'off'; else app.ExportMRDButton.Enable = 'on'; end
            if code(8) == 0 app.ExitButton.Enable = 'off'; else app.ExitButton.Enable = 'on'; end

            drawnow;

        end % Dashboard buttons


        % Navigator selection buttons
        function NavSelButtonsFcn(app,on)

            if on

                app.NavigatorEndEditField.Enable = 'on';
                app.NavigatorNRPointsEditField.Enable = 'on';
                app.NavigatorFlipSwitch.Enable = 'on';
                app.DiscardEditField.Enable = 'on';
                app.SharingEditField.Enable = 'on';
                if app.retroDataPars.multiCoilFlag
                    app.CoilSpinner.Enable = 'on';
                else
                    app.CoilSpinner.Enable = 'off';
                end
                app.LogLinButton.Enable = 'on';
                app.HeartEditField.Enable = 'on';
                app.RespirationEditField.Enable = 'on';
                app.HeartWidthEditField.Enable = 'on';
                app.RespirationWidthEditField.Enable = 'on';
                app.RespirationWindowEditField.Enable = 'on';
                app.ResetFilterValuesButton.Enable = 'on';
                app.MouseButton.Enable = 'on';
                app.RatButton.Enable = 'on';
                app.MouseButton2.Enable = 'on';
                app.RatButton2.Enable = 'on';
                app.FilterOrderEditField.Enable = 'on';
                app.MaxHeartRateEditField.Enable ='on';
                app.MinHeartRateEditField.Enable ='on';
                app.MinRespRateEditField.Enable = 'on';
                app.MaxRespRateEditField.Enable = 'on';
                app.RespirationToggleCheckBox.Enable = 'on';

            else

                app.NavigatorEndEditField.Enable = 'off';
                app.NavigatorNRPointsEditField.Enable = 'off';
                app.NavigatorFlipSwitch.Enable = 'off';
                app.DiscardEditField.Enable = 'off';
                app.SharingEditField.Enable = 'off';
                app.CoilSpinner.Enable = 'off';
                app.LogLinButton.Enable = 'off';
                app.HeartEditField.Enable = 'off';
                app.RespirationEditField.Enable = 'off';
                app.HeartWidthEditField.Enable = 'off';
                app.RespirationWidthEditField.Enable = 'off';
                app.RespirationWindowEditField.Enable = 'off';
                app.ResetFilterValuesButton.Enable = 'off';
                app.MouseButton.Enable = 'off';
                app.RatButton.Enable = 'off';
                app.MouseButton2.Enable = 'off';
                app.RatButton2.Enable = 'off';
                app.FilterOrderEditField.Enable = 'off';
                app.MaxHeartRateEditField.Enable ='off';
                app.MinHeartRateEditField.Enable ='off';
                app.MinRespRateEditField.Enable = 'off';
                app.MaxRespRateEditField.Enable = 'off';
                app.RespirationToggleCheckBox.Enable = 'off';

            end

            drawnow;

        end % Navigator selection buttons


        % Navigator viewing buttons
        function NavViewingButtonsFcn(app,on)

            if on

                app.StartEditField.Enable = 'on';
                app.RangeEditField.Enable = 'on';
                app.StartSlider.Enable = 'on';
                app.RangeSlider.Enable = 'on';
                app.NavStartButton.Enable = 'on';
                app.NavStopButton.Enable = 'on';
                app.NavFastSlowSwitch.Enable = 'on';

                if (app.DynamicsEditField.Value == 1) && ~app.retroDataPars.multi2DFlag && ~app.retroDataPars.vfaDataFlag
                    app.IncludeCheckBox.Enable = 'on';
                    app.ExcludeCheckBox.Enable = 'on';
                else
                    app.IncludeCheckBox.Enable = 'off';
                    app.ExcludeCheckBox.Enable = 'off';
                    app.IncludeStartSlider.Enable = 'off';
                    app.IncludeEndSlider.Enable = 'off';
                    app.ExcludeStartSlider.Enable = 'off';
                    app.ExcludeEndSlider.Enable = 'off';
                end

                if app.IncludeCheckBox.Value
                    app.IncludeStartSlider.Enable = 'on';
                    app.IncludeEndSlider.Enable = 'on';
                else
                    app.IncludeStartSlider.Enable = 'off';
                    app.IncludeEndSlider.Enable = 'off';
                end

                if app.ExcludeCheckBox.Value
                    app.ExcludeStartSlider.Enable = 'on';
                    app.ExcludeEndSlider.Enable = 'on';
                else
                    app.ExcludeStartSlider.Enable = 'off';
                    app.ExcludeEndSlider.Enable = 'off';
                end

            else

                app.StartEditField.Enable = 'off';
                app.RangeEditField.Enable = 'off';
                app.StartSlider.Enable = 'off';
                app.RangeSlider.Enable = 'off';
                app.NavStartButton.Enable = 'off';
                app.NavStopButton.Enable = 'off';
                app.NavFastSlowSwitch.Enable = 'off';
                app.IncludeCheckBox.Enable = 'off';
                app.IncludeStartSlider.Enable = 'off';
                app.IncludeEndSlider.Enable = 'off';
                app.ExcludeCheckBox.Enable = 'off';
                app.ExcludeStartSlider.Enable = 'off';
                app.ExcludeEndSlider.Enable = 'off';

            end

            drawnow;

        end % Navigator viewing buttons


        % Cine reco buttons
        function CineRecoButtonsFcn(app,on)

            if on 

                app.ScoutButton.Enable ='on';
                app.SystolicFunctionButton.Enable = 'on';
                app.DiastolicFunctionButton.Enable = 'on';
                app.RespiratoryFunctionButton.Enable = 'on';
                app.FramesEditField.Enable = 'on';

                app.DynamicsEditField.Enable = 'on';
                app.RealTimeFunctionButton.Enable = 'on';
                if app.retroDataPars.multi2DFlag
                    app.DynamicsEditField.Enable = 'off';
                    app.RealTimeFunctionButton.Enable = 'off';
                    app.DynamicsEditField.Value = 1;
                end

                if strcmp(app.retroDataPars.dataType,'3D')
                    app.RealTimeFunctionButton.Enable = 'off';
                end

                app.VfaFunctionButton.Enable = 'off';
                if app.retroDataPars.vfaDataFlag
                    app.DynamicsEditField.Enable = 'off';
                    app.VfaFunctionButton.Enable = 'on';
                    app.RealTimeFunctionButton.Enable = 'off';
                end

                app.RecoTypeDropDown.Enable = 'on';

                % K-space data sorted and Bart is available
                if app.validSortFlag && app.bartDetectedFlag
                    
                    app.WVxyzEditField.Enable = 'on';
                    app.TVxyzEditField.Enable = 'on';
                    app.LLRxyzEditField.Enable = 'on';
                    app.TVcineEditField.Enable = 'on';
                    app.TVdynEditField.Enable = 'on';

                    app.LcurveStartButton.Enable = 'on';
                    app.LcurveStopButton.Enable = 'off';
                    app.Lambda1EditField.Enable = 'on';
                    app.Lambda2EditField.Enable = 'on';
                    app.LambdaStepsEditField.Enable = 'on';
                    app.LambdaIterationsEditField.Enable = 'on';
                    app.LcurveWVxyzCheckBox.Enable = 'on';
                    app.LcurveTVxyzCheckBox.Enable = 'on';
                    app.LcurveLRxyzCheckBox.Enable = 'on';
                    app.LcurveTVcineCheckBox.Enable = 'on';
                    app.LcurveTVdynCheckBox.Enable = 'on';

                    if app.retroDataPars.multiCoilFlag
                        app.ESPIRiTCheckBox.Enable = 'on';
                    else
                        app.ESPIRiTCheckBox.Enable = 'off';
                        app.ESPIRiTCheckBox.Value = 0;
                    end

                end

                % K-space data sorted and Bart is not available
                if app.validSortFlag && ~app.bartDetectedFlag
                    
                    app.WVxyzEditField.Enable = 'off';
                    app.TVxyzEditField.Enable = 'off';
                    app.LLRxyzEditField.Enable = 'off';
                    app.TVcineEditField.Enable = 'on';
                    app.TVdynEditField.Enable = 'off';

                    app.LcurveStartButton.Enable = 'off';
                    app.LcurveStopButton.Enable = 'off';
                    app.Lambda1EditField.Enable = 'off';
                    app.Lambda2EditField.Enable = 'off';
                    app.LambdaStepsEditField.Enable = 'off';
                    app.LambdaIterationsEditField.Enable = 'off';
                    app.LcurveWVxyzCheckBox.Enable = 'off';
                    app.LcurveTVxyzCheckBox.Enable = 'off';
                    app.LcurveLRxyzCheckBox.Enable = 'off';
                    app.LcurveTVcineCheckBox.Enable = 'off';
                    app.LcurveTVdynCheckBox.Enable = 'off';

                end

                % K-space data not sorted
                if ~app.validSortFlag

                    app.WVxyzEditField.Enable = 'off';
                    app.TVxyzEditField.Enable = 'off';
                    app.LLRxyzEditField.Enable = 'off';
                    app.TVcineEditField.Enable = 'off';
                    app.ESPIRiTCheckBox.Enable = 'off';
                    app.TVdynEditField.Enable = 'off';
                   
                    app.LcurveStartButton.Enable = 'off';
                    app.LcurveStopButton.Enable = 'off';
                    app.Lambda1EditField.Enable = 'off';
                    app.Lambda2EditField.Enable = 'off';
                    app.LambdaStepsEditField.Enable = 'off';
                    app.LambdaIterationsEditField.Enable = 'off';
                    app.LcurveWVxyzCheckBox.Enable = 'off';
                    app.LcurveTVxyzCheckBox.Enable = 'off';
                    app.LcurveLRxyzCheckBox.Enable = 'off';
                    app.LcurveTVcineCheckBox.Enable = 'off';
                    app.LcurveTVdynCheckBox.Enable = 'off';

                end

            else

                app.ScoutButton.Enable ='off';
                app.SystolicFunctionButton.Enable = 'off';
                app.DiastolicFunctionButton.Enable = 'off';
                app.RespiratoryFunctionButton.Enable = 'off';
                app.RealTimeFunctionButton.Enable = 'off';
                app.VfaFunctionButton.Enable = 'off';

                app.FramesEditField.Enable = 'off';
                app.DynamicsEditField.Enable = 'off';
                app.RecoTypeDropDown.Enable = 'off';
                app.WVxyzEditField.Enable = 'off';
                app.TVcineEditField.Enable = 'off';
                app.TVxyzEditField.Enable = 'off';
                app.LLRxyzEditField.Enable = 'off';
                app.TVdynEditField.Enable = 'off';
                app.ESPIRiTCheckBox.Enable = 'off';

                app.LcurveStartButton.Enable = 'off';
                app.LcurveStopButton.Enable = 'off';
                app.Lambda1EditField.Enable = 'off';
                app.Lambda2EditField.Enable = 'off';
                app.LambdaStepsEditField.Enable = 'off';
                app.LambdaIterationsEditField.Enable = 'off';
                app.LcurveWVxyzCheckBox.Enable = 'off';
                app.LcurveTVxyzCheckBox.Enable = 'off';
                app.LcurveLRxyzCheckBox.Enable = 'off';
                app.LcurveTVcineCheckBox.Enable = 'off';
                app.LcurveTVdynCheckBox.Enable = 'off';

            end

            drawnow;

        end % CineRecoButtonsFcn


        % Movie Buttons
        function MovieButtons(app,on)

            if on && app.validRecoFlag

                if app.stopMovieFlag
                    app.StartButton.Enable = 'on';
                    app.StopButton.Enable = 'off';
                else
                    app.StartButton.Enable = 'off';
                    app.StopButton.Enable = 'on';
                end

                app.MovieFrameEditField.Enable = 'on';
                app.MovieCheckBox1.Enable = 'on';
                app.WindowScaleSlider.Enable = 'on';
                app.LevelScaleSlider.Enable = 'on';
                app.AutoButton.Enable = 'on';
                app.cwButton.Enable = 'on';
                app.ccwButton.Enable = 'on';
                app.MovieFrameIncrButton.Enable = 'on';
                app.MovieFrameDecrButton.Enable = 'on';
                app.MovieButton.Enable = 'on';
                app.AveragesButton.Enable = 'on';
                app.kSpaceButton.Enable = 'on';

                if app.retroDataPars.multiCoilFlag
                    app.MovieCoilSpinner.Enable = 'on';
                else
                    app.MovieCoilSpinner.Enable = 'off';
                end

                if app.validSenseMapFlag
                    app.SenseMapButton.Enable = 'on';
                    s1 = size(app.retroRecoPars.senseMap,4);
                    s2 = size(app.retroRecoPars.senseMap,5);
                    app.SenseMapSpinner1.Value = 1;
                    if s1>1
                        app.SenseMapSpinner1.Limits = [1,s1];
                        app.SenseMapSpinner1.Enable = 'on';
                    else
                        app.SenseMapSpinner1.Limits = [1,2];
                        app.SenseMapSpinner1.Enable = 'off';
                    end
                    app.SenseMapSpinner2.Value = 1;
                    if s2>1
                        app.SenseMapSpinner2.Limits = [1,s2];
                        app.SenseMapSpinner2.Enable = 'on';
                    else
                        app.SenseMapSpinner2.Limits = [1,2];
                        app.SenseMapSpinner2.Enable = 'off';
                    end
                else
                    app.SenseMapButton.Enable = 'off';
                    app.SenseMapSpinner1.Enable = 'off';
                    app.SenseMapSpinner2.Enable = 'off';
                end

                if app.retroRecoPars.multiSliceFlag
                    app.MovieSliceEditField.Enable = 'on';
                    app.MovieSliceIncrButton.Enable = 'on';
                    app.MovieSliceDecrButton.Enable = 'on';
                    app.MovieCheckBox2.Enable = 'on';
                end

                if app.retroRecoPars.multiDynamicFlag
                    app.MovieDynEditField.Enable = 'on';
                    app.MovieDynIncrButton.Enable = 'on';
                    app.MovieDynDecrButton.Enable = 'on';
                    app.MovieCheckBox3.Enable = 'on';
                end

            else

                app.StartButton.Enable = 'off';
                app.StopButton.Enable = 'off';
                app.MovieFrameEditField.Enable = 'off';
                app.MovieSliceEditField.Enable = 'off';
                app.MovieDynEditField.Enable = 'off';
                app.MovieCheckBox1.Enable = 'off';
                app.MovieCheckBox2.Enable = 'off';
                app.MovieCheckBox3.Enable = 'off';
                app.WindowScaleSlider.Enable = 'off';
                app.LevelScaleSlider.Enable = 'off';
                app.AutoButton.Enable = 'off';
                app.cwButton.Enable = 'off';
                app.ccwButton.Enable = 'off';
                app.MovieFrameIncrButton.Enable = 'off';
                app.MovieFrameDecrButton.Enable = 'off';
                app.MovieSliceIncrButton.Enable = 'off';
                app.MovieSliceDecrButton.Enable = 'off';
                app.MovieDynIncrButton.Enable = 'off';
                app.MovieDynDecrButton.Enable = 'off';
                app.MovieButton.Enable = 'off';
                app.AveragesButton.Enable = 'off';
                app.kSpaceButton.Enable = 'off';
                app.MovieCoilSpinner.Enable = 'off';
                app.SenseMapButton.Enable = 'off';
                app.SenseMapSpinner1.Enable = 'off';
                app.SenseMapSpinner2.Enable = 'off';
                app.stopMovieFlag = true;

            end

            drawnow;

        end % MovieButtons


        % Switch of all buttons
        function SwitchOffAllButtonsFcn(app)

            % Stop the movies
            app.stopNavMovieFlag = true;
            app.stopMovieFlag = true;

            % Dashboard buttons
            app.SetDashboardButtonsFcn(0);

            % Navigator selection and filtering
            app.NavSelButtonsFcn(false);

            % Navigator viewing
            app.NavViewingButtonsFcn(false);

            % Cine reconstruction buttons
            app.CineRecoButtonsFcn(false);

            % Movie window
            app.MovieButtons(false);

        end % SwitchOffAllButtonsFcn


        % Load icons
        function SetAppLayoutPropertiesFcn(app)

            app.Credits.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);

            app.TulipImage.ImageSource = 'AmsterdamUMC.gif';
            app.MRSImage.ImageSource = 'MRS.gif';
            app.RadialConstructionImage.ImageSource = 'underconstruction.gif';

            app.BusyImage1.ImageSource = 'busy.gif';
            app.BusyImage2.ImageSource = 'busy.gif';
            app.BusyImage3.ImageSource = 'busy.gif';
            app.BusyImage4.ImageSource = 'busy.gif';
            app.BusyImage5.ImageSource = 'busy.gif';
            app.BusyImage6.ImageSource = 'busy.gif';
            app.BusyImage7.ImageSource = 'busy.gif';

            app.BusyImage1.Visible = 'off';
            app.BusyImage2.Visible = 'off';
            app.BusyImage3.Visible = 'off';
            app.BusyImage4.Visible = 'off';
            app.BusyImage5.Visible = 'off';
            app.BusyImage6.Visible = 'off';
            app.BusyImage7.Visible = 'off';

            app.LoadMRDButton.Icon = 'disk.png';
            app.FilterButton.Icon = 'filter.png';
            app.SortKspaceButton.Icon = 'sort.gif';
            app.ReconstructButton.Icon = 'reconstruct.gif';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExportMRDButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StartButton.Icon = 'play.gif';
            app.NavStartButton.Icon = 'play.gif';
            app.StopButton.Icon = 'stop.gif';
            app.NavStopButton.Icon = 'stop.gif';
            app.cwButton.Icon = 'cw.png';
            app.ccwButton.Icon = 'ccw.png';
            app.StatusImage.ImageSource = 'statusokay.gif';

            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green';
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.black = 'Black';
            app.lightblue = '#DEE6EB';
            app.lightgrey = '#F0F0F0';

            % Set some plot properties
            app.CardiacNavFig.Toolbar.Visible = 'off';
            app.RespirationNavFig.Toolbar.Visible = 'off';
            app.RawNavFig.Toolbar.Visible = 'off';
            app.SpectrumFig.Toolbar.Visible = 'off';
            app.HeartRateTimeFig.Toolbar.Visible = 'off';
            app.RespRateTimeFig.Toolbar.Visible = 'off';
            app.MovieFig.Toolbar.Visible = 'off';
            app.TrajectoryFig.Toolbar.Visible = 'off';
            app.LcurveFig.Toolbar.Visible = 'off';
            app.LcurveModelErrorFig.Toolbar.Visible = 'off';

            % Switch off plot interactivity, except for movie plot
            app.CardiacNavFig.Interactions = [];
            app.RespirationNavFig.Interactions = [];
            app.RawNavFig.Interactions = [];
            app.SpectrumFig.Interactions = [];
            app.HeartRateTimeFig.Interactions = [];
            app.RespRateTimeFig.Interactions = [];
            app.MovieFig.Interactions = [zoomInteraction regionZoomInteraction];
            app.TrajectoryFig.Interactions = [];
            app.LcurveFig.Interactions = [];
            app.LcurveModelErrorFig.Interactions = [];

            % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

        end % SetAppLayoutPropertiesFcn


        % Species presets
        function SetSpeciesFcn(app)

            % Mouse preset settings
            if app.MouseButton.Value

                app.retroNavPars.bandwidthHR = 100;
                app.HeartWidthEditField.Value = 100;
                app.retroNavPars.bandwidthRR = 20;
                app.RespirationWidthEditField.Value = 40;
                app.retroNavPars.respPercentage = 30;
                app.RespirationWindowEditField.Value = 30;
                app.MinHeartRateEditField.Value = 300;
                app.MaxHeartRateEditField.Value = 620;
                app.MinRespRateEditField.Value = 30;
                app.MaxRespRateEditField.Value = 90;
                app.FilterOrderEditField.Value = 4;

            end

            % Rat preset settings
            if app.RatButton.Value

                app.retroNavPars.bandwidthHR = 100;
                app.HeartWidthEditField.Value = 100;
                app.retroNavPars.bandwidthRR = 20;
                app.RespirationWidthEditField.Value = 40;
                app.retroNavPars.respPercentage = 30;
                app.RespirationWindowEditField.Value = 30;
                app.MinHeartRateEditField.Value = 200;
                app.MaxHeartRateEditField.Value = 450;
                app.MinRespRateEditField.Value = 30;
                app.MaxRespRateEditField.Value = 90;
                app.FilterOrderEditField.Value = 4;
           
            end

        end % SetSpeciesFcn


        function SetRecoParametersFcn(app)

            % Settings for scout acquisition (e.g. 3 orthogonal slices)
            if app.ScoutButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 1;
                if ~app.retroDataPars.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.005;
                app.LLRxyzEditField.Value = 0.000;
                app.TVcineEditField.Value = 0.000;
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
           
            end

            % Settings for systolic function, typical number of CINE frames = 12
            if app.SystolicFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 12;
                if ~app.retroDataPars.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.000;
                app.LLRxyzEditField.Value = 0.000;
                app.TVcineEditField.Value = 0.100;
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;

            end

            % Settings for diastolic function, typical number of CINE frames = 32
            if app.DiastolicFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 32;
                if ~app.retroDataPars.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.000;
                app.LLRxyzEditField.Value = 0.000;
                app.TVcineEditField.Value = 0.100;
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;

            end

            % For reconstruction of respiratory motion
            if app.RespiratoryFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'respiratory';
                app.FramesEditField.Value = 60;
                if ~app.retroDataPars.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.000;
                app.LLRxyzEditField.Value = 0.000;
                app.TVcineEditField.Value = 0.100;
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;

            end

            % For 'realtime' reconstruction of X frames / heartbeat
            if app.RealTimeFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'realtime';
                app.FramesEditField.Value = 2;
                if ~app.retroDataPars.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 3;
                app.WVxyzEditField.Value = 0.000;
                app.TVxyzEditField.Value = 0.000;
                app.LLRxyzEditField.Value = 0.000;
                app.TVcineEditField.Value = 0.001;
                app.TVdynEditField.Value = 0.200;
                app.ESPIRiTCheckBox.Value = 0;

            end

            % For 'realtime' reconstruction of X frames / heartbeat
            if app.VfaFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 6;
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.000;
                app.TVxyzEditField.Value = 0.000;
                app.LLRxyzEditField.Value = 0.050;
                app.TVcineEditField.Value = 0.100;
                app.TVdynEditField.Value = 0.020;
                app.ESPIRiTCheckBox.Value = 0;

            end

        end % SetRecoParametersFcn


        % ------------------------------------------
        % Image acquisition data parameters
        % ------------------------------------------

        % Set MRD paths
        function SetMRDimportPathFcn(app)

            % Try to find text file with mrd import path in app directory
            if app.AutoRecoCheckBox.Value
                
                if isdeployed
                    mrdPathFile = strcat(app.currentDir,filesep,'mrdpath.txt');
                else
                    mrdPathFile = 'mrdpath.txt';
                end
                
                if isfile(mrdPathFile)
                    try
                        folderName = fileread(mrdPathFile);
                        if exist(folderName, 'dir')
                            app.mrdImportPath = folderName;
                        end
                    catch
                        app.mrdImportPath = '';
                    end
                end
                
            end

            % On pc search for the path in the Windows registry
            if ispc
                try
                    app.mrdImportPath = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    app.mrdImportPath = [app.mrdImportPath,filesep];
                    app.TextMessage('MRD directory found in Windows registry ...');
                    app.TextMessage(strcat('MRD directory =',{' '},app.mrdImportPath));
                    app.mrdAutoImportPath = app.mrdImportPath;
                catch
                    app.mrdImportPath = '';
                end
            end

            % If still nothing found, than empty the path names
            if isempty(app.mrdImportPath)
                app.mrdAutoImportPath = '';
            else
                app.mrdAutoImportPath = app.mrdImportPath;
            end

        end % SetMRDimportPathFcn


        % Define data parameters
        function DataParametersFcn(app)

            % Parameters
            app.retroDataPars.dimz = size(app.retroDataPars.data{1},2);                     % nr of points in 3rd dimension (2D multi-slice or 3D)
            app.retroNavPars.bandwidthHR = app.HeartWidthEditField.Value;                   % cardiac bandpass filter bandwidth, default = 150
            app.retroNavPars.bandwidthRR = app.RespirationWidthEditField.Value;             % respiratory bandpass filter bandwidth, default = 20
            app.retroNavPars.meanHeartRate = app.HeartEditField.Value;
            app.retroNavPars.respPercentage = app.RespirationWindowEditField.Value;         % percentage of data discarded during respiration, default = 30%
            app.nrFrames = app.FramesEditField.Value;                                       % default number of cardiac frames
            app.nrDynamics = app.DynamicsEditField.Value;                                   % default number of dynamics
            app.validFilterFlag = false;                                                    % reset flags
            app.validSenseMapFlag = false;
            app.validRecoFlag = false;
            app.validSortFlag = false;
            app.retroRecoPars.multiSliceFlag = false;
            app.retroRecoPars.multiDynamicFlag = false;
            
            % GUI parameters
            app.NavigatorEndEditField.Value = app.retroDataPars.primary_navigator_point;    % primary navigator point
            app.DiscardEditField.Value = app.retroDataPars.nr_nav_points_discarded;         % number of nav points discarded between navigator and echo
            app.NavigatorNRPointsEditField.Value = app.retroDataPars.nr_nav_points_used;    % number of nav points used
            app.StartEditField.Value = 1;                                                   % reset the nav viewing start value
            app.RangeEditField.Value = 100;                                                 % reset the nav viewing range
            app.StartSlider.Value = 1;                                                      % reset the nav viewing start value slider
            app.RangeSlider.Value = 100;                                                    % reset the nav viewing range slider
            app.navMovieSpeed = 10;                                                         % initial speed of the nav movie
            app.IncludeCheckBox.Value = 0;                                                  % reset the include and exclude sliders
            app.ExcludeCheckBox.Value = 0;

            switch app.retroDataPars.dataType

                case {'2D','2Dms','3D','3Dp2roud'}

                    if app.DiscardEditField.Value > round((size(app.retroDataPars.data{1},4) - app.retroDataPars.primary_navigator_point)/2)    % check that number of discard points is not bigger than 1/2 readout
                        app.DiscardEditField.Value = round((size(app.retroDataPars.data{1},4) - app.retroDataPars.primary_navigator_point)/2);
                    end

                    app.retroDataPars.dimx = size(app.retroDataPars.data{1},4) - ...
                        app.retroDataPars.primary_navigator_point - app.retroDataPars.nr_nav_points_discarded;      % nr readout points (no_samples)
                    cla(app.TrajectoryFig);                                                                         % clear trajectory figure

                    switch app.retroDataPars.pe1_order

                        case 0

                            app.TrajectoryViewField.Value = 'linear';
                            app.retroDataPars.dimy = app.retroDataPars.NO_VIEWS;                                                            % nr phase encoding steps (no_views)
                            app.retroDataPars.nrKsteps = app.retroDataPars.dimy;                                                           % nr k-space points per repetition = nr phase encoding steps
                            app.retroDataPars.nrKlines = app.retroDataPars.nr_repetitions*app.retroDataPars.dimy*app.retroDataPars.dimz;    % total number of k-lines that are measured
                            [app.retroKspacePars,app.retroDataPars] = app.retroKspacePars.getKspaceTrajectory(app.retroDataPars, app);       % get trajectory
                            plot(app.TrajectoryFig, app.retroKspacePars.trajectory);                                                        % plot the trajectory
                            xlim(app.TrajectoryFig,[1 length(app.retroKspacePars.trajectory)-1]);
                            ylim(app.TrajectoryFig,[min(app.retroKspacePars.trajectory)-1 max(app.retroKspacePars.trajectory)+1]);

                        case 2

                            app.TrajectoryViewField.Value = 'zigzag';
                            app.retroDataPars.dimy = app.retroDataPars.NO_VIEWS;                                                            % nr phase encoding steps (no_views)
                            app.retroDataPars.nrKsteps = app.retroDataPars.dimy;                                                           % nr k-space points per repetition = nr phase encoding steps
                            app.retroDataPars.nrKlines = app.retroDataPars.nr_repetitions*app.retroDataPars.dimy*app.retroDataPars.dimz;    % total number of k-lines that are measured
                            [app.retroKspacePars,app.retroDataPars] = app.retroKspacePars.getKspaceTrajectory(app.retroDataPars, app);       % get trajectory
                            plot(app.TrajectoryFig, app.retroKspacePars.trajectory);                                                        % plot the trajectory
                            xlim(app.TrajectoryFig,[1 length(app.retroKspacePars.trajectory)-1]);
                            ylim(app.TrajectoryFig,[min(app.retroKspacePars.trajectory)-1 max(app.retroKspacePars.trajectory)+1]);

                        case 3

                            app.TrajectoryViewField.Value = 'user defined';
                            app.retroDataPars.dimy = max(app.retroDataPars.gp_var_mul)-min(app.retroDataPars.gp_var_mul)+1;                     % effective nr phase encoding steps (no_effective_views)
                            app.retroDataPars.nrKsteps = app.retroDataPars.NO_VIEWS;                                                           % nr k-space trajectory points (no_views) in table
                            app.retroDataPars.nrKlines = app.retroDataPars.nr_repetitions*app.retroDataPars.nrKsteps*app.retroDataPars.dimz;   % total number of k-lines that are measured
                            [app.retroKspacePars,app.retroDataPars] = app.retroKspacePars.getKspaceTrajectory(app.retroDataPars, app);           % get trajectory
                            plot(app.TrajectoryFig, app.retroKspacePars.trajectory);                                                            % plot the trajectory
                            xlim(app.TrajectoryFig,[1 length(app.retroKspacePars.trajectory)-1]);
                            ylim(app.TrajectoryFig,[min(app.retroKspacePars.trajectory)-1 max(app.retroKspacePars.trajectory)+1]);

                        case 4

                            % RESERVED FOR P2ROUD ACQUISITION
                            app.TrajectoryViewField.Value = 'P2ROUD';
                            app.retroDataPars.dimy = app.retroDataPars.NO_VIEWS;                                                                % nr phase encoding steps (no_views)
                            app.retroDataPars.nrKsteps = app.retroDataPars.dimy;                                                               % nr k-space points per repetition = nr phase encoding steps
                            app.retroDataPars.nrKlines = app.retroDataPars.nr_repetitions*app.retroDataPars.dimy*app.retroDataPars.dimz;        % total number of k-lines that are measured
                            flist = dir(fullfile(app.mrdImportPath,'*.txt'));
                            if ~isempty(flist)
                                app.retroKspacePars.trajectory = load([flist(1).folder,filesep,flist(1).name]);
                                scatter(app.TrajectoryFig, app.retroKspacePars.trajectory(1:2:end),app.retroKspacePars.trajectory(2:2:end),'s');            % plot the 3D trajectory
                                xlim(app.TrajectoryFig,[min(app.retroKspacePars.trajectory(1:2:end))-1,max(app.retroKspacePars.trajectory(1:2:end))+1]);
                                ylim(app.TrajectoryFig,[min(app.retroKspacePars.trajectory(2:2:end))-1,max(app.retroKspacePars.trajectory(2:2:end))+1]);
                            else
                                text(app.TrajectoryFig,-0.7,1,'MISSING');
                                text(app.TrajectoryFig,-2.4,-0.5,'TRAJECTORY');
                                xlim(app.TrajectoryFig,[-3 9]);
                                ylim(app.TrajectoryFig,[-3 3]);
                            end

                    end

                case '2Dradial'

                    app.TrajectoryViewField.Value = 'radial';
                    app.retroDataPars.pe1_order = 8;
                    app.DiscardEditField.Value = 0;
                    app.retroDataPars.nr_nav_points_discarded = 0;
                    app.retroDataPars.dimx = app.retroDataPars.NO_SAMPLES;
                    app.retroDataPars.dimy = app.retroDataPars.dimx;
                    app.retroDataPars.nrKsteps = app.retroDataPars.NO_VIEWS;
                    app.retroDataPars.nrKlines = app.retroDataPars.nr_repetitions*app.retroDataPars.nrKsteps*app.retroDataPars.dimz;
                    [app.retroKspacePars,app.retroDataPars] = app.retroKspacePars.getKspaceTrajectory(app.retroDataPars, app);           % get trajectory
                    plot(app.TrajectoryFig, app.retroKspacePars.trajectory);                                                            % plot the trajectory
                    xlim(app.TrajectoryFig,[1 length(app.retroKspacePars.trajectory)-1]);
                    ylim(app.TrajectoryFig,[min(app.retroKspacePars.trajectory)-1 max(app.retroKspacePars.trajectory)+1]);

            end

            % Reset include / exclude window
            app.retroDataPars.includeWindow = ones(app.retroDataPars.nrKlines,1);
            app.retroDataPars.excludeWindow = ones(app.retroDataPars.nrKlines,1);

            % Total acquisition time in seconds
            app.acqDur = app.retroDataPars.nrKlines*app.retroDataPars.TR/1000;

            % Display the acquisitionparameters
            app.NxViewField.Value = app.retroDataPars.dimx;
            app.NyViewField.Value = app.retroDataPars.dimy;
            app.NzViewField.Value = app.retroDataPars.dimz;
            app.NcViewField.Value = app.retroDataPars.nr_coils;
            app.kLinesRepetitionViewField.Value = app.retroDataPars.nrKsteps;
            app.RepetitionsViewField.Value = app.retroDataPars.nr_repetitions;
            app.NrKlinesViewField.Value = app.retroDataPars.nrKlines;
            app.TRViewField.Value = app.retroDataPars.TR;
            app.ScanTimeViewField.Value = datestr(seconds(app.acqDur),'HH:MM:SS.FFF');
            app.TimeDynViewField.Value = datestr(seconds(app.acqDur/app.DynamicsEditField.Value),'HH:MM:SS.FFF');

            % Define the ranges of the display range sliders
            app.StartEditField.Limits = [1, app.retroDataPars.nrKlines-1];
            app.StartSlider.Limits = [1, app.retroDataPars.nrKlines-1];
            app.StartEditField.Value = round(app.retroDataPars.nrKlines/2);
            app.StartSlider.Value = round(app.retroDataPars.nrKlines/2);
            app.RangeEditField.Value = 600;
            app.RangeSlider.Value = 600;

            % Data limits
            app.IncludeStartSlider.Limits = [0,app.acqDur];
            app.IncludeEndSlider.Limits = [0,app.acqDur];
            app.IncludeStartSlider.Value = 0;
            app.IncludeEndSlider.Value = app.acqDur;
            app.ExcludeStartSlider.Limits = [0,app.acqDur];
            app.ExcludeEndSlider.Limits = [0,app.acqDur];
            app.ExcludeStartSlider.Value = 0;
            app.ExcludeEndSlider.Value = app.acqDur;
            app.SenseMapSpinner1.Value = 1;
            app.SenseMapSpinner2.Value = 1;
            app.ESPIRiTCheckBox.Value = 0;

            % Case of multicoil data
            if app.retroDataPars.multiCoilFlag
                if app.CoilSpinner.Value > app.retroDataPars.nr_coils
                    app.CoilSpinner.Value.Value = 1;
                    app.MovieCoilSpinner.Value = 1;
                end
                app.CoilSpinner.Limits = [1, app.retroDataPars.nr_coils];
                app.MovieCoilSpinner.Limits = [1, app.retroDataPars.nr_coils];
            else
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1, 2];
                app.MovieCoilSpinner.Value = 1;
                app.MovieCoilSpinner.Limits = [1, 2];
            end

            % Case of variable flip-angle data
            if app.retroDataPars.vfaDataFlag
                app.nrDynamicsLabel.Text = '# flip-angles';
                app.MovieDynLabel.Text = 'flip-angle';
                app.DynamicLabel.Text = 'flip-angle';
                app.DynamicsEditField.Editable = 'off';
                app.DynamicsEditField.Value = app.retroDataPars.VFA_size;
                app.nrDynamics = app.retroDataPars.VFA_size;
            else
                app.nrDynamicsLabel.Text = '# dynamics';
                app.MovieDynLabel.Text = 'dynamic';
                app.DynamicLabel.Text = 'dynamic';
                app.DynamicsEditField.Editable = 'on';
                app.DynamicsEditField.Value = 1;
                app.nrDynamics = 1;
            end

            % Set initial reconstruction parameters, based on the data
            if app.AutoGuessRecoCheckBox.Value
                app.retroDataPars = app.retroDataPars.guessRecoType;
                switch app.retroDataPars.recoGuess
                    case 'scout'
                        app.ScoutButton.Value = 1;
                    case 'systolic function'
                        app.SystolicFunctionButton.Value = 1;
                    case 'diastolic function'
                        app.DiastolicFunctionButton.Value = 1;
                    case 'variable flip-angle'
                        app.VfaFunctionButton.Value = 1;
                end
                app.SetRecoParametersFcn;
            end

        end % DataParametersFcn


        % Export the reconstruction settings
        function ExportRecoParametersFcn(app,exportdir)

            pars = strcat(...
                "------------------------- \n\n", ...
                "RETROSPECTIVE ", app.appVersion,"\n\n", ...
                "Gustav Strijkers\n", ...
                "Amsterdam UMC\n", ...
                "g.j.strijkers@amsterdamumc.nl\n\n", ...
                "------------------------- \n", ...
                "\nDATA \n\n", ...
                "file = ", app.DataFile.Value, "\n", ...
                "\nNAVIGATOR \n\n", ...
                "primary = ", num2str(app.NavigatorEndEditField.Value), "\n", ...
                "#points = ", num2str(app.NavigatorNRPointsEditField.Value), "\n", ...
                "switch = ",app.NavigatorFlipSwitch.Value , "\n", ...
                "\nFILTERS \n\n", ...
                "heart = ", num2str(app.HeartEditField.Value), " bpm\n", ...
                "heart width = ", num2str(app.HeartWidthEditField.Value), " bpm\n", ...
                "respiration = ", num2str(app.RespirationEditField.Value), " bpm\n", ...
                "respiration width = ", num2str(app.RespirationWidthEditField.Value), " bpm\n", ...
                "respiration window = ", num2str(app.RespirationWindowEditField.Value), " %%\n", ...
                "expiration (0) / inspiration (1) = ",num2str(app.RespirationToggleCheckBox.Value), " \n", ...
                "\nCINE\n\n", ...
                "type = ",app.RecoTypeDropDown.Value, "\n", ...
                "#frames = ",num2str(app.FramesEditField.Value), "\n", ...
                "#dynamics = ",num2str(app.DynamicsEditField.Value), "\n", ...
                "\nRECO \n\n", ...
                "WVxyz = ",num2str(app.WVxyzEditField.Value), "\n", ...
                "TVxyz = ",num2str(app.TVxyzEditField.Value), "\n", ...
                "LLRxyz = ",num2str(app.LLRxyzEditField.Value), "\n", ...
                "TVcine = ",num2str(app.TVcineEditField.Value), "\n", ...
                "TVdyn = ",num2str(app.TVdynEditField.Value), "\n", ...
                "ESPIRiT = ",num2str(app.ESPIRiTCheckBox.Value), "\n", ...
                "sharing = ",num2str(app.SharingEditField.Value), "\n", ...
                "\nINCLUDE & EXCLUDE WINDOWS\n\n", ...
                "include = ",num2str(app.IncludeCheckBox.Value), "\n", ...
                "include start = ",num2str(app.IncludeStartSlider.Value), " s\n", ...
                "include end = ",num2str(app.IncludeEndSlider.Value), " s\n", ...
                "exclude = ",num2str(app.ExcludeCheckBox.Value), "\n", ...
                "exclude start = ",num2str(app.ExcludeStartSlider.Value), " s\n", ...
                "exclude end = ",num2str(app.ExcludeEndSlider.Value), " s\n", ...
                "\nRATES\n\n", ...
                "respiration rate = ",num2str(app.FinalRespirationViewField.Value), " bpm\n", ...
                "heart rate = ",num2str(app.FinalHeartViewField.Value), " bpm\n" ...
                );

            fid = fopen(strcat(exportdir,filesep,'recoparmeters_',app.tag,'.txt'),'wt');
            fprintf(fid,pars);
            fclose(fid);

        end % ExportRecoParametersFcn


        % ------------------------------------------
        % Other functions
        % ------------------------------------------


        % Calculates the power spectrum of the raw navigator signal within the boundaries of the data-window
        function CalculateNavigatorSpectrumFcn(app)

            % Determine the power spectrum of the navigator
            app.retroNavPars.physioFilterSettings = [app.MinHeartRateEditField.Value app.MaxHeartRateEditField.Value app.MinRespRateEditField.Value app.MaxRespRateEditField.Value app.FilterOrderEditField.Value];
            app.retroNavPars = app.retroNavPars.determinePowerSpectrumPCA(app.retroDataPars);

            % Update the detected heartrate and respirationrate fields
            app.HeartEditField.Value = app.retroNavPars.detectedHR;
            app.RespirationEditField.Value = app.retroNavPars.detectedRR;

            if app.HeartWidthEditField.Value > app.HeartEditField.Value
                app.HeartWidthEditField.Value = app.HeartEditField.Value;
            end

            if app.RespirationWidthEditField.Value > app.RespirationEditField.Value
                app.RespirationWidthEditField.Value = app.RespirationEditField.Value;
            end

        end % CalculateNavigatorSpectrumFcn


        % L-curve analysis
        function LcurveanalysisFcn(app,lambda,x,y,par)

            % Calculate the curvature and find the maximum
            xl = log10(x);
            yl = log10(y);

            curv(1) = 0;
            curv(length(xl))=0;
            for i=2:length(xl)-1

                a = sqrt( (xl(i+1)-xl(i))^2   + (yl(i+1)-yl(i))^2   );
                b = sqrt( (xl(i-1)-xl(i))^2   + (yl(i-1)-yl(i))^2   );
                c = sqrt( (xl(i-1)-xl(i+1))^2 + (yl(i-1)-yl(i+1))^2 );
                s = ( a + b + c ) / 2;
                A = sqrt(s*(s-a)*(s-b)*(s-c));
                curv(i) = 4*A/(a*b*c);

            end
            [~,idx] = max(curv);

            % Set lambda to the value corresponding to maximum curvature
            switch par

                case 1
                    app.WVxyzEditField.Value = lambda(idx);

                case 2
                    app.TVxyzEditField.Value = lambda(idx);

                case 3
                    app.LLRxyzEditField.Value = lambda(idx);

                case 4
                    app.TVcineEditField.Value = lambda(idx);

                case 5
                    app.TVdynEditField.Value = lambda(idx);

            end

            % Indicate the value in the L-curve and model-error curve
            hold(app.LcurveFig,"on");
            loglog(app.LcurveFig,10^xl(idx),10^yl(idx),'o','MarkerEdgeColor',app.orange,'MarkerFaceColor',app.brightred,'LineWidth',1.5);
            hold(app.LcurveFig,"off");

            hold(app.LcurveModelErrorFig,"on");
            loglog(app.LcurveModelErrorFig,[lambda(1) lambda(end)],[10^xl(idx) 10^xl(idx)],'MarkerEdgeColor',[0 .5 .5],'MarkerFaceColor',app.blue,'LineWidth',1.5);
            hold(app.LcurveModelErrorFig,"off");

            pause(1);

        end % LcurveanalysisFcn


        % ------------------------------------------
        % Plotting figures and images
        % ------------------------------------------


        % Plot the raw navigator signals
        function PlotRawNavigatorFcn(app)

            % Display x-range
            startPoint = app.StartEditField.Value;
            endPoint = app.StartEditField.Value+app.RangeEditField.Value-1;

            % Extract part of the navigator that will be shown
            trace1 = app.retroNavPars.navAmplitude{app.CoilSpinner.Value}(startPoint:endPoint);

            % Display y-range
            maxPlot = max(trace1);
            minPlot = min(trace1);

            % Plot the navigator
            plot(app.RawNavFig, trace1,'Color',app.blue);
            xlim(app.RawNavFig, [0 length(trace1)+1]);
            ylim(app.RawNavFig, [1.1*minPlot 1.1*maxPlot+1]);
            drawnow;

        end % PlotRawNavigatorFcn


        % Plot the navigator with cardiac filtering
        function PlotCardiacNavigatorFcn(app)

            % Clear the image
            cla(app.CardiacNavFig);
            hold(app.CardiacNavFig,'on');

            if app.validFilterFlag

                % Display x-range
                startPoint = app.StartEditField.Value;
                endPoint = app.StartEditField.Value+app.RangeEditField.Value-1;

                % Extract part of the navigator that will be shown
                trace1 = app.retroNavPars.PCANav(startPoint:endPoint);
                trace2 = app.retroNavPars.heartNav(startPoint:endPoint);

                % Display y-range
                maxPlot = 1.1*max(trace1);
                minPlot = 1.1*min(trace1);

                % Scale the filtered signal for viewing purposes
                trace2 = 0.25*max(trace1)*trace2;

                % Show the detected trigger points (only when movie is not running)
                if app.stopNavMovieFlag
                    triggerPoints = app.retroNavPars.heartTrigPoints((app.retroNavPars.heartTrigPoints>=startPoint) & (app.retroNavPars.heartTrigPoints<=endPoint));
                    if length(triggerPoints) < 40
                        for i = 1:length(triggerPoints)
                            win=plot(app.CardiacNavFig,[triggerPoints(i)-startPoint+1,triggerPoints(i)-startPoint+1],[minPlot,maxPlot],'black','LineWidth',1);
                            win.Color(4) = 0.2; % transparancy
                        end
                    end
                end

                % Plot the raw navigator
                plot(app.CardiacNavFig, trace1,'Color',app.blue);

                % Plot the navigator filtered for cardiac motion
                plot(app.CardiacNavFig, trace2,'LineWidth',1,'Color',app.red);

                % Set plot axes
                xlim(app.CardiacNavFig, [0 length(trace1)+1]);
                ylim(app.CardiacNavFig, [minPlot maxPlot]);

            end

            hold(app.CardiacNavFig,'off');
            drawnow limitrate;

        end % PlotCardiacNavigatorFcn


        % Plot the navigator with respiratory filtering
        function PlotRespirationNavigatorFcn(app)

            % Clear the image
            cla(app.RespirationNavFig);
            hold(app.RespirationNavFig,'on');

            if app.validFilterFlag

                % Display x-range
                startpoint = app.StartEditField.Value;
                endpoint = app.StartEditField.Value+app.RangeEditField.Value-1;

                % Extract part of the navigator that will be shown
                trace1 = app.retroNavPars.PCANav(startpoint:endpoint);
                trace2 = app.retroNavPars.respNav(startpoint:endpoint);

                % Scale the filtered signal for viewing purposes
                trace2 = 0.25*max(trace1)*trace2;

                % Plot respiratory window masks in transparent red
                maxplt = 1.1*max(trace1);
                minplt = 1.1*min(trace1);
                if app.stopNavMovieFlag
                    steps = 1;
                else
                    steps = 4;
                end
                for i=startpoint:steps:endpoint
                    if app.retroNavPars.respWindow(i)==1
                        win=line(app.RespirationNavFig,[i-app.StartEditField.Value+1;i-app.StartEditField.Value+1],[app.retroNavPars.respWindow(i)*minplt;app.retroNavPars.respWindow(i)*maxplt],'Color',app.red,'LineWidth',1);
                        win.Color(4) = 0.1*steps; % transparancy
                    end
                end

                % Show the detected trigger points (only when movie is not running)
                if app.stopNavMovieFlag
                    triggerpoints = app.retroNavPars.respTrigPoints((app.retroNavPars.respTrigPoints>=startpoint) & (app.retroNavPars.respTrigPoints<=endpoint));
                    if length(triggerpoints) < 10
                        for i = 1:length(triggerpoints)
                            win=plot(app.RespirationNavFig,[triggerpoints(i)-startpoint+1,triggerpoints(i)-startpoint+1],[minplt,maxplt],'black','LineWidth',1);
                            win.Color(4) = 0.2; % transparancy
                        end
                    end
                end

                % Plot raw navigator
                plot(app.RespirationNavFig, trace1,'Color',app.blue);

                % Plot navigator signal filtered for respiratory motion
                plot(app.RespirationNavFig, trace2,'LineWidth',1,'Color',app.red);

                % Set plot axes
                xlim(app.RespirationNavFig, [0 length(trace1)]);
                ylim(app.RespirationNavFig, [minplt maxplt]);

            end

            hold(app.RespirationNavFig,'off');
            drawnow limitrate;

        end % PlotRespirationNavigatorFcn


        % Plot the time-heartrate and time-respirationrate plots
        function PlotRatesFcn(app)

            % Clear the images
            cla(app.HeartRateTimeFig);
            cla(app.RespRateTimeFig);

            if app.validFilterFlag

                hr = app.retroNavPars.heartRateTime;
                hrf = app.retroNavPars.heartRateTimeFiltered;
                resp = app.retroNavPars.respRateTime;
                respf = app.retroNavPars.respRateTimeFiltered;

                % Y-scale
                maxHeart = round(2*app.retroNavPars.meanHeartRate);
                maxResp = round(2*app.retroNavPars.meanRespRate);
                maxHeart(maxHeart<100) = 100;
                maxResp(maxResp<50) = 50;

                % Show the time-heartrate plot
                hold(app.HeartRateTimeFig,'on');
                plot(app.HeartRateTimeFig, app.retroDataPars.TR*app.retroNavPars.heartTrigPoints(1:end-1)'/1000, hr,'.','Color',app.blue);                 % heart rate
                plot(app.HeartRateTimeFig, app.retroDataPars.TR*app.retroNavPars.heartTrigPoints(1:end-1)'/1000, hrf,'LineWidth',2,'Color',app.red);   % trend line
                axis(app.HeartRateTimeFig,[0 app.retroDataPars.TR*app.retroNavPars.heartTrigPoints(end)/1000 0 maxHeart]);
                hold(app.HeartRateTimeFig,'off');
               
                % Show the time-respirationrate plot
                hold(app.RespRateTimeFig,'on');
                plot(app.RespRateTimeFig, app.retroDataPars.TR*app.retroNavPars.respTrigPoints(1:end-1)'/1000,resp,'.','Color',app.blue);                % respiration rate
                plot(app.RespRateTimeFig, app.retroDataPars.TR*app.retroNavPars.respTrigPoints(1:end-1)'/1000,respf,'LineWidth',2,'Color',app.red);  % trend line
                axis(app.RespRateTimeFig,[0 app.retroDataPars.TR*app.retroNavPars.respTrigPoints(end)/1000 0 maxResp]);
                hold(app.RespRateTimeFig,'off');
             
                % Plot the dynamic or multi-slice windows
                app.PlotDynamicsWindowsFcn;
                app.PlotMultiSliceWindowsFcn;

            end

        end % PlotRatesFcn


        % Plot the separation into dynamics in the time-heart-rate and time-respiration-rate plots
        function PlotDynamicsWindowsFcn(app)

            % Delete previous windows
            delete(app.dPlot1);
            delete(app.dPlot2);
            delete(app.dPlot1Txt);
            delete(app.dPlot2Txt);

            if app.DynamicsEditField.Value > 1

                % Y-scale
                maxHeart = round(2*app.retroNavPars.meanHeartRate);
                maxResp = round(2*app.retroNavPars.meanRespRate);
                maxHeart(maxHeart<100) = 100;
                maxResp(maxResp<50) = 50;

                if app.retroDataPars.vfaDataFlag
                    dynamicLabel = 'fa';
                else
                    dynamicLabel = 'd';
                end

                hold(app.HeartRateTimeFig,'on');

                % Show shaded areas in the time-heartrate plot
                heartRange = app.retroDataPars.TR*app.retroNavPars.heartTrigPoints(end-1)/1000;
                step = heartRange / app.DynamicsEditField.Value;
                pgon = polyshape();
                for i = 1:2:app.DynamicsEditField.Value
                    xStart = (i-1)*step;
                    xEnd = i*step;
                    pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[5 maxHeart-5 maxHeart-5 5]));
                end
                app.dPlot1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                % Add dynamic frame numbers
                if app.DynamicsEditField.Value < 15
                    idx = 1:app.DynamicsEditField.Value;
                    txtPosX = (idx - 0.5 - app.DynamicsEditField.Value / 25)*step;
                    txtPosY = 0.95*maxHeart*ones(app.DynamicsEditField.Value,1)';
                    txtStr = strcat(dynamicLabel,num2str(idx(:)));
                    app.dPlot1Txt = text(app.HeartRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                end

                hold(app.HeartRateTimeFig,'off');

                hold(app.RespRateTimeFig,'on');

                % Show in the time-respirationrate plot
                heartRange = app.retroDataPars.TR*app.retroNavPars.respTrigPoints(end-1)/1000;
                step = heartRange / app.DynamicsEditField.Value;
                pgon = polyshape();
                for i = 1:2:app.DynamicsEditField.Value
                    xStart = (i-1)*step;
                    xEnd = i*step;
                    pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[0.5 maxResp-0.5 maxResp-0.5 0.5]));
                end
                app.dPlot2 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                % Add dynamic frame numbers
                if app.DynamicsEditField.Value < 15
                    idx = 1:app.DynamicsEditField.Value;
                    txtPosX = (idx - 0.5 - app.DynamicsEditField.Value / 25)*step;
                    txtPosY = 0.95*maxResp*ones(app.DynamicsEditField.Value,1)';
                    txtStr = strcat(dynamicLabel,num2str(idx(:)));
                    app.dPlot2Txt = text(app.RespRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                end

                hold(app.RespRateTimeFig,'off');

            end

        end % PlotDynamicsWindowsFcn


        % Plot the separation into slices for the time-heartrate and time-respiratiorate plots
        function PlotMultiSliceWindowsFcn(app)

            % Delete previous windows
            delete(app.msPlot1);
            delete(app.msPlot2);
            delete(app.msPlot1Txt);
            delete(app.msPlot2Txt);

            if strcmp(app.retroDataPars.dataType,'2Dms')

                % Y-scale
                maxHeart = round(2*app.retroNavPars.meanHeartRate);
                maxResp = round(2*app.retroNavPars.meanRespRate);
                maxHeart(maxHeart<100) = 100;
                maxResp(maxResp<50) = 50;

                hold(app.HeartRateTimeFig,'on');

                % Show shaded areas in the time-heartrate plot
                heartRange = app.retroDataPars.TR*app.retroNavPars.heartTrigPoints(end-1)/1000;
                step = heartRange / app.NzViewField.Value;
                pgon = polyshape();
                for i = 1:2:app.NzViewField.Value
                    xStart = (i-1)*step;
                    xEnd = i*step;
                    pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[5 maxHeart-5 maxHeart-5 5]));
                end
                app.msPlot1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                % Add dynamic frame numbers
                if app.NzViewField.Value < 15
                    idx = 1:app.NzViewField.Value;
                    txtPosX = (idx - 0.5 - app.NzViewField.Value / 50)*step;
                    txtPosY = 0.95*maxHeart*ones(app.NzViewField.Value,1)';
                    txtStr = strcat('s',num2str(idx(:)));
                    app.msPlot1Txt = text(app.HeartRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                end

                hold(app.HeartRateTimeFig,'off');

                hold(app.RespRateTimeFig,'on');

                % Show in the time-respirationrate plot
                heartRange = app.retroDataPars.TR*app.retroNavPars.respTrigPoints(end-1)/1000;
                step = heartRange / app.NzViewField.Value;
                pgon = polyshape();
                for i = 1:2:app.NzViewField.Value
                    xStart = (i-1)*step;
                    xEnd = i*step;
                    pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[0.5 maxResp-0.5 maxResp-0.5 0.5]));
                end
                app.msPlot2 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                % Add dynamic frame numbers
                if app.NzViewField.Value < 15
                    idx = 1:app.NzViewField.Value;
                    txtPosX = (idx - 0.5 - app.NzViewField.Value / 50)*step;
                    txtPosY = 0.95*maxResp*ones(app.NzViewField.Value,1)';
                    txtStr = strcat('s',num2str(idx(:)));
                    app.msPlot2Txt = text(app.RespRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                end

                hold(app.RespRateTimeFig,'off');

            end

        end % PlotMultiSliceWindowsFcn


        % Plot windows in time-heartrate and time-respirationrate plots which indicate the range of data shown in the navigator plots
        function PlotRateLimitsFcn(app)

            % Delete previous windows
            delete(app.cardRateWindow);
            delete(app.respRateWindow);

            % Start and ending of the window
            xStart = app.StartEditField.Value*app.retroDataPars.TR/1000;
            xEnd = xStart + app.RangeEditField.Value*app.retroDataPars.TR/1000;

            % Y-scale
            maxHeart = round(2*app.retroNavPars.meanHeartRate);
            maxResp = round(2*app.retroNavPars.meanRespRate);
            maxHeart(maxHeart<100) = 100;
            maxResp(maxResp<50) = 50;

            % Time-heartrate plot navigator inspection window
            hold(app.HeartRateTimeFig,'on');
            pgon = polyshape([xStart xStart xEnd xEnd],[5 maxHeart-5 maxHeart-5 5]);
            app.cardRateWindow = plot(app.HeartRateTimeFig, pgon,'FaceColor','black','FaceAlpha',0.25,'LineStyle','none');
            hold(app.HeartRateTimeFig,'off');

            % Time-respirationrate plot navigator inspection window
            hold(app.RespRateTimeFig,'on');
            pgon = polyshape([xStart xStart xEnd xEnd],[0.5 maxResp-0.5 maxResp-0.5 0.5]);
            app.respRateWindow = plot(app.RespRateTimeFig, pgon,'FaceColor','black','FaceAlpha',0.25,'LineStyle','none');
            hold(app.RespRateTimeFig,'off');

        end % PlotRateLimitsFcn


        % Plot the data window in time-heartrate and time-respirationrate plots
        function PlotDataWindowFcn(app)

            % Delete previous windows
            delete(app.dWin1);
            delete(app.dWin2);
            delete(app.dWin3);
            delete(app.dWin4);

            % Y-scale
            maxHeart = round(2*app.retroNavPars.meanHeartRate);
            maxResp = round(2*app.retroNavPars.meanRespRate);
            maxHeart(maxHeart<100) = 100;
            maxResp(maxResp<50) = 50;

            if app.IncludeCheckBox.Value == true

                % Time-heartrate plot window
                hold(app.HeartRateTimeFig,'on');
                pgon = polyshape([app.IncludeStartSlider.Value app.IncludeStartSlider.Value app.IncludeEndSlider.Value app.IncludeEndSlider.Value],[5 maxHeart-5 maxHeart-5 5]);
                app.dWin1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.brightgreen,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.HeartRateTimeFig,[0 app.retroDataPars.TR*app.retroNavPars.heartTrigPoints(end)/1000 0 maxHeart]);
                hold(app.HeartRateTimeFig,'off');
             
                % Time-respirationrate plot window
                hold(app.RespRateTimeFig,'on');
                pgon = polyshape([app.IncludeStartSlider.Value app.IncludeStartSlider.Value app.IncludeEndSlider.Value app.IncludeEndSlider.Value],[0.5 maxResp-0.5 maxResp-0.5 0.5]);
                app.dWin2 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.brightgreen,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.RespRateTimeFig,[0 app.retroDataPars.TR*app.retroNavPars.respTrigPoints(end)/1000 0 maxResp]);
                hold(app.RespRateTimeFig,'off');
                drawnow;

            end

            if app.ExcludeCheckBox.Value == true

                % Time-heartrate plot window
                hold(app.HeartRateTimeFig,'on');
                pgon = polyshape([app.ExcludeStartSlider.Value app.ExcludeStartSlider.Value app.ExcludeEndSlider.Value app.ExcludeEndSlider.Value],[5 maxHeart-5 maxHeart-5 5]);
                app.dWin3 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.red,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.HeartRateTimeFig,[0 app.retroDataPars.TR*app.retroNavPars.heartTrigPoints(end)/1000 0 maxHeart]);
                hold(app.HeartRateTimeFig,'off');
              
                % Time-respirationrate plot window
                hold(app.RespRateTimeFig,'on');
                pgon = polyshape([app.ExcludeStartSlider.Value app.ExcludeStartSlider.Value app.ExcludeEndSlider.Value app.ExcludeEndSlider.Value],[0.5 maxResp-0.5 maxResp-0.5 0.5]);
                app.dWin4 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.red,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.RespRateTimeFig,[0 app.retroDataPars.TR*app.retroNavPars.respTrigPoints(end)/1000 0 maxResp]);
                hold(app.RespRateTimeFig,'off');
                drawnow;

            end

            % Set the acquisition parameters
            app.NrKlinesViewField.Value = nnz(app.retroDataPars.includeWindow.*app.retroDataPars.excludeWindow);
            app.RepetitionsViewField.Value = round(app.NrKlinesViewField.Value/(app.kLinesRepetitionViewField.Value*app.retroDataPars.dimz));
            app.ScanTimeViewField.Value = datestr(seconds(app.NrKlinesViewField.Value*app.retroDataPars.TR/1000),'HH:MM:SS.FFF');
            app.TimeDynViewField.Value = datestr(seconds(app.acqDur/app.DynamicsEditField.Value),'HH:MM:SS.FFF');

        end % PlotDataWindowFcn

        % Plot the power spectrum of the raw navigator signal
        function PlotNavigatorSpectrumFcn(app)

            % Limits
            startFreq = find(app.retroNavPars.frequency > 10,1);
            endFreq   = find(app.retroNavPars.frequency > 800,1);

            if strcmp(app.LogLinButton.Text,'lin')
                intensity = app.retroNavPars.powerSpectrum;
            else
                intensity = log(app.retroNavPars.powerSpectrum);
            end

            yMax = 1.3*max(intensity(startFreq:endFreq));
            yMin = -0.05*yMax;
            if isinf(yMax) || isinf(yMin)
                yMin =0;
                yMax = 1;
            end
            yMax(yMax<yMin) = yMin+1;
            cla(app.SpectrumFig);
            hold(app.SpectrumFig,'on');
            xlim(app.SpectrumFig, [10 800]);
            ylim(app.SpectrumFig, [yMin, yMax+1]);
            plot(app.SpectrumFig,app.retroNavPars.frequency,intensity,'Color',app.blue);

            app.retroNavPars.bandwidthHR = app.HeartWidthEditField.Value;
            app.retroNavPars.bandwidthRR = app.RespirationWidthEditField.Value;

            % Plot the butter filter for cardiac motion filtering
            flt = app.retroNavPars.filterShape(app.retroDataPars, app, 'heart');
            area(app.SpectrumFig,flt(:,1),0.95*yMax*flt(:,2),'FaceColor',app.brightgreen,'FaceAlpha',0.1,'EdgeColor',app.brightgreen,'LineStyle', 'none');
            plot(app.SpectrumFig,[app.retroNavPars.detectedHR,app.retroNavPars.detectedHR],[0,yMax],'Color',app.brightgreen,'LineWidth',1,'LineStyle','--');
            text(app.SpectrumFig,app.retroNavPars.detectedHR+10,0.9*yMax,[num2str(app.retroNavPars.detectedHR),' bpm']);

            % Plot the butter filter for the repiration motion filtering
            try
                flt = app.retroNavPars.filterShape(app.retroDataPars, app, 'resp');
                area(app.SpectrumFig,flt(:,1),0.95*yMax*flt(:,2),'FaceColor',app.brightgreen,'FaceAlpha',0.1,'EdgeColor',app.brightgreen,'LineStyle', 'none');
                plot(app.SpectrumFig,[app.retroNavPars.detectedRR,app.retroNavPars.detectedRR],[0,yMax],'Color',app.brightgreen,'LineWidth',1,'LineStyle','--');
                text(app.SpectrumFig,app.retroNavPars.detectedRR+10,0.9*yMax,[num2str(app.retroNavPars.detectedRR),' bpm']);
            catch ME
                app.TextMessage(ME.message);
            end

            hold(app.SpectrumFig,'off');
            drawnow;

        end % PlotNavigatorSpectrumFcn


        % Autoset level and window
        function AutoIntensityFcn(app,images)

            scale = double(max(abs(images(:))));

            app.LevelScaleSlider.Value = 0;
            app.WindowScaleSlider.Value = 0;

            app.LevelScaleSlider.Limits = [0 scale+1];
            app.WindowScaleSlider.Limits = [0 scale+1];

            app.level = 2*double(mean(abs(images((abs(images)>0.05*scale) & (abs(images)<0.95*scale)))));
            app.level(app.level>scale) = scale;
            app.level(isnan(app.level)) = scale/2;

            app.window = 1.8*app.level;
            app.window(app.window>scale) = scale;

            app.LevelScaleSlider.Value = app.level;
            app.WindowScaleSlider.Value = app.window;

        end % AutoIntensityFcn


        % Plot the current movie frame
        function PlotCurrentMovieFrameFcn(app)

            try

                % MRI movie
                im = squeeze(app.retroRecoPars.movieApp(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                [yl,xl] = size(im); % needs to be here, in case app.MovieButton changes during this routine
                delete(app.cbar);
                if app.MovieButton.Value == 1
                    if app.retroDataPars.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        daspect(app.MovieFig,[1 app.retroDataPars.aspectratio 1]);
                    else
                        daspect(app.MovieFig,[app.retroDataPars.aspectratio 1 1]);
                    end
                    app.moviePlot = imshow(rot90(im),[app.level-app.window/2 app.level+app.window/2],'Parent',app.MovieFig);
                end

                % Averages
                if app.AveragesButton.Value == 1
                    maxScale = round(max(app.retroKspacePars.kSpaceAvg(:)));
                    c = [0 0 0 ; summer(maxScale-1)];
                    im = squeeze(app.retroKspacePars.kSpaceAvg(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                    [yl,xl] = size(im);
                    if app.retroDataPars.PHASE_ORIENTATION
                        im = rot90(im,-1);
                    end
                    app.moviePlot = imshow(rot90(im),[0,maxScale],'ColorMap',c,'Parent',app.MovieFig);
                    app.cbar = colorbar(app.MovieFig,'location','eastoutside','TickLabelsMode','auto','FontSize',12);
                end

                % Raw k-space
                if app.kSpaceButton.Value == 1
                    im = squeeze(abs(app.retroKspacePars.kSpace{app.MovieCoilSpinner.Value}(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value)));
                    [yl,xl] = size(im);
                    if app.retroDataPars.PHASE_ORIENTATION
                        im = rot90(im,-1);
                    end
                    app.moviePlot = imshow(rot90(im),[app.level-app.window/2 app.level+app.window/2],'Parent',app.MovieFig);
                end

                % Sense map
                if (app.SenseMapButton.Value == 1) && (app.validSenseMapFlag)
                    im = squeeze(app.retroRecoPars.senseMap(:,:,app.MovieSliceEditField.Value,app.SenseMapSpinner1.Value,app.SenseMapSpinner2.Value));
                    [yl,xl] = size(im);
                    if app.retroDataPars.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        daspect(app.MovieFig,[1 app.retroDataPars.aspectratio 1]);
                    else
                        daspect(app.MovieFig,[app.retroDataPars.aspectratio 1 1]);
                    end
                    app.moviePlot = imshow(rot90(im),[app.level-app.window/2 app.level+app.window/2],'Parent',app.MovieFig);
                end

                xlim(app.MovieFig, [0 xl+1]);
                ylim(app.MovieFig, [0 yl+1]);

                drawnow;

            catch
            end

        end % PlotCurrentMovieFrameFcn


        % Fast update the current movie frame
        function UpdateMovieFrameFcn(app)

            % MRI movie
            if app.MovieButton.Value == 1
                im = squeeze(app.retroRecoPars.movieApp(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                if app.retroDataPars.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                set(app.moviePlot, 'CData', rot90(im));
            end

            % Averages
            if app.AveragesButton.Value == 1
                im = squeeze(app.retroKspacePars.kSpaceAvg(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                if app.retroDataPars.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                set(app.moviePlot, 'CData', rot90(im));
            end

            % Raw k-space
            if app.kSpaceButton.Value == 1
                im = squeeze(abs(app.retroKspacePars.kSpace{app.MovieCoilSpinner.Value}(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value)));
                if app.retroDataPars.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                set(app.moviePlot, 'CData', rot90(im));
            end

            % Sense map
            if (app.SenseMapButton.Value == 1) && (app.validSenseMapFlag)
                im = squeeze(abs(app.retroRecoPars.senseMap(:,:,app.MovieSliceEditField.Value,app.SenseMapSpinner1.Value,app.SenseMapSpinner2.Value)));
                if app.retroDataPars.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                set(app.moviePlot, 'CData', rot90(im));
            end

            drawnow;

        end % UpdateMovieFrameFcn


        % Plot L curve
        function PlotLcurveFig(app,lambda,x,y)

            % Plot the L-curve and model-error curve
            if length(x)>1

                try
                    app.LcurveFig.XLim = [min(x) max(x)];
                    app.LcurveFig.YLim = [min(y) max(y)];
                    app.LcurveModelErrorFig.XLim = [app.Lambda1EditField.Value app.Lambda2EditField.Value];
                    app.LcurveModelErrorFig.YLim = [min(x) max(x)];
                catch ME
                    app.TextMessage(ME.message);
                end

                loglog(app.LcurveFig,x,y,'o','MarkerEdgeColor',[0 .5 .5],'MarkerFaceColor',app.blue,'LineWidth',1.5);
                loglog(app.LcurveModelErrorFig,lambda(1:length(x)),x,'o','MarkerEdgeColor',[0 .5 .5],'MarkerFaceColor',app.blue,'LineWidth',1.5);

            end

        end % PlotLcurveFig


    end % Methods (Access = private)


    % ----------------------------------------------
    % Public functions, available outside the app
    % ----------------------------------------------


    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message)

            try
                newText = [message ; app.MessageBox.Value];
                if size(newText,1)>100 
                    newText = newText(1:100,1);
                end
                app.MessageBox.Value = newText;
                drawnow;
            catch
            end

        end % TextMessage


        % Status icon
        function SetStatus(app,status)

            switch status

                case 0
                    app.StatusImage.Position = [608,64,30,30];
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.Text = 'OK';
                    app.StatusLabel.FontColor = app.green;

                case 1
                    app.StatusImage.Position = [608,64,30,30];
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.Text = 'Warning';
                    app.StatusLabel.FontColor = app.orange;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end

                case 2
                    app.StatusImage.Position = [608,64,30,30];
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.Text = 'ERROR';
                    app.StatusLabel.FontColor = app.brightred;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end

                case 3
                    app.StatusImage.Position = [611,67,24,24];
                    app.StatusImage.ImageSource = 'busy2.gif';
                    app.StatusLabel.Text = 'BUSY';
                    app.StatusLabel.FontColor = app.green;

                case 4
                    app.StatusImage.Position = [608,64,30,30];
                    app.StatusImage.ImageSource = 'auto.png';
                    app.StatusLabel.Text = 'Auto';
                    app.StatusLabel.FontColor = app.blue;

            end

            drawnow;

        end % SetStatus


    end % Methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)

            % Disable unnecessary warnings
            warning off;
            clc;

            % Start the parallel pool if it is not running already
            p = gcp('nocreate');
            if isempty(p)
                try
                    parpool(8);
                catch
                    parpool;
                end
            end

            % Create empty objects of classes
            app.retroDataPars = retroData();
            app.retroNavPars = retroNav();
            app.retroKspacePars = retroKspace();
            app.retroRecoPars = retroReco();

            % Empty file import and export paths
            app.mrdImportPath = '';
            app.mrdAutoImportPath = '';
            app.mrdAutoFile = '';
            app.dicomExportPath = '';
            app.gifExportPath = '';

            % Initalize flags
            app.validFilterFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.validSenseMapFlag = false;
            app.stopMovieFlag = true;
            app.stopLcurveFlag = true;
            app.stopNavMovieFlag = true;

            % Add subfolders to search path
            if (~isdeployed)
                app.oldDir = pwd;
                info = dir(which('retrospective.mlapp'));
                app.appDir = info.folder;
                cd(app.appDir);
                addpath(genpath(pwd));
                try
                    rmpath(genpath(strcat(pwd,filesep,'Retrospective/')));
                catch
                end
                try
                    rmpath(genpath(strcat(pwd,filesep,'Retrospective_resources/')));
                catch
                end
                try
                    rmpath(genpath(strcat(pwd,filesep,'Standalone installers/')));
                catch
                end
            end

            % Load all icons & colors
            app.SetAppLayoutPropertiesFcn;

            % Add the bart path to the environment variable if available
            try
                if ismac
                    % [~,result] = system('uname -m');
                    [~,result] = system('sysctl -in sysctl.proc_translated');
                    if strcmp(strtrim(result),'1') % Running ROSETTA2, assuming arm architecture 
                        folderName = fileread('bartpath_osx_arm.txt');
                        app.TextMessage('Apple arm version');
                    else
                        folderName = fileread('bartpath_osx_intel.txt');
                        app.TextMessage('Apple Intel version');
                    end
                    setenv('TOOLBOX_PATH', folderName);
                else
                    app.TextMessage('PC Intel version');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            try
                bart(app,'version');
                app.bartDetectedFlag = true;
            catch ME
                app.bartDetectedFlag = false;
                app.TextMessage(ME.message);
            end

            if app.bartDetectedFlag
                app.TextMessage('BART toolbox detected ...');
            else
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end
            
            % Determine the current directory in case of deployed app
            if isdeployed
                try
                    [~,result] = system('path');
                    app.currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    app.TextMessage(strcat("Path: ",app.currentDir));
                catch
                    app.currentDir = '';
                end
            end
            
            % Set the import directories
            app.SetMRDimportPathFcn;
          
            % Set buttons
            app.LoadMRDButton.Enable = 'on';
         
        end

        % Callback function: DataFile, LoadMRDButton
        function LoadMRDfileButtonPushed(app, event)

            % Indicate busy
            app.SetLightsFcn(0);
            app.SwitchOffAllButtonsFcn;
            app.Lamp1.Color = app.yellow;
            app.BusyImage1.Visible = 'on';
            app.SetStatus(3);
        
            % Reset object of class retroData
            app.retroDataPars = retroData();
            app.retroNavPars = retroNav();
            app.retroKspacePars = retroKspace();
            app.retroRecoPars = retroReco();
            tempdir = app.mrdImportPath;

            % Reset flags & some parameters
            app.validFilterFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.validSenseMapFlag = false;
            app.stopMovieFlag = true;
            app.stopLcurveFlag = true;
            app.stopNavMovieFlag = true;
            app.NavigatorFlipSwitch.Value = 'Up';
            app.ProgressGauge.Value = 0;
            drawnow;
            
            try

                % Select a DATA file manually or automatically
                if app.AutoRecoCheckBox.Value

                    [filePath,name,ext] = fileparts(app.mrdAutoFile);
                    app.mrdImportPath = filePath;
                    mrdFileName = strcat(name,ext);

                else

                    app.RetrospectiveUIFigure.Visible = 'off';
                    [mrdFileName,app.mrdImportPath] = uigetfile(strcat(app.mrdImportPath,filesep,{'*.mrd;*.orig;*.job0'}));
                    app.RetrospectiveUIFigure.Visible = 'on';

                end

                % Import the data
                if ischar(mrdFileName)

                    % File selected
                    app.mrdFile = [app.mrdImportPath,mrdFileName];

                    if strcmp(mrdFileName,"fid.orig") || strcmp(mrdFileName,"rawdata.job0")

                        % ------------------------------------------------------------------------
                        % B-type scanner data import
                        % ------------------------------------------------------------------------

                        [data, parameters] = app.retroDataPars.importB(app);
                        app.DataFile.Value = mrdFileName;
                        app.retroDataPars.rprFlag = false;

                        % Construct the retroData parameter class
                        app.retroDataPars = retroData(parameters, data);

                        % Check if the data is a valid file with a motion navigator acquisition
                        app.retroDataPars = app.retroDataPars.acquisitionType(app);

                    else

                        % ------------------------------------------------------------------------
                        % MR Solutions data import
                        % ------------------------------------------------------------------------

                        mrdStart = extractBefore(mrdFileName,'_');
                        fList = dir(fullfile(app.mrdImportPath,[mrdStart,'*.MRD']));

                        % Check wether the filelist contains already a reconstructed MRD file, remove this one from the list
                        if length(fList) > 1
                            for i = length(fList):-1:1
                                if contains(fList(i).name,'retro') || contains(fList(i).name,'p2roud')
                                    fList(i) = [];
                                end
                            end
                        end

                        if ~isempty(fList)

                            % Filename tag
                            app.DataFile.Value = fList(1).name;
                            [~,app.tag,~] = fileparts([fList(1).folder,filesep,fList(1).name]);

                            % Load the data
                            app.TextMessage('Loading k-space data ...');
                            nrc = length(fList);
                            data = cell(nrc);
                            for i=1:nrc
                                app.TextMessage(strcat('Loading coil',{' '},num2str(i)));
                                [data{i},~,parameters,~] = app.retroDataPars.importMRD(fullfile(fList(i).folder,fList(i).name),'seq','seq');
                            end
                            parameters.coil_scaling = ones(nrc,1);
                            parameters.nr_coils = nrc;

                            % Construct the retroData parameter class
                            app.retroDataPars = retroData(parameters, data);

                            % Read the full mrd-file footer
                            app.retroDataPars = app.retroDataPars.readMrdFooter(app, fullfile(fList(1).folder,fList(1).name));

                            % Read the rpr file
                            rprfilename = [fList(1).folder,filesep,app.tag,'.rpr'];
                            app.retroDataPars = app.retroDataPars.readRprfile(app, rprfilename);
                          
                            % Check if the data is a valid file with a motion navigator acquisition
                            app.retroDataPars = app.retroDataPars.acquisitionType(app);
                            app.NavigatorEndEditField.Value = app.retroDataPars.primary_navigator_point;

                        end

                    end

                    % ------------------------------------------------------------------------
                    % Some additional checks on the data
                    % ------------------------------------------------------------------------

                    % Check if there are sufficient number of experiments
                    app.retroDataPars = app.retroDataPars.checkNumberOfExperiments(app);
                   
                    % Check for averaging
                    app.retroDataPars = app.retroDataPars.checkNumberOfAverages(app);
                   
                    % 3D multi-slab data is not supported
                    app.retroDataPars = app.retroDataPars.checkForMultiSlab(app);
                   
                    % Determine whether 3D data has multiple flip-angles
                    app.retroDataPars = app.retroDataPars.checkForVFA(app);
                    app.FAViewField.Value = app.retroDataPars.VFA_size;

                    % Check that TR is not equal to zero
                    app.retroDataPars = app.retroDataPars.checkTR(app);
                
                end

            catch ME

                app.TextMessage(ME.message);
                app.retroDataPars.validDataFlag = false;
         
            end

            if app.retroDataPars.validDataFlag

                % Initialize
                app.SetSpeciesFcn;                         % set initial filtering values for mouse/rat
                app.RespirationToggleCheckBox.Value = 0;   % set reconstruction to expiration
                app.DataParametersFcn;                     % set intial data parameters
                app.NavPointEditFieldValueChanged;         % extract navigator

                % Reset buttons
                app.SetLightsFcn(1);
                app.BusyImage1.Visible = 'off';
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(false);
                app.CineRecoButtonsFcn(false);
                app.MovieButtons(false);
                app.SetStatus(0);

            else

                % Reset buttons
                app.LoadMRDButton.Enable = 'on';
                app.ExitButton.Enable = 'on';
                app.Lamp1.Color = app.brightred;
                app.BusyImage1.Visible = 'off';
                app.NavSelButtonsFcn(false);
                app.NavViewingButtonsFcn(false);
                app.CineRecoButtonsFcn(false);
                app.MovieButtons(false);
                app.SetStatus(2);

                % Reset some parameters
                app.mrdImportPath = tempdir;
                app.mrdAutoImportPath = tempdir;
                app.SetMRDimportPathFcn;

                % Error message
                app.TextMessage('ERROR: Not a valid data file ...');

            end

        end

        % Value changed function: DiscardEditField, NavigatorEndEditField, 
        % ...and 2 other components
        function NavPointEditFieldValueChanged(app, event)

            % Update the used navigator point and the number of navigator points used
            if app.NavigatorNRPointsEditField.Value > app.NavigatorEndEditField.Value
                app.NavigatorNRPointsEditField.Value = app.NavigatorEndEditField.Value;
            end
            app.retroDataPars.primary_navigator_point = app.NavigatorEndEditField.Value;
            app.retroDataPars.nr_nav_points_used = app.NavigatorNRPointsEditField.Value;
            app.retroDataPars.nr_nav_points_discarded = app.DiscardEditField.Value;

            % Update the data parameters
            app.DataParametersFcn;

            % Switch the data-window off
            app.IncludeCheckBox.Value = false;
            app.PlotDataWindowFcn;

            % Determine the value of the switch to flip the navigator
            app.retroNavPars = app.retroNavPars.readFlipSwitch(app);

            % Extract the navigator signals
            app.retroNavPars = app.retroNavPars.extractNavigator(app.retroDataPars);

            % Plot the raw navigator signal
            app.PlotRawNavigatorFcn;

            % Determine and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;

            % Plot or clear the other navigator graphs
            app.PlotCardiacNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            cla(app.HeartRateTimeFig);
            cla(app.RespRateTimeFig);

            % Reset buttons
            app.SetLightsFcn(1);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(131);
            app.NavSelButtonsFcn(true);
            app.ProgressGauge.Value = 0;

        end

        % Value changed function: CoilSpinner
        function CoilSpinnerValueChanged(app, event)

            % plot the raw navigator signal
            app.PlotRawNavigatorFcn;

            % show the samples-navigator plots
            app.PlotCardiacNavigatorFcn;
            app.PlotRespirationNavigatorFcn;

        end

        % Value changed function: HeartEditField, HeartWidthEditField, 
        % ...and 3 other components
        function FilterEditFieldValueChanged(app, event)

            app.validFilterFlag = false;

            if app.HeartWidthEditField.Value > app.HeartEditField.Value
                app.HeartWidthEditField.Value = app.HeartEditField.Value;
            end

            if app.RespirationWidthEditField.Value > app.RespirationEditField.Value
                app.RespirationWidthEditField.Value = app.RespirationEditField.Value;
            end

            app.retroNavPars.detectedHR = app.HeartEditField.Value;
            app.retroNavPars.bandwidthHR = app.HeartWidthEditField.Value;
            app.retroNavPars.detectedRR = app.RespirationEditField.Value;
            app.retroNavPars.bandwidthRR = app.RespirationWidthEditField.Value;
            app.retroNavPars.respPercentage = app.RespirationWindowEditField.Value;

            app.FilterButtonPushed;
            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: ResetFilterValuesButton
        function ResetFilterValuesButtonPushed(app, event)

            % reset filter values
            app.validFilterFlag = false;
            app.SetSpeciesFcn;
            app.CalculateNavigatorSpectrumFcn;
            app.FilterButtonPushed;
            app.PlotNavigatorSpectrumFcn;

        end

        % Selection changed function: SpeciesButton
        function SpeciesButtonSelectionChanged(app, event)

            app.MouseButton2.Value = app.MouseButton.Value;
            app.RatButton2.Value = app.RatButton.Value;
            app.ResetFilterValuesButtonPushed;

        end

        % Selection changed function: SpeciesButton2
        function SpeciesButton2SelectionChanged(app, event)

            app.MouseButton.Value = app.MouseButton2.Value;
            app.RatButton.Value = app.RatButton2.Value;
            app.ResetFilterValuesButtonPushed;

        end

        % Button down function: SpeciesButton, SpeciesButton2
        function SpeciesButtonButtonDown(app, event)

            app.ResetFilterValuesButtonPushed;

        end

        % Value changed function: RespirationToggleCheckBox
        function RespirationToggleCheckBoxValueChanged(app, event)
        
            app.ResetFilterValuesButtonPushed;

        end

        % Selection changed function: RecoPresetsButtonGroup
        function RecoPresetsButtonGroupSelectionChanged(app, event)

            % Update the reonstruction parameters
            app.SetRecoParametersFcn;
            app.CinePanelValueChanged;

        end

        % Button down function: RecoPresetsButtonGroup
        function RecoPresetsButtonGroupButtonDown(app, event)

            app.RecoPresetsButtonGroupSelectionChanged;

        end

        % Value changed function: AutoRecoCheckBox
        function AutoRecoCheckBoxValueChanged(app, event)

            % Color the canvas for visual indication of auto-reconstruction
            if app.AutoRecoCheckBox.Value
                app.StopButtonPushed;
                app.AutoSoundsCheckBox.Value = 0;
                app.SetMRDimportPathFcn;
            else
                app.AutoSoundsCheckBox.Value = 1;
                app.RetrospectiveUIFigure.Color = app.lightgrey;
                app.SetStatus(0);
            end
         
            while app.AutoRecoCheckBox.Value

                % Show autostatus
                app.TextMessage('Auto reco moded started ...');
                app.TextMessage(strcat('Watching:',{' '},app.mrdAutoImportPath));
                app.RetrospectiveUIFigure.Color = app.lightblue;
                app.SetStatus(4);
                
                % Initial mrd files in directory + sub-directories
                searchFiles = [app.mrdAutoImportPath,filesep,'**',filesep,'*.MRD'];
                fut = parfeval(@dir, 1, searchFiles);
                didFinish = wait(fut, 'finished', 5);  % Search directories and sub-directories for max 5 seconds
                if didFinish
                    fns = fetchOutputs(fut);
                    fOld = {fns(1:end).name};
                else
                    cancel(fut);
                    fOld = {''};
                end
                fIndx = 0;
                clear('fut');
                fDiff = [];
                
                % Loop and wait for new mrd file
                while app.AutoRecoCheckBox.Value && isempty(fDiff)

                    fut = parfeval(@dir, 1, searchFiles);
                    didFinish = wait(fut, 'finished', 5);  % Search directories and sub-directories for max 5 seconds
                    if didFinish
                        fns = fetchOutputs(fut);
                        fNew = {fns(1:end).name};
                    else
                        app.TextMessage('WARNING: Searching path takes too long, too many subdirectories ...');
                        cancel(fut);
                        fNew = {''};
                        app.AutoRecoCheckBox.Value = 0;
                        app.AutoSoundsCheckBox.Value = 1;
                        app.RetrospectiveUIFigure.Color = app.lightgrey;
                        app.SetStatus(1);
                    end
                    clear('fut');

                    [fDiff,fIndx] = setdiff(fNew,fOld);

                    drawnow;

                end

                % Continue when new mrd file is detected or autorecocheckbox == false
                if fIndx>0

                    app.TextMessage('New MRD file found ...');

                    app.mrdAutoFile = fullfile(fns(fIndx).folder,fns(fIndx).name);

                    pause(1);
                    app.LoadMRDfileButtonPushed;                    % Load data

                    if app.retroDataPars.validDataFlag
                        app.FilterButtonPushed;                     % Filter the navigator data

                        if app.validFilterFlag
                            app.SortKspaceButtonPushed;             % Sort the data into k-space

                            if app.validSortFlag
                                app.ReconstructButtonPushed;        % Reconstruct the images/movie

                                if app.validRecoFlag
                                    app.ExportMRDButtonPushed;      % Export new MRD file

                                end
                            end
                        end
                    end

                end

            end

        end

        % Value changed function: FilterOrderEditField, 
        % ...and 4 other components
        function FilterSettingsChanged(app, event)

            if app.MinRespRateEditField.Value > app.MaxRespRateEditField.Value
                app.MinRespRateEditField.Value = app.MaxRespRateEditField.Value;
            end

            if app.MinHeartRateEditField.Value > app.MaxHeartRateEditField.Value
                app.MinHeartRateEditField.Value = app.MaxHeartRateEditField.Value;
            end

            app.CalculateNavigatorSpectrumFcn;
            app.FilterButtonPushed;
            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: LogLinButton
        function LogLinButtonPushed(app, event)

            if strcmp(app.LogLinButton.Text,'log')
                app.LogLinButton.Text = 'lin';
            else
                app.LogLinButton.Text = 'log';
            end

            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)

            % indicate busy
            app.SetLightsFcn(1);
            app.Lamp2.Color = app.yellow;
            app.BusyImage2.Visible = 'on';
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;

            app.TextMessage('Filtering the navigator ...');

            % Reset some parameters
            app.validFilterFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.stopMovieFlag = true;
            app.stopLcurveFlag = true;
            app.stopNavMovieFlag = true;
            app.ProgressGauge.Value = 0;

            try

                % Filter settings from GUI
                app.retroNavPars.physioFilterSettings = [app.MinHeartRateEditField.Value ...
                    app.MaxHeartRateEditField.Value app.MinRespRateEditField.Value ...
                    app.MaxRespRateEditField.Value app.FilterOrderEditField.Value];
               
                % Filter for cardiac and respiratory motion
                app.retroNavPars = app.retroNavPars.filterNavPCA(app.retroDataPars);
                app.RespirationEditField.Value = app.retroNavPars.detectedRR;
                app.RespirationWidthEditField.Value = app.retroNavPars.bandwidthRR;
 
                % Extract cardiac trigger points
                app.retroNavPars = app.retroNavPars.trigPointsHeart(app.retroDataPars);

                % Calculate heart-time curves, trend-lines, and mean heart-rate
                app.retroNavPars = app.retroNavPars.calcHeartRate(app.retroDataPars);
                app.FinalHeartViewField.Value = app.retroNavPars.meanHeartRate;
 
                % Extract respiratory trigger points
                app.retroNavPars = app.retroNavPars.trigPointsResp(app.retroDataPars);

                % Calculate resp-time curves, trend-lines, and mean resp-rate
                app.retroNavPars = app.retroNavPars.calcRespRate(app.retroDataPars);
                app.FinalRespirationViewField.Value = app.retroNavPars.meanRespRate;

                % Make the respiration exclusion windows
                app.retroNavPars = app.retroNavPars.makeRespWindow(app,app.retroDataPars);
              
                % Let the app know the nav data is filtered
                app.validFilterFlag = true;

                % Plot the datawindow in the time-heartrate and time-respirationrate plots and datawindow
                app.PlotRatesFcn;
                app.PlotRateLimitsFcn;
                app.PlotDataWindowFcn;

                % Show the samples-navigator plots
                app.PlotCardiacNavigatorFcn;
                app.PlotRespirationNavigatorFcn;

                % Reset buttons
                app.SetLightsFcn(3);
                app.BusyImage2.Visible = 'off';
                app.SetDashboardButtonsFcn(135);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtons(false);
                app.SetStatus(0);

            catch ME

                % Let the app know the nav data is not filtered
                app.validFilterFlag = false;

                % Reset buttons
                app.SetLightsFcn(1);
                app.BusyImage2.Visible = 'off';
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(false);
                app.MovieButtons(false);
                app.SetStatus(2);
              
                % Error message
                app.TextMessage('ERROR: Something went wrong during filtering of the navigator ...')
                app.TextMessage(ME.message);
     
            end

        end

        % Value changed function: RangeEditField, StartEditField
        function DisplayEditFieldValuesChanged(app, event)

            value1 = app.StartEditField.Value;
            value2 = app.RangeEditField.Value;

            % Range should be at least 20
            value2(value2<20) = 20;

            % Range should not exceed 2000, else plot gets really slow and cluttered
            value2(value2>2000) = 2000;

            % Start + Range should not exceed the number of available k-lines
            if value1 + value2 > app.retroDataPars.nrKlines
                value1 = app.retroDataPars.nrKlines - value2;
            end

            % Report the values back to the user-interface
            app.StartEditField.Value = value1;
            app.RangeEditField.Value = value2;

            % Adjust the sliders also
            app.StartSlider.Value = app.StartEditField.Value;
            app.RangeSlider.Value = app.RangeEditField.Value;

            % Plot the raw navigator, cardiac filtered, and respiration filtered
            app.PlotRawNavigatorFcn;
            app.PlotCardiacNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            app.PlotRateLimitsFcn;

        end

        % Value changed function: RangeSlider, StartSlider
        function DisplaySlidersValueChanged(app, event)

            % Set the field values to match the sliders
            app.StartEditField.Value = round(app.StartSlider.Value);
            app.RangeEditField.Value = round(app.RangeSlider.Value);

            % Proceed as if the field values were changed
            app.DisplayEditFieldValuesChanged;

        end

        % Button pushed function: NavStartButton
        function NavStartButtonPushed(app, event)

            app.stopNavMovieFlag = false;

            while ~app.stopNavMovieFlag

                value1 = app.StartEditField.Value;
                value2 = app.RangeEditField.Value;

                value1 = value1 + app.navMovieSpeed;

                if value1 + value2 > app.retroDataPars.nrKlines
                    value1 = 1;
                end

                app.StartEditField.Value = value1;
                app.StartSlider.Value = value1;

                app.PlotRawNavigatorFcn;
                app.PlotCardiacNavigatorFcn;
                app.PlotRespirationNavigatorFcn;
                app.PlotRateLimitsFcn;

            end

        end

        % Button pushed function: NavStopButton
        function NavStopButtonPushed(app, event)

            app.stopNavMovieFlag = true;

            % Plot the raw navigator, cardiac filtered, and respiration filtered
            app.PlotRawNavigatorFcn;
            app.PlotCardiacNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            app.PlotRateLimitsFcn;

        end

        % Value changed function: NavFastSlowSwitch
        function NavFastSlowSwitchValueChanged(app, event)

            if app.NavFastSlowSwitch.Value == "slow"
                app.navMovieSpeed = 25;
            else
                app.navMovieSpeed = 100;
            end

        end

        % Value changed function: IncludeCheckBox
        function IncludeCheckBoxValueChanged(app, event)

            % Include window check-box on or off
            if app.IncludeCheckBox.Value == 1

                app.IncludeSliderValueChanged;

            else

                app.retroDataPars.includeWindow = ones(app.retroDataPars.nrKlines,1);
                app.PlotDataWindowFcn;
                app.CalculateNavigatorSpectrumFcn;
                app.PlotNavigatorSpectrumFcn;

                % Reset buttons
                app.SetLightsFcn(1);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.stopMovieFlag = true;
                app.SetStatus(0);
           
            end

        end

        % Value changed function: IncludeEndSlider, IncludeStartSlider
        function IncludeSliderValueChanged(app, event)

            % Read the sliders
            value1 = app.IncludeStartSlider.Value;      % start of the include-window in units of seconds
            value2 = app.IncludeEndSlider.Value;        % end of the include-window in units of seconds

            % End should be bigger than Start and length of the window should be at least 10 (arbitrary) repetitions
            if value2-value1 < round(10 * app.retroDataPars.nrKsteps * app.retroDataPars.TR / 1000)
                value2 = value1 + round(10 * app.retroDataPars.nrKsteps * app.retroDataPars.TR / 1000);
            end

            % End should be smaller than total acquisition time and length of the window should be at least 10 (arbitrary) repetitions
            if value2 > app.acqDur
                value2 = app.acqDur;
                value1 = value2 - (10 * app.retroDataPars.nrKsteps * app.retroDataPars.TR / 1000);
            end

            % set the values and sliders
            app.IncludeStartSlider.Value = value1;
            app.IncludeEndSlider.Value = value2;

            % set the value of the include window
            app.retroDataPars.includeWindow = zeros(app.retroDataPars.nrKlines,1);
            value1k = round(1000*value1/app.retroDataPars.TR)+1;    % start of the window in nr k-lines
            value2k = round(1000*value2/app.retroDataPars.TR);      % end of the window in nr k-lines
            if value2k > app.retroDataPars.nrKlines
                value2k = app.retroDataPars.nrKlines;
            end
            app.retroDataPars.includeWindow(value1k:value2k) = 1;

            % Plot the datawindow in the time-heartrate and time-respirationrate plots
            app.PlotDataWindowFcn;

            % Calculate and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;

            % Reset buttons
            app.SetLightsFcn(1);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(131);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.stopMovieFlag = true;
         
            % Check whether there is sufficient data left to do a reconstruction
            amountofklines = nnz(app.retroDataPars.includeWindow.*app.retroDataPars.excludeWindow);
            if amountofklines < 512
                app.TextMessage('ERROR: Not enough k-lines left for reconstruction, adjust data windows ...');
                app.FilterButton.Enable = 'off';
                app.SetStatus(2);
            else
                app.SetStatus(0);
            end

        end

        % Value changed function: ExcludeCheckBox
        function ExcludeCheckBoxValueChanged(app, event)

            % Exclude window check-box on or off
            if app.ExcludeCheckBox.Value == 1

                app.ExcludeSliderValueChanged;

            else

                app.retroDataPars.excludeWindow = ones(app.retroDataPars.nrKlines,1);
                app.PlotDataWindowFcn;
                app.CalculateNavigatorSpectrumFcn;
                app.PlotNavigatorSpectrumFcn;

                % Reset buttons
                app.SetLightsFcn(1);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.stopMovieFlag = true;
                app.SetStatus(0);
            
            end

        end

        % Value changed function: ExcludeEndSlider, ExcludeStartSlider
        function ExcludeSliderValueChanged(app, event)

            % Read the sliders
            value1 = app.ExcludeStartSlider.Value;      % start of the exclude-window in units of seconds
            value2 = app.ExcludeEndSlider.Value;        % end of the exclude-window in units of seconds

            % End should be bigger than Start and length of the window should be at least 10 (arbitrary) repetitions
            if value2-value1 < round(10 * app.retroDataPars.nrKsteps * app.retroDataPars.TR / 1000)
                value2 = value1 + round(10 * app.retroDataPars.nrKsteps * app.retroDataPars.TR / 1000);
            end

            % End should be smaller than total acquisition time and length of the window should be at least 10 (arbitrary) repetitions
            if value2 > app.acqDur
                value2 = app.acqDur;
                value1 = value2 - (10 * app.retroDataPars.nrKsteps * app.retroDataPars.TR / 1000);
            end

            % set the values and sliders
            app.ExcludeStartSlider.Value = value1;
            app.ExcludeEndSlider.Value = value2;

            % set the value of the exclude window
            app.retroDataPars.excludeWindow = ones(app.retroDataPars.nrKlines,1);
            value1k = round(1000*value1/app.retroDataPars.TR)+1;        % start of the window in nr k-lines
            value2k = round(1000*value2/app.retroDataPars.TR);          % end of the window in nr k-lines
            if value2k > app.retroDataPars.nrKlines
                value2k = app.retroDataPars.nrKlines;
            end
            app.retroDataPars.excludeWindow(value1k:value2k) = 0;

            % Plot the datawindow in the time-heartrate and time-respirationrate plots
            app.PlotDataWindowFcn;

            % Calculate and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;

            % Reset buttons
            app.SetLightsFcn(1);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(131);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.stopMovieFlag = true;
        
            % Check whether there is sufficient data left to do a reconstruction
            numberOfKlines = nnz(app.retroDataPars.includeWindow.*app.retroDataPars.excludeWindow);
            if numberOfKlines < 512
                app.TextMessage('ERROR: Not enough k-lines left for reconstruction, adjust data windows ...');
                app.FilterButton.Enable = 'off';
                app.SetStatus(2);
            else
                app.SetStatus(0);
            end

        end

        % Value changed function: DynamicsEditField, FramesEditField, 
        % ...and 2 other components
        function CinePanelValueChanged(app, event)

            % If running, stop the movie
            app.StopButtonPushed;

            % New number of frames
            app.nrFrames = app.FramesEditField.Value;
            app.nrDynamics = app.DynamicsEditField.Value;

            if app.nrDynamics > 1
                app.TimeDynViewField.Value = datestr(seconds(app.acqDur/app.DynamicsEditField.Value),'HH:MM:SS.FFF');
                app.IncludeCheckBox.Value = 0;
                app.IncludeCheckBoxValueChanged;
                app.ExcludeCheckBox.Value = 0;
                app.ExcludeCheckBoxValueChanged;
            else
                app.TimeDynViewField.Value = app.ScanTimeViewField.Value;
            end

            % Plot the dynamic windows in the rate plots
            app.PlotDynamicsWindowsFcn;

            % Stop movie & reset progress bar
            app.stopMovieFlag = true;
            app.ProgressGauge.Value = 0;
        
            % Reset buttons
            app.SetLightsFcn(3);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(135);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
        
        end

        % Button pushed function: SortKspaceButton
        function SortKspaceButtonPushed(app, event)

            % Indicate busy
            app.SetLightsFcn(3);
            app.Lamp3.Color = app.yellow;
            app.BusyImage3.Visible = 'on';
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;

            app.TextMessage('Sorting ...');

            % Reset some parameters
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.stopMovieFlag = true;
            app.stopLcurveFlag = true;
            app.stopNavMovieFlag = true;
            app.ProgressGauge.Value = 0;

            try
      
                % Extract all the raw k-space imaging data
                app.retroKspacePars = app.retroKspacePars.extractData(app.retroDataPars);

                % Sort by cardiac phase/relative binning, cardiac absolute binning, respiratory phase binning, or realtime
                switch app.RecoTypeDropDown.Value

                    case 'cardiac'
                        app.nrCardFrames = app.nrFrames;
                        app.nrRespFrames = 1;
                        app.retroKspacePars = app.retroKspacePars.assignBinTimes(app.retroNavPars, app);
                        app.retroKspacePars = app.retroKspacePars.assignBinFrames(app.retroNavPars, app.retroDataPars, app);

                    case 'cardiac abs'
                        app.nrCardFrames = app.nrFrames;
                        app.nrRespFrames = 1;
                        app.retroKspacePars = app.retroKspacePars.assignBinTimesAbs(app.retroNavPars, app.retroDataPars, app);
                        app.retroKspacePars = app.retroKspacePars.assignBinFramesAbs(app.retroNavPars, app.retroDataPars, app);

                    case 'respiratory'
                        app.nrCardFrames = 1;
                        app.nrRespFrames = app.nrFrames;
                        app.retroKspacePars = app.retroKspacePars.assignBinTimes(app.retroNavPars, app);
                        app.retroKspacePars = app.retroKspacePars.assignBinFrames(app.retroNavPars, app.retroDataPars, app);

                    case 'realtime'
                        app.nrCardFrames = app.nrFrames;
                        app.nrRespFrames = 1;
                        app.nrDynamics = 1;    % will be determined later depending on the number of heartbeats
                        app.retroKspacePars = app.retroKspacePars.assignBinTimes(app.retroNavPars, app);
                        app.retroKspacePars = app.retroKspacePars.assignBinFrames(app.retroNavPars, app.retroDataPars, app);

                end

                % Sort k-space
                switch app.retroDataPars.dataType

                    case {'2D','2Dms'}
                        if strcmp(app.RecoTypeDropDown.Value,'realtime')
                            app.retroKspacePars = app.retroKspacePars.fillKspace2D_RT(app.retroNavPars, app.retroDataPars, app);
                            app.DynamicsEditField.Value = app.nrDynamics;
                            app.TimeDynViewField.Value = datestr(seconds(app.acqDur/app.DynamicsEditField.Value),'HH:MM:SS.FFF');
                        else
                            app.retroKspacePars = app.retroKspacePars.fillKspace2D(app.retroDataPars, app);
                        end

                    case '3D'
                        app.retroKspacePars = app.retroKspacePars.fillKspace3D(app.retroDataPars, app);

                    case '3Dp2roud'
                        app.retroKspacePars = app.retroKspacePars.fillKspace3D_P2ROUD(app.retroDataPars, app);

                    case '2Dradial'
                        app.retroKspacePars = app.retroKspacePars.fillKspace2D_RADIAL(app.retroNavPars, app.retroDataPars, app);
                      
                end

                % Reshape to either cardiac or respiratory CINE
                app.retroKspacePars = app.retroKspacePars.reshapeKspace;
              
                % Calculate effective number of averages
                app.AveragesViewField.Value = mean2(app.retroKspacePars.kSpaceAvg);
                app.retroDataPars.NO_AVERAGES = round(app.AveragesViewField.Value);

                % Let the app know the data is sorted
                app.validSortFlag = true;
       
                % K-space statistics
                [app.retroKspacePars, app] = app.retroKspacePars.kSpaceStats(app);

                % Reset buttons
                app.SetLightsFcn(7);
                app.BusyImage3.Visible = 'off';
                app.SetDashboardButtonsFcn(143);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtons(false);
                app.SetStatus(0);

            catch ME

                % Let the app know the data is not sorted
                app.validSortFlag = false;

                % Reset buttons
                app.SetLightsFcn(3);
                app.BusyImage3.Visible = 'off';
                app.SetDashboardButtonsFcn(135);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(false);
                app.MovieButtons(false);
                app.SetStatus(2);

                % Error message
                app.TextMessage('ERROR: Something went wrong during sorting of the data into k-space ...')
                app.TextMessage(ME.message);
            
            end

        end

        % Value changed function: TVcineEditField, TVxyzEditField, 
        % ...and 1 other component
        function RecoParEditFieldValueChanged(app, event)

            % Stop movie & reset progress bar
            app.stopMovieFlag = true;
            app.ProgressGauge.Value = 0;

            % Reset buttons
            app.SetLightsFcn(7);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(143);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtons(false);
         
        end

        % Button pushed function: LcurveStartButton
        function LcurveStartButtonPushed(app, event)

            % Indicate busy
            app.TextMessage('L-curve calculation in progress ...');
            app.SetStatus(3);
            app.stopMovieFlag = true;
            app.stopLcurveFlag = false;
            app.SwitchOffAllButtonsFcn;
            app.LcurveStartButton.Enable = 'off';
            app.LcurveStopButton.Enable = 'on';
          
            if app.bartDetectedFlag && ~app.retroRecoPars.multiDynamicFlag && (strcmp(app.retroDataPars.dataType,'2D')==true || strcmp(app.retroDataPars.dataType,'2Dms')==true)

                % Range of lamda's
                lambda = logspace(log10(app.Lambda1EditField.Value),log10(app.Lambda2EditField.Value),app.LambdaStepsEditField.Value);
                lambda = round(lambda,3);

                % Determine which parameters will be optimized
                par = [0 0 0 0 0];
                if app.LcurveWVxyzCheckBox.Value  == 1  par(1) = 1; end %#ok<*SEPEX> 
                if app.LcurveTVxyzCheckBox.Value  == 1  par(2) = 1; end
                if app.LcurveLRxyzCheckBox.Value  == 1  par(3) = 1; end
                if app.LcurveTVcineCheckBox.Value == 1  par(4) = 1; end
                if app.LcurveTVdynCheckBox.Value  == 1  par(5) = 1; end

                % Intialize progress gauge
                totalNumberOfSteps = app.LambdaIterationsEditField.Value * sum(par) * length(lambda);
                app.ProgressGauge.Value = 0;
                cnt = 0;

                % Iterations
                iter = 0;
                while (iter<app.LambdaIterationsEditField.Value) && (app.stopLcurveFlag==false)
                    iter = iter + 1;

                    % Wavelet xyz
                    if par(1)==1

                        % Reset graph
                        cla(app.LcurveFig);
                        cla(app.LcurveModelErrorFig);
                        app.LcurveFig.YLabel.String = "|| WVxyz x_{\lambda} ||_1";
                        drawnow;

                        x = [];
                        y = [];

                        i = 0;
                        while (i<length(lambda)) && (app.stopLcurveFlag==false)
                            i = i + 1;

                            % Wavelet
                            app.WVxyzEditField.Value = lambda(i);
                            app.TextMessage(strcat('L-curve, WVxyz, iteration =',num2str(iter),', lambda =',num2str(lambda(i))));

                            % CS reco
                            app.retroRecoPars.sos = 0;
                            app.retroRecoPars = app.retroRecoPars.reco2D(app.retroDataPars, app.retroKspacePars, app);
                            Lmovie = app.retroRecoPars.movieExp;
                          
                            % L1 norm wavelet
                            WVxyz = bart(app,'cdf97 6',Lmovie);
                            y(i) = norm(WVxyz(:),1);

                            % L2 norm
                            kSpaceIm = bart(app,'fft -u -i 6',Lmovie);
                            kSpaceIm = bart(app,['resize -c 1 ',num2str(size(app.retroKspacePars.kSpace{1},2)),' 2 ',num2str(size(app.retroKspacePars.kSpace{1},3))],kSpaceIm);
                            x(i) = 0;
                            for j = 1:app.retroDataPars.nr_coils
                                mask = app.retroKspacePars.kSpace{j} ~= 0;
                                kSpaceDiff = abs(kSpaceIm(:,:,:,:,:,j).*mask) - abs(app.retroKspacePars.kSpace{j});
                                x(i) = x(i) + norm(kSpaceDiff(:),2);
                            end

                            % Plot the data
                            app.PlotLcurveFig(lambda,x,y);

                            % Update counter
                            cnt = cnt + 1;
                            app.ProgressGauge.Value = round(100*cnt/totalNumberOfSteps);

                        end

                        % Analyze the L-curve and determine optimal value
                        if ~app.stopLcurveFlag
                            app.LcurveanalysisFcn(lambda,x,y,1);
                        end

                    end

                    % Total Variation spatial domain
                    if par(2)==1

                        % Reset graph
                        cla(app.LcurveFig);
                        cla(app.LcurveModelErrorFig);
                        app.LcurveFig.YLabel.String = "|| TVxyz x_{\lambda} ||_1";
                        drawnow;

                        x = [];
                        y = [];

                        i = 0;
                        while (i<length(lambda)) && (app.stopLcurveFlag==false)
                            i = i + 1;

                            % Total variation
                            app.TVxyzEditField.Value = lambda(i);
                            app.TextMessage(strcat('L-curve, TVxyz, iteration=',num2str(iter),', lambda=',num2str(lambda(i))));

                            % CS reco
                            app.retroRecoPars.sos = 0;
                            app.retroRecoPars = app.retroRecoPars.reco2D(app.retroDataPars, app.retroKspacePars, app);
                            Lmovie = app.retroRecoPars.movieExp;
                            
                            % L1 norm TV in spatial dimension
                            for dim1=1:size(Lmovie,1)
                                for dim4=1:size(Lmovie,4)
                                    for dim5=1:size(Lmovie,5)
                                        for dim6=1:size(Lmovie,6)
                                            [Gx,Gy] = gradient(squeeze(Lmovie(dim1,:,:,dim4,dim5,dim6)));
                                            TVxyz(dim1,:,:,dim4,dim5,dim6) = sqrt(Gx.^2 + Gy.^2);
                                        end
                                    end
                                end
                            end
                            y(i) = norm(TVxyz(:),1);

                            % L2 norm
                            kSpaceIm = bart(app,'fft -u -i 6',Lmovie);
                            kSpaceIm = bart(app,['resize -c 1 ',num2str(size(app.retroKspacePars.kSpace{1},2)),' 2 ',num2str(size(app.retroKspacePars.kSpace{1},3))],kSpaceIm);
                            x(i) = 0;
                            for j = 1:app.retroDataPars.nr_coils
                                mask = app.retroKspacePars.kSpace{j} ~= 0;
                                kSpaceDiff = abs(kSpaceIm(:,:,:,:,:,j).*mask) - abs(app.retroKspacePars.kSpace{j});
                                x(i) = x(i) + norm(kSpaceDiff(:),2);
                            end

                            % Plot the data
                            app.PlotLcurveFig(lambda,x,y);

                            % Update counter
                            cnt = cnt + 1;
                            app.ProgressGauge.Value = round(100*cnt/totalNumberOfSteps);

                        end

                        % Analyze the L-curve and determine optimal value
                        if ~app.stopLcurveFlag
                            app.LcurveanalysisFcn(lambda,x,y,2);
                        end

                    end

                    % Low-Rank in spatial domain
                    if par(3)==1

                        % Reset graph
                        cla(app.LcurveFig);
                        cla(app.LcurveModelErrorFig);
                        app.LcurveFig.YLabel.String = "|| LRxyz x_{\lambda} ||_1";
                        drawnow;

                        x = [];
                        y = [];

                        i = 0;
                        while (i<length(lambda)) && (app.stopLcurveFlag==false)
                            i = i + 1;

                            % Total variation
                            app.LLRxyzEditField.Value = lambda(i);
                            app.TextMessage(strcat('L-curve, LRxyz, iteration=',num2str(iter),', lambda=',num2str(lambda(i))));

                            % CS reco
                            app.retroRecoPars.sos = 0;
                            app.retroRecoPars = app.retroRecoPars.reco2D(app.retroDataPars, app.retroKspacePars, app);
                            Lmovie = app.retroRecoPars.movieExp;
                            
                            % L1 norm TV in spatial dimension
                            for dim1=1:size(Lmovie,1)
                                for dim4=1:size(Lmovie,4)
                                    for dim5=1:size(Lmovie,5)
                                        for dim6=1:size(Lmovie,6)
                                            [Gx,Gy] = gradient(squeeze(Lmovie(dim1,:,:,dim4,dim5,dim6)));
                                            TVxyz(dim1,:,:,dim4,dim5,dim6) = sqrt(Gx.^2 + Gy.^2);
                                        end
                                    end
                                end
                            end
                            y(i) = norm(TVxyz(:),1);

                            % L2 norm
                            kSpaceIm = bart(app,'fft -u -i 6',Lmovie);
                            kSpaceIm = bart(app,['resize -c 1 ',num2str(size(app.retroKspacePars.kSpace{1},2)),' 2 ',num2str(size(app.retroKspacePars.kSpace{1},3))],kSpaceIm);
                            x(i) = 0;
                            for j = 1:app.retroDataPars.nr_coils
                                mask = app.retroKspacePars.kSpace{j} ~= 0;
                                kSpaceDiff = abs(kSpaceIm(:,:,:,:,:,j).*mask) - abs(app.retroKspacePars.kSpace{j});
                                x(i) = x(i) + norm(kSpaceDiff(:),2);
                            end

                            % Plot the data
                            app.PlotLcurveFig(lambda,x,y);

                            % Update counter
                            cnt = cnt + 1;
                            app.ProgressGauge.Value = round(100*cnt/totalNumberOfSteps);

                        end

                        % Analyze the L-curve and determine optimal value
                        if ~app.stopLcurveFlag
                            app.LcurveanalysisFcn(lambda,x,y,3);
                        end

                    end

                    % Total Variation CINE
                    if par(4)==1

                        % Reset graph
                        cla(app.LcurveFig);
                        cla(app.LcurveModelErrorFig);
                        app.LcurveFig.YLabel.String = "|| TVcine x_{\lambda} ||_1";
                        drawnow;

                        x = [];
                        y = [];

                        i = 0;
                        while (i<length(lambda)) && (app.stopLcurveFlag==false)
                            i = i + 1;

                            % Total variation
                            app.TVcineEditField.Value = lambda(i);
                            app.TextMessage(strcat('L-curve, TVcine, iteration=',num2str(iter),', lambda=',num2str(lambda(i))));

                            % CS reco
                            app.retroRecoPars.sos = 0;
                            app.retroRecoPars = app.retroRecoPars.reco2D(app.retroDataPars, app.retroKspacePars, app);
                            Lmovie = app.retroRecoPars.movieExp;
                            
                            % L1 norm TV
                            TVcine = circshift(Lmovie,1) - Lmovie;
                            y(i) = norm(TVcine(:),1);

                            % L2 norm
                            kSpaceIm = bart(app,'fft -u -i 6',Lmovie);
                            kSpaceIm = bart(app,['resize -c 1 ',num2str(size(app.retroKspacePars.kSpace{1},2)),' 2 ',num2str(size(app.retroKspacePars.kSpace{1},3))],kSpaceIm);
                            x(i) = 0;
                            for j = 1:app.retroDataPars.nr_coils
                                mask = app.retroKspacePars.kSpace{j} ~= 0;
                                kSpaceDiff = abs(kSpaceIm(:,:,:,:,:,j).*mask) - abs(app.retroKspacePars.kSpace{j});
                                x(i) = x(i) + norm(kSpaceDiff(:),2);
                            end

                            % Plot the data
                            app.PlotLcurveFig(lambda,x,y);

                            % Update counter
                            cnt = cnt + 1;
                            app.ProgressGauge.Value = round(100*cnt/totalNumberOfSteps);

                        end

                        % Analyze the L-curve and determine optimal value
                        if ~app.stopLcurveFlag
                            app.LcurveanalysisFcn(lambda,x,y,4);
                        end

                    end

                    % Total Variation dynamic dimension
                    if par(5)==1

                        % Reset graph
                        cla(app.LcurveFig);
                        cla(app.LcurveModelErrorFig);
                        app.LcurveFig.YLabel.String = "|| TVdyn x_{\lambda} ||_1";
                        drawnow;

                        x = [];
                        y = [];

                        i = 0;
                        while (i<length(lambda)) && (app.stopLcurveFlag==false)
                            i = i + 1;

                            % Total variation
                            app.TVdynEditField.Value = lambda(i);
                            app.TextMessage(strcat('L-curve, TVdyn, iteration=',num2str(iter),', lambda=',num2str(lambda(i))));

                            % CS reco
                            app.retroRecoPars.sos = 0;
                            app.retroRecoPars = app.retroRecoPars.reco2D(app.retroDataPars, app.retroKspacePars, app);
                            Lmovie = app.retroRecoPars.movieExp;

                            % L1 norm TV in dynamic dimension
                            TVtime = circshift(Lmovie,5) - Lmovie;
                            y(i) = norm(TVtime(:),1);

                            % L2 norm
                            kSpaceIm = bart(app,'fft -u -i 6',Lmovie);
                            kSpaceIm = bart(app,['resize -c 1 ',num2str(size(app.retroKspacePars.kSpace{1},2)),' 2 ',num2str(size(app.retroKspacePars.kSpace{1},3))],kSpaceIm);
                            x(i) = 0; %#ok<*AGROW> 
                            for j = 1:app.retroDataPars.nr_coils
                                mask = app.retroKspacePars.kSpace{j} ~= 0;
                                kSpaceDiff = abs(kSpaceIm(:,:,:,:,:,j).*mask) - abs(app.retroKspacePars.kSpace{j});
                                x(i) = x(i) + norm(kSpaceDiff(:),2);
                            end

                            % Plot the data
                            app.PlotLcurveFig(lambda,x,y);

                            % Update counter
                            cnt = cnt + 1;
                            app.ProgressGauge.Value = round(100*cnt/totalNumberOfSteps);

                        end

                        % Analyze the L-curve and determine optimal value
                        if ~app.stopLcurveFlag
                            app.LcurveanalysisFcn(lambda,x,y,5);
                        end

                    end

                end

            else

                app.TextMessage('ERROR: L-curve calculations require Bart toolbox and single-dynamic Cartesian 2D data...');
                app.SetStatus(2);

            end

            % Stopped before completion
            if app.stopLcurveFlag
                app.TextMessage('L-curve calculation stopped by user ...');
            end

            % Let the app know there is no valid reco
            app.validRecoFlag = false;

            % Reset buttons
            app.SetLightsFcn(7);
            app.BusyImage3.Visible = 'off';
            app.SetDashboardButtonsFcn(143);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtons(false);
            app.SetStatus(0);

        end

        % Button pushed function: LcurveStopButton
        function LcurveStopButtonPushed(app, event)

            % Stop the L-curve calculations
            app.stopLcurveFlag = true;
            drawnow;

        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)

            % Indicate busy
            app.SetLightsFcn(7);
            app.Lamp4.Color = app.yellow;
            app.BusyImage4.Visible = 'on';
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;
            app.TabGroup4.SelectedTab = app.MovieTab;

            % Reset some parameters
            app.stopMovieFlag = true;
            app.stopLcurveFlag = true;
            app.stopNavMovieFlag = true;
            app.ProgressGauge.Value = 0;
            app.retroRecoPars.sos = 1;
                        
            app.validRecoFlag = false;
            
            app.retroRecoPars.multiSliceFlag = false;
            app.retroRecoPars.multiDynamicFlag = false;
            app.validSenseMapFlag = false;

            app.sliceNr = 1;
            app.dynamicNr = 1;
            app.MovieSliceEditField.Value = 1;
            app.MovieFrameEditField.Value = 1;
            app.MovieDynEditField.Value = 1;
            app.MovieCheckBox1.Value = 1;
            app.MovieCheckBox2.Value = 0;
            app.MovieCheckBox3.Value = 0;

            % For testing purposes
            % app.bartDetectedFlag = false;
            

            % Reconstruction
            try

                switch app.retroDataPars.dataType

                    case {'2D','2Dms'}

                        app.retroRecoPars.sos = 1;
                        app.retroRecoPars = app.retroRecoPars.reco2D(app.retroDataPars, app.retroKspacePars, app);
                        app.validRecoFlag = true;
                        app.validSenseMapFlag = true;

                    case {'3D','3Dp2roud'}

                        app.sliceNr = round(app.retroDataPars.dimz/2);
                        app.MovieSliceEditField.Value = app.sliceNr;
                        app.retroRecoPars = app.retroRecoPars.reco3D(app.retroDataPars, app.retroKspacePars, app);
                        app.validRecoFlag = true;
                        app.validSenseMapFlag = true;

                    case '2Dradial'

                        app.retroRecoPars = app.retroRecoPars.recoRadial(app.retroDataPars, app.retroKspacePars, app);
                        app.validRecoFlag = true;
                        app.validSenseMapFlag = true;

                end

            catch ME

                app.TextMessage(ME.message);
                app.validRecoFlag = false;
                app.validSenseMapFlag = false;

            end

            % Successful reco or not
            if app.validRecoFlag

                % Normalize image intensity somewhat
                app.retroRecoPars = app.retroRecoPars.normImages(app.retroDataPars);
                
                % Set multislice or multidynamic flags
                app.retroRecoPars = app.retroRecoPars.determineMultiDimensions;

                % Number of slices and starting movie slice
                app.nrSlices = size(app.retroRecoPars.movieApp,4);
                app.MovieSliceEditField.Value = round(app.nrSlices/2);

                % Info on phase orientation 
                if app.retroDataPars.PHASE_ORIENTATION
                    app.TextMessage('INFO: phase orientation = 1 / horizontal ... ')
                else
                    app.TextMessage('INFO: phase orientation = 0 / vertical ... ')
                end

                % Reset buttons
                app.SetLightsFcn(15);
                app.BusyImage4.Visible = 'off';
                app.SetDashboardButtonsFcn(255);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtons(true);
                app.SetStatus(0);

                % Start the movie
                app.MovieButton.Value = 1;
                app.ButtonGroupSelectionChanged;
                if ~app.AutoRecoCheckBox.Value
                    if app.nrFrames > 1
                        app.StartButtonPushed;
                    end
                else
                    app.PlotCurrentMovieFrameFcn;
                end

            else

                % Inform the app that the reco was not successful
                app.validRecoFlag = false;
                app.validSenseMapFlag = false;

                % Reset buttons
                app.SetLightsFcn(7);
                app.BusyImage4.Visible = 'off';
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(135);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtons(false);
            
                % Error message
                app.TextMessage('ERROR: There was a problem with reconstruction ...');
                app.SetStatus(2);
          
            end

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            % Movie is running
            app.stopMovieFlag = false;
            app.TextMessage('Movie playing ...');

            % Buttons
            app.MovieButtons(true);
            app.ExitButton.Enable = 'off';

            app.MovieFig.Position = [154,15,280,280];

            counter1 = app.MovieFrameEditField.Value;
            counter2 = app.sliceNr;
            counter3 = app.dynamicNr;

            % Show the movie frame
            app.ButtonGroupSelectionChanged;

            % Play the movie continuously until app.stopMovieFlag==true
            while app.stopMovieFlag == false

                % Time
                tic

                % Calculate movie speed
                if strcmp(app.RecoTypeDropDown.Value,'realtime')
                    minTimePerFrame = 1/(app.MovieCheckBox1.Value * app.nrFrames + app.MovieCheckBox2.Value*app.nrSlices + app.MovieCheckBox3.Value * app.nrDynamics);
                else
                    minTimePerFrame = 1/(app.MovieCheckBox1.Value * app.nrFrames);
                    if app.MovieCheckBox1.Value == 0
                        minTimePerFrame = 1/(app.MovieCheckBox2.Value * app.nrSlices + app.MovieCheckBox3.Value * app.nrDynamics);
                    end
                end

                % Show the movie frame
                app.UpdateMovieFrameFcn;

                chk = false;

                if app.MovieCheckBox1.Value == 1
                    counter1 = counter1 + 1;
                    chk = true;
                    if counter1 > app.nrFrames
                        counter1 = 1;
                        chk = false;
                    end
                    app.MovieFrameEditField.Value = counter1;
                end

                if (app.MovieCheckBox2.Value == 1) && (chk == false)
                    counter2 = counter2 + 1;
                    chk = true;
                    if counter2 > app.retroDataPars.dimz
                        counter2 = 1;
                        chk = false;
                    end
                    app.MovieSliceEditField.Value = counter2;
                    app.sliceNr = counter2;
                end

                if (app.MovieCheckBox3.Value == 1) && (chk == false)
                    counter3 = counter3 + 1;
                    chk = true; %#ok<*NASGU> 
                    if counter3 > app.nrDynamics
                        counter3 = 1;
                        chk = false;
                    end
                    app.MovieDynEditField.Value = counter3;
                    app.dynamicNr = counter3;
                end

                % Slow movie a bit down with small number of frames
                mvTime = toc;

                if mvTime < minTimePerFrame
                    pause(minTimePerFrame-mvTime);
                end

            end

            app.PlotCurrentMovieFrameFcn;
         
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            % Stop the movie
            app.stopMovieFlag = true;
            app.TextMessage('Stopping the movie ...');

            % Reset buttons
            app.MovieButtons(true);
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Value changed function: MovieFrameEditField
        function MovieFrameEditFieldValueChanged(app, event)

            value = app.MovieFrameEditField.Value;

            % If running, stop the movie
            app.StopButtonPushed;

            % Check if input value <= number of cardiac frames
            if value > app.nrFrames
                app.MovieFrameEditField.Value = app.nrFrames;
            end

            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;

        end

        % Value changed function: MovieSliceEditField
        function MovieSliceEditFieldValueChanged(app, event)

            value = app.MovieSliceEditField.Value;

            if value > app.nrSlices
                app.MovieSliceEditField.Value = app.nrSlices;
            end

            app.sliceNr = app.MovieSliceEditField.Value;

            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;

        end

        % Value changed function: MovieDynEditField
        function MovieDynEditFieldValueChanged(app, event)

            value = app.MovieDynEditField.Value;

            if value > app.nrDynamics
                app.MovieDynEditField.Value = app.nrDynamics;
            end

            app.dynamicNr = app.MovieDynEditField.Value;

            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;

        end

        % Value changed function: MovieCheckBox1, MovieCheckBox2, 
        % ...and 1 other component
        function MovieCheckBoxValueChanged(app, event)

            if (app.MovieCheckBox1.Value == 0) && (app.MovieCheckBox2.Value == 0) && (app.MovieCheckBox3.Value == 0)
                app.MovieCheckBox1.Value = 1;
            end

        end

        % Value changed function: WindowScaleSlider
        function WindowScaleSliderValueChanged(app, event)

            value = app.WindowScaleSlider.Value;
            app.window = value;
            app.PlotCurrentMovieFrameFcn;

        end

        % Value changed function: LevelScaleSlider
        function LevelScaleSliderValueChanged(app, event)

            value = app.LevelScaleSlider.Value;
            app.level = value;
            app.PlotCurrentMovieFrameFcn;

        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)

            % MRI movie
            if app.MovieButton.Value == 1
                app.AutoIntensityFcn(app.retroRecoPars.movieApp);
            end

            % Averages
            if app.AveragesButton.Value == 1
                app.AutoIntensityFcn(app.retroKspacePars.kSpaceAvg);
            end

            % Raw k-space
            if app.kSpaceButton.Value == 1
                app.AutoIntensityFcn(app.retroKspacePars.kSpace{app.MovieCoilSpinner.Value});
            end

            % Sense map
            if (app.SenseMapButton.Value == 1) && (app.validSenseMapFlag)
                app.AutoIntensityFcn(app.retroRecoPars.senseMap);
            end

            app.PlotCurrentMovieFrameFcn;
       
        end

        % Value changed function: MovieCoilSpinner, SenseMapSpinner1, 
        % ...and 1 other component
        function MovieCoilSpinnerValueChanged(app, event)

            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;

        end

        % Button pushed function: cwButton
        function cwButtonPushed(app, event)

            % Rotate movie clock-wise
            app.retroRecoPars.movieApp = permute(rot90(permute(app.retroRecoPars.movieApp,[2,3,1,4,5]),-1),[3,1,2,4,5]);
            app.retroRecoPars.senseMap = rot90(app.retroRecoPars.senseMap,-1);
            app.retroDataPars.aspectratio = 1/app.retroDataPars.aspectratio;

            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: ccwButton
        function ccwButtonPushed(app, event)

            % Rotate movie counter-clock-wise
            app.retroRecoPars.movieApp = permute(rot90(permute(app.retroRecoPars.movieApp,[2,3,1,4,5])),[3,1,2,4,5]);
            app.retroRecoPars.senseMap = rot90(app.retroRecoPars.senseMap,1);
            app.retroDataPars.aspectratio = 1/app.retroDataPars.aspectratio;

            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieFrameIncrButton
        function MovieFrameIncrButtonPushed(app, event)

            % Shift movie +1 frame along the time/frame axis

            switch app.retroDataPars.dataType

                case {'2D','3D','2Dradial','3Dp2roud'}

                    app.retroRecoPars.movieExp = circshift(app.retroRecoPars.movieExp,-1,1);
                    app.retroRecoPars.movieApp = circshift(app.retroRecoPars.movieApp,-1,1);
                    for i = 1:app.retroDataPars.nr_coils
                        app.retroKspacePars.kSpace{i} = circshift(app.retroKspacePars.kSpace{i},-1,1);
                    end
                    app.retroKspacePars.kSpaceAvg = circshift(app.retroKspacePars.kSpaceAvg,-1,1);

                case '2Dms'

                    % Shift for the CS movie slice
                    shiftedMovieSlice = circshift(app.retroRecoPars.movieExp(:,:,:,app.sliceNr),-1,1);
                    app.retroRecoPars.movieExp(:,:,:,app.sliceNr) = shiftedMovieSlice;

                    % Shift for the movie slice
                    shiftedMovieSlice = circshift(app.retroRecoPars.movieApp(:,:,:,app.sliceNr),-1,1);
                    app.retroRecoPars.movieApp(:,:,:,app.sliceNr) = shiftedMovieSlice;

                    % Shift k-space movie for all coils
                    for i = 1:app.retroDataPars.nr_coils
                        shiftedMovieSlice = circshift(app.retroKspacePars.kSpace{i}(:,:,:,app.sliceNr),-1,1);
                        app.retroKspacePars.kSpace{i}(:,:,:,app.sliceNr) = shiftedMovieSlice;
                    end

                    % Shift the averages movie
                    shiftedMovieSlice = circshift(app.retroKspacePars.kSpaceAvg(:,:,:,app.sliceNr),-1,1);
                    app.retroKspacePars.kSpaceAvg(:,:,:,app.sliceNr) = shiftedMovieSlice;

            end

            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieFrameDecrButton
        function MovieFrameDecrButtonPushed(app, event)

            % Shift movie -1 frame along the time/frame axis

            switch app.retroDataPars.dataType

                case {'2D','3D','2Dradial','3Dp2roud'}

                    app.retroRecoPars.movieExp = circshift(app.retroRecoPars.movieExp,1,1);
                    app.retroRecoPars.movieApp = circshift(app.retroRecoPars.movieApp,1,1);
                    for i = 1:app.retroDataPars.nr_coils
                        app.retroKspacePars.kSpace{i} = circshift(app.retroKspacePars.kSpace{i},1,1);
                    end
                    app.retroKspacePars.kSpaceAvg = circshift(app.retroKspacePars.kSpaceAvg,1,1);

                case '2Dms'

                    % Shift for the CS movie slice
                    shiftedMovieSlice = circshift(app.retroRecoPars.movieExp(:,:,:,app.sliceNr),1,1);
                    app.retroRecoPars.movieExp(:,:,:,app.sliceNr) = shiftedMovieSlice;

                    % Shift for the movie slice
                    shiftedMovieSlice = circshift(app.retroRecoPars.movieApp(:,:,:,app.sliceNr),1,1);
                    app.retroRecoPars.movieApp(:,:,:,app.sliceNr) = shiftedMovieSlice;

                    % Shift k-space movie for all coils
                    for i = 1:app.retroDataPars.nr_coils
                        shiftedMovieSlice = circshift(app.retroKspacePars.kSpace{i}(:,:,:,app.sliceNr),1,1);
                        app.retroKspacePars.kSpace{i}(:,:,:,app.sliceNr) = shiftedMovieSlice;
                    end

                    % Shift the averages movie
                    shiftedMovieSlice = circshift(app.retroKspacePars.kSpaceAvg(:,:,:,app.sliceNr),1,1);
                    app.retroKspacePars.kSpaceAvg(:,:,:,app.sliceNr) = shiftedMovieSlice;

            end

            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieSliceIncrButton
        function MovieSliceIncrButtonPushed(app, event)

            % Increment the slice number by 1
            app.sliceNr = app.sliceNr + 1;

            if app.kSpaceButton.Value == 1 || app.AveragesButton.Value == 1
                if app.sliceNr > size(app.retroDataPars.data{1},2)
                    app.sliceNr = 1;
                end
            else
                if app.sliceNr > app.nrSlices
                    app.sliceNr = 1;
                end
            end

            app.MovieSliceEditField.Value = app.sliceNr;

            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;

        end

        % Button pushed function: MovieSliceDecrButton
        function MovieSliceDecrButtonPushed(app, event)

            % Decrease the slice number by 1
            app.sliceNr = app.sliceNr - 1;

            if app.kSpaceButton.Value == 1 || app.AveragesButton.Value == 1
                if app.sliceNr < 1
                    app.sliceNr = size(app.data{1},2);
                end
            else
                if app.sliceNr < 1
                    app.sliceNr = app.nrSlices;
                end
            end

            app.MovieSliceEditField.Value = app.sliceNr;

            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;

        end

        % Button pushed function: MovieDynIncrButton
        function MovieDynIncrButtonPushed(app, event)
   
            % Increment the dynamic number by 1
            app.dynamicNr = app.dynamicNr + 1;
            if app.dynamicNr > app.nrDynamics
                app.dynamicNr = 1;
            end
            app.MovieDynEditField.Value = app.dynamicNr;

            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;

        end

        % Button pushed function: MovieDynDecrButton
        function MovieDynDecrButtonPushed(app, event)

            % Decrease the dynamic number by 1
            app.dynamicNr = app.dynamicNr - 1;
            if app.dynamicNr < 1
                app.dynamicNr = app.nrDynamics;
            end
            app.MovieDynEditField.Value = app.dynamicNr;

            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            % Show the movie frame

            if app.MovieButton.Value == 1
                app.AutoIntensityFcn(app.retroRecoPars.movieExp);
            end

            if app.kSpaceButton.Value == 1
                app.sliceNr = 1;
                app.MovieSliceEditField.Value = app.sliceNr;
                app.AutoIntensityFcn(app.retroKspacePars.kSpace{app.MovieCoilSpinner.Value});
            end

            if app.AveragesButton.Value == 1
                app.sliceNr = 1;
                app.MovieSliceEditField.Value = app.sliceNr;
            end

            if (app.SenseMapButton.Value == 1) && (app.validSenseMapFlag)
                app.AutoIntensityFcn(app.retroRecoPars.senseMap(:,:,app.MovieSliceEditField.Value,app.SenseMapSpinner1.Value,app.SenseMapSpinner2.Value));
            end

            app.PlotCurrentMovieFrameFcn;

        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)

            % If running, stop the movie
            app.StopButtonPushed;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.Lamp5.Color = app.yellow;
            app.BusyImage5.Visible = 'on';
            app.SetStatus(3);

            % Search for dicom file
            app.TextMessage('Searching for DICOM information ...');

            % Assuming that MRD and Data directory share the same base-directory
            % Example: base / MRD / 343 / 8455 /

            % Base / data / 343 / DICOM / 8455 /
            dcmSpath{1} = replace(app.mrdImportPath,'MRD','Data');
            dcmPos = strfind(dcmSpath{1},filesep);
            dcmPos = dcmPos(end-1);
            dcmSpath{1} = insertBefore(dcmSpath{1},dcmPos,[filesep,'DICOM']);

            % Base / data / 343 / DICOM / 8455 / 1 /
            dcmSpath{2} = [dcmSpath{1},'1',filesep];

            % Base / data / 343 / DICOM / 8455 / 2 /
            dcmSpath{3} = [dcmSpath{1},'2',filesep];

            % Don't mention the B word
            dcmSpath{4} = strcat(app.mrdImportPath,filesep,'pdata',filesep,'1',filesep,'dicom');
            dcmSpath{5} = strcat(app.mrdImportPath,filesep,'pdata',filesep,'2',filesep,'dicom');

            % Check if one of the directories contains dicom files
            dcmExist = false;
            app.TextMessage('Searching for DICOM info in the following locations ...');
            for i=1:length(dcmSpath)
                app.TextMessage(dcmSpath{i});
                if ~isempty(dir([dcmSpath{i},filesep,'*.dcm']))
                    dcmPath = dcmSpath{i};
                    dcmExist = true;
                    app.TextMessage('Found DICOM info ...');
                    filesepIdx = strfind(dcmPath, filesep);
                    app.dicomExportPath = dcmPath(filesepIdx(1):filesepIdx(end-4));
                    app.SetStatus(0);
                    break;
                end
            end

            % Ask for dicom export directory
            try
                app.RetrospectiveUIFigure.Visible = 'off';
                app.dicomExportPath = uigetdir(app.dicomExportPath,'Dicom export path');
                app.RetrospectiveUIFigure.Visible = 'on';
                drawnow;
            catch ME
                app.TextMessage(ME.message);
            end

            % Check if directory exists and is writeable - does not always guarantee that the directory can be written
            ready = true;
            if ~ischar(app.dicomExportPath)         % user cancel
                ready = false;
                app.dicomExportPath = '';
            else
                [~, Attrib] = fileattrib(app.dicomExportPath);
                if ~any(Attrib.UserWrite)           % check if directory is writable
                    ready = false;
                end
            end

            % Export to dicom
            if ready

                app.TextMessage('DICOM export ...');

                if dcmExist

                    % Export, reading dicom information from dcm_path
                    try
                        exportPath = retroExportDicomDcm(app,dcmPath,app.dicomExportPath,app.acqDur,app.RecoTypeDropDown.Value);
                        app.ExportRecoParametersFcn(exportPath);
                        success = true;
                    catch ME
                        app.TextMessage('ERROR: problem writing Dicom files ...');
                        app.TextMessage(ME.message);
                        success = false;
                        app.Lamp5.Color = app.brightred;
                        app.SetStatus(2);
                    end

                else

                    % Export without image orientation information
                    try
                        app.TextMessage('WARNING: DICOM image orientation information not available ...');
                        app.SetStatus(1);
                        exportPath = retroExportDicomMat(app,app.dicomExportPath,app.acqDur,app.tag,app.RecoTypeDropDown.Value);
                        app.ExportRecoParametersFcn(exportPath);
                        success = true;
                    catch ME
                        app.TextMessage('ERROR: problem writing Dicom files ...');
                        app.TextMessage(ME.message);
                        success = false;
                        app.Lamp5.Color = app.brightred;
                        app.SetStatus(2);
                    end

                end

                clearvars dcm_movie;

            else

                app.TextMessage('ERROR: not a valid export directory');
                success = false;

            end

            if success
                app.Lamp5.Color = app.brightgreen;
                app.SetStatus(0);
            else
                app.Lamp5.Color = app.brightred;
                app.SetStatus(2);
            end

            % Reset buttons
            app.BusyImage5.Visible = 'off';
            app.SetDashboardButtonsFcn(255);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtons(true);
         
        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)

            % If running, stop the movie
            app.StopButtonPushed;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.Lamp6.Color = app.yellow;
            app.BusyImage6.Visible = 'on';
            app.SetStatus(3);

            app.TextMessage('Animated GIF export ...');
            
            % Ask for directory
            app.RetrospectiveUIFigure.Visible = 'off';
            app.gifExportPath = uigetdir(app.gifExportPath,'GIF export path');
            app.RetrospectiveUIFigure.Visible = 'on';
            drawnow;

            % Check if directory exists and is writeable
            ready = true;
            if ~ischar(app.gifExportPath)   % user cancel
                ready = false;
                app.gifExportPath = '';
            else
                [~, Attrib] = fileattrib(app.gifExportPath);
                if ~any(Attrib.UserWrite)
                    ready = false;
                end
            end

            % Export to animated gif
            if ready

                try
                    gifExportFolder = retroExportGif(app.retroDataPars,app.gifExportPath,app.retroRecoPars.movieApp,app.tag,app.window,app.level,app.RecoTypeDropDown.Value,app.acqDur);
                    app.ExportRecoParametersFcn(gifExportFolder);
                    app.Lamp6.Color = app.brightgreen;
                    app.SetStatus(0);
                catch ME
                    app.TextMessage('ERROR: problem writing files ...');
                    app.TextMessage(ME.message);
                    app.Lamp6.Color = app.brightred;
                    app.SetStatus(2);
                end

            else

                app.TextMessage('ERROR: not a valid export directory');
                app.Lamp6.Color = app.brightred;
                app.SetStatus(2);

            end

            % Reset buttons
            app.SetDashboardButtonsFcn(255);
            app.BusyImage6.Visible = 'off';
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtons(true);
        
        end

        % Button pushed function: ExportMRDButton
        function ExportMRDButtonPushed(app, event)

            % If running, stop the movie
            app.StopButtonPushed;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.Lamp7.Color = app.yellow;
            app.BusyImage7.Visible = 'on';
            app.SetStatus(3);
            
            app.TextMessage('MRD and SUR file export ...');

            % Make new k-space from the movie
            try
                app.retroKspacePars = app.retroKspacePars.backToKspace(app.retroDataPars, app.retroRecoPars);
            catch ME
                app.TextMessage(ME.message);
                app.TextMessage('Error in app.retroKspacePars.backToKspace ...');
            end

            try
                % New parameters for mrd footer
                [par.NoSamples,par.NoViews,par.NoViews2,par.NoSlices,par.NoEchoes,par.NoExperiments] = size(app.retroKspacePars.kSpaceMrd);
                par.NoAverages = round(app.AveragesViewField.Value);
                par.peorder = 0;
                par.slicenav = 0;
                par.radialon = 0;
                par.frameloopon = 1;
                par.pe2_centric_on = 0;
                if app.FAViewField.Value > 1
                    par.tr = floor(app.retroDataPars.TR);                                       % Data for T1 mapping needs original TR
                    par.tr_extra_us = (app.retroDataPars.TR - par.tr)*1000;
                    par.ti = round(1000*(60/app.retroNavPars.meanHeartRate)/app.nrFrames,2);    % Use ti parameter for time between frames (will be recognized by T1 mapping app)
                else
                    tr_temp = 1000*(60/app.retroNavPars.meanHeartRate)/app.nrFrames;
                    par.tr = floor(tr_temp);                                                    % CINE data needs TR adjusted to new frame rate
                    par.tr_extra_us = (tr_temp - par.tr)*1000;
                    par.ti = app.retroDataPars.TR;
                end
                par.te = 0;                                                                     % Set to zero, because no_echoes dimension is used for frames
                par.batchslices = 0;
                par.View1order = "SEQUENTIAL";
                par.View2order = "SEQUENTIAL";
                par.vfasize = app.retroDataPars.VFA_size;
                par.vfaangles = [app.retroDataPars.VFA_size, app.retroDataPars.VFA_angles];
            catch ME
                app.TextMessage(ME.message);
                app.TextMessage('Error in MRD parameter assignments ...');
            end

            % Create new MRD footer and rpr content
            try
                app.retroDataPars = app.retroDataPars.makeMrdFooter(par);
            catch ME
                app.TextMessage(ME.message);
                app.TextMessage('Error in app.retroDataPars.makeMrdFooter ...');
            end

            try
                if app.retroDataPars.rprFlag
                    app.retroDataPars = app.retroDataPars.makeRprFile(par);
                end
            catch ME
                app.TextMessage(ME.message);
                app.TextMessage('Error in app.retroDataPars.makeRprFile ...');
            end

            % First try auto export the new MRD and rpr files
            try
                app.mrdExportPath = app.mrdImportPath;
                mrdFilename = ['retro_',num2str(par.NoExperiments),'_',num2str(par.NoEchoes),'_',app.tag,'.MRD'];
                rprFilename = ['retro_',num2str(par.NoExperiments),'_',num2str(par.NoEchoes),'_',app.tag,'.rpr'];
                mrdExportFilename = [app.mrdExportPath,filesep,mrdFilename];
                rprExportFilename = [app.mrdExportPath,filesep,rprFilename];
                app.retroDataPars.writeDataToMrd(app.retroKspacePars, mrdExportFilename, par);
                if app.retroDataPars.rprFlag
                    app.retroDataPars.writeToRprFile(rprExportFilename);
                end
                app.retroDataPars.writePhysLog(app.retroNavPars,app.mrdExportPath);
                app.ExportRecoParametersFcn(app.mrdExportPath);
                success = true;
            catch ME
                app.TextMessage(ME.message);
                app.TextMessage('Error in MRD export ...');
                success = false;
            end

            % If unsuccessful, try again using user-input directory
            if ~success

                % Ask for directory
                try
                    app.RetrospectiveUIFigure.Visible = 'off';
                    app.mrdExportPath = uigetdir(app.mrdExportPath,'MRD export path');
                    app.RetrospectiveUIFigure.Visible = 'on';
                    drawnow;
                catch ME
                    app.TextMessage(ME.message);
                end

                % Check if directory exists and is writeable
                try
                    ready = true;
                    if ~ischar(app.mrdExportPath)   % user cancel
                        ready = false;
                        app.mrdExportPath = '';
                    else
                        [~, Attrib] = fileattrib(app.mrdExportPath);
                        if ~any(Attrib.UserWrite)
                            ready = false;
                        end
                    end
                catch ME
                    app.TextMessage(ME.message);
                end

                % Export new MRD and rpr files to user-input directory
                if ready

                    try
                        mrdExportFilename = [app.mrdExportPath,filesep,mrdFilename];
                        rprExportFilename = [app.mrdExportPath,filesep,rprFilename];

                        app.retroDataPars.writeDataToMrd(app.retroKspacePars, mrdExportFilename, par);
                        if app.retroDataPars.rprFlag
                            app.retroDataPars.writeToRprFile(rprExportFilename);
                        end
                        app.retroDataPars.writePhysLog(app.retroNavPars,app.mrdExportPath);
                        app.ExportRecoParametersFcn(app.mrdExportPath);
                        success = true;
                    catch ME
                        app.TextMessage(ME.message);
                        success = false;
                    end

                end

            end

            % Try to autoreconstruct and export of SUR files
            if success && ispc
                try
                    app.mrdImportPath = strcat(app.mrdImportPath,filesep);
                    dblSlash = strcat(filesep,filesep);
                    app.mrdImportPath = strrep(app.mrdImportPath,dblSlash,filesep);
                catch ME
                    app.TextMessage(ME.message);
                end
                
                % Determine SUR directory
                try
                    scanPos = strfind(app.mrdImportPath,filesep);
                    scanTag = strcat(app.mrdImportPath(scanPos(end-2)+1:scanPos(end-1)-1),filesep,'Image',filesep,app.mrdImportPath(scanPos(end-1)+1:scanPos(end)-1));
                    surPath = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','BaseStorageDirectory');
                    surPath1 = [surPath,filesep,scanTag,filesep,'1',filesep];
                    surPath2 = [surPath,filesep,scanTag,filesep];
                    app.TextMessage('Data directory found in Windows registry ...');
                catch ME
                    app.TextMessage(ME.message);
                    surPath = replace(app.mrdImportPath,'MRD','Data');
                    surPos = strfind(surPath,filesep);
                    surPos = surPos(end-1);
                    surPath1 = [insertBefore(surPath,surPos,[filesep,'Image']),'1',filesep];
                    surPath2 = insertBefore(surPath,surPos,[filesep,'Image']);
                end

                try
                    surPath = surPath2;
                    if (exist(surPath1, 'dir'))
                        surPath = surPath1;
                    end
                catch ME
                    app.TextMessage(ME.message);
                end

                try
                    app.TextMessage(strcat('SUR directory =',{' '},surPath));

                    % SUR file names
                    surSuffix = extractBefore(app.tag,'_');

                    % Make and save the new SUR files
                    app.retroRecoPars.recoSurFiles(surPath,surSuffix,mrdExportFilename,rprExportFilename);

                catch ME
                    app.TextMessage('WARNING: Automatic reconstruction of SUR files failed ...');
                    app.TextMessage(ME.message);
                    app.SetStatus(1);
                end

            end

            % Reset buttons
            if success
                app.Lamp7.Color = app.brightgreen;
                app.SetStatus(0);
            else
                app.Lamp7.Color = app.brightred;
                app.TextMessage('ERROR: MRD export failed ...');
                app.SetStatus(2);
            end
            app.SetDashboardButtonsFcn(255);
            app.BusyImage7.Visible = 'off';
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtons(true);
         
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('retrospective.mlapp'));
                app.appDir = info.folder;
                rmpath(genpath(app.appDir));
                cd(app.oldDir);
            end

            % Exit
            app.delete;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RetrospectiveUIFigure = uifigure('Visible', 'off');
            app.RetrospectiveLabel = uilabel(app.RetrospectiveUIFigure);
            app.RetrospectiveLabel.Text = 'Retrospective';
            app.Credits = uilabel(app.RetrospectiveUIFigure);
            app.Credits.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulipImage = uiimage(app.RetrospectiveUIFigure);
            app.MessageBox = uitextarea(app.RetrospectiveUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Value = {'Messages ....'};
            app.MRSImage = uiimage(app.RetrospectiveUIFigure);
            app.BackgroundBox = uitextarea(app.RetrospectiveUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.StatusImage = uiimage(app.RetrospectiveUIFigure);
            app.StatusLabel = uilabel(app.RetrospectiveUIFigure);
            app.StatusLabel.Text = 'status';
            app.TabGroup4 = uitabgroup(app.RetrospectiveUIFigure);
            app.MovieTab = uitab(app.TabGroup4);
            app.MovieTab.Title = 'Movie';
            app.MovieFig = uiaxes(app.MovieTab);
            app.MovieFig.XLim = [0 255];
            app.MovieFig.YLim = [0 255];
            app.MovieFig.XTick = [];
            app.MovieFig.YTick = [];
            app.StartButton = uibutton(app.MovieTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Tooltip = {'Start the movie'};
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.MovieTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Tooltip = {'Stop the movie'};
            app.StopButton.Text = 'Stop';
            app.intensityLabel = uilabel(app.MovieTab);
            app.intensityLabel.Tooltip = {'Level and Window'};
            app.intensityLabel.Text = 'intensity';
            app.cwButton = uibutton(app.MovieTab, 'push');
            app.cwButton.ButtonPushedFcn = createCallbackFcn(app, @cwButtonPushed, true);
            app.cwButton.Enable = 'off';
            app.cwButton.Tooltip = {'Rotate movie cw'};
            app.cwButton.Text = '';
            app.ccwButton = uibutton(app.MovieTab, 'push');
            app.ccwButton.ButtonPushedFcn = createCallbackFcn(app, @ccwButtonPushed, true);
            app.ccwButton.Enable = 'off';
            app.ccwButton.Tooltip = {'Rotate movie cc'};
            app.ccwButton.Text = '';
            app.MovieRotateLabel = uilabel(app.MovieTab);
            app.MovieRotateLabel.Text = 'rotate';
            app.MovieFrameIncrButton = uibutton(app.MovieTab, 'push');
            app.MovieFrameIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieFrameIncrButtonPushed, true);
            app.MovieFrameIncrButton.Enable = 'off';
            app.MovieFrameIncrButton.Text = '+1';
            app.MovieFrameLabel = uilabel(app.MovieTab);
            app.MovieFrameLabel.Text = 'frame';
            app.MovieFrameDecrButton = uibutton(app.MovieTab, 'push');
            app.MovieFrameDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieFrameDecrButtonPushed, true);
            app.MovieFrameDecrButton.Enable = 'off';
            app.MovieFrameDecrButton.Text = '-1';
            app.MovieSliceIncrButton = uibutton(app.MovieTab, 'push');
            app.MovieSliceIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieSliceIncrButtonPushed, true);
            app.MovieSliceIncrButton.Enable = 'off';
            app.MovieSliceIncrButton.Text = '+1';
            app.MovieSliceLabel = uilabel(app.MovieTab);
            app.MovieSliceLabel.Text = 'slice';
            app.MovieSliceDecrButton = uibutton(app.MovieTab, 'push');
            app.MovieSliceDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieSliceDecrButtonPushed, true);
            app.MovieSliceDecrButton.Enable = 'off';
            app.MovieSliceDecrButton.Text = '-1';
            app.ButtonGroup = uibuttongroup(app.MovieTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.MovieButton = uiradiobutton(app.ButtonGroup);
            app.MovieButton.Enable = 'off';
            app.MovieButton.Tooltip = {'Movie display'};
            app.MovieButton.Text = 'movie';
            app.MovieButton.Value = true;
            app.AveragesButton = uiradiobutton(app.ButtonGroup);
            app.AveragesButton.Enable = 'off';
            app.AveragesButton.Tooltip = {'Map of the number of k-space signal averages'};
            app.AveragesButton.Text = 'averages';
            app.kSpaceButton = uiradiobutton(app.ButtonGroup);
            app.kSpaceButton.Enable = 'off';
            app.kSpaceButton.Tooltip = {'Absolute value of k-space'};
            app.kSpaceButton.Text = '|k-space|';
            app.SenseMapButton = uiradiobutton(app.ButtonGroup);
            app.SenseMapButton.Enable = 'off';
            app.SenseMapButton.Tooltip = {'Coil sensitivity maps'};
            app.SenseMapButton.Text = 'sense-map';
            app.MovieCoilSpinner = uispinner(app.MovieTab);
            app.MovieCoilSpinner.Limits = [1 16];
            app.MovieCoilSpinner.ValueDisplayFormat = '%.0f';
            app.MovieCoilSpinner.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.MovieCoilSpinner.Enable = 'off';
            app.MovieCoilSpinner.Value = 1;
            app.SenseMapSpinner1 = uispinner(app.MovieTab);
            app.SenseMapSpinner1.Limits = [1 16];
            app.SenseMapSpinner1.ValueDisplayFormat = '%.0f';
            app.SenseMapSpinner1.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.SenseMapSpinner1.Enable = 'off';
            app.SenseMapSpinner1.Value = 1;
            app.SenseMapSpinner2 = uispinner(app.MovieTab);
            app.SenseMapSpinner2.Limits = [1 16];
            app.SenseMapSpinner2.ValueDisplayFormat = '%.0f';
            app.SenseMapSpinner2.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.SenseMapSpinner2.Enable = 'off';
            app.SenseMapSpinner2.Value = 1;
            app.MovieDynIncrButton = uibutton(app.MovieTab, 'push');
            app.MovieDynIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieDynIncrButtonPushed, true);
            app.MovieDynIncrButton.Enable = 'off';
            app.MovieDynIncrButton.Tooltip = {''};
            app.MovieDynIncrButton.Text = '+1';
            app.MovieDynLabel = uilabel(app.MovieTab);
            app.MovieDynLabel.Text = 'dynamic';
            app.MovieDynDecrButton = uibutton(app.MovieTab, 'push');
            app.MovieDynDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieDynDecrButtonPushed, true);
            app.MovieDynDecrButton.Enable = 'off';
            app.MovieDynDecrButton.Text = '-1';
            app.MovieCheckBox1 = uicheckbox(app.MovieTab);
            app.MovieCheckBox1.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox1.Enable = 'off';
            app.MovieCheckBox1.Text = '';
            app.MovieCheckBox1.Value = true;
            app.MovieCheckBox2 = uicheckbox(app.MovieTab);
            app.MovieCheckBox2.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox2.Enable = 'off';
            app.MovieCheckBox2.Text = '';
            app.MovieCheckBox3 = uicheckbox(app.MovieTab);
            app.MovieCheckBox3.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox3.Enable = 'off';
            app.MovieCheckBox3.Text = '';
            app.AutoButton = uibutton(app.MovieTab, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Enable = 'off';
            app.AutoButton.Tooltip = {'Auto adjust image intensity'};
            app.AutoButton.Text = 'auto';
            app.frameLabel = uilabel(app.MovieTab);
            app.frameLabel.Tooltip = {'Cardiac / respiratory movie'};
            app.frameLabel.Text = 'frame';
            app.MovieFrameEditField = uieditfield(app.MovieTab, 'numeric');
            app.MovieFrameEditField.Limits = [1 240];
            app.MovieFrameEditField.ValueDisplayFormat = '%.0f';
            app.MovieFrameEditField.ValueChangedFcn = createCallbackFcn(app, @MovieFrameEditFieldValueChanged, true);
            app.MovieFrameEditField.Enable = 'off';
            app.MovieFrameEditField.Tooltip = {'CINE frame number'};
            app.MovieFrameEditField.Value = 1;
            app.heartbpmLabel_2 = uilabel(app.MovieTab);
            app.heartbpmLabel_2.Tooltip = {'Average heart rate.'};
            app.heartbpmLabel_2.Text = 'heart (bpm)';
            app.FinalHeartViewField = uieditfield(app.MovieTab, 'numeric');
            app.FinalHeartViewField.Editable = 'off';
            app.FinalHeartViewField.Tooltip = {'Heart rate (bpm)'};
            app.respbpmLabel_2 = uilabel(app.MovieTab);
            app.respbpmLabel_2.Tooltip = {'Average respiration rate.'};
            app.respbpmLabel_2.Text = 'resp. (bpm)';
            app.FinalRespirationViewField = uieditfield(app.MovieTab, 'numeric');
            app.FinalRespirationViewField.Editable = 'off';
            app.FinalRespirationViewField.Tooltip = {'Respiration rate (bpm)'};
            app.sliceLabel = uilabel(app.MovieTab);
            app.sliceLabel.Tooltip = {'Slices movie'};
            app.sliceLabel.Text = 'slice';
            app.MovieSliceEditField = uieditfield(app.MovieTab, 'numeric');
            app.MovieSliceEditField.Limits = [1 1024];
            app.MovieSliceEditField.ValueDisplayFormat = '%.0f';
            app.MovieSliceEditField.ValueChangedFcn = createCallbackFcn(app, @MovieSliceEditFieldValueChanged, true);
            app.MovieSliceEditField.Enable = 'off';
            app.MovieSliceEditField.Tooltip = {'Slice number'};
            app.MovieSliceEditField.Value = 1;
            app.WLabel = uilabel(app.MovieTab);
            app.WLabel.Tooltip = {'Intensity scale adjustment.'};
            app.WLabel.Text = 'W';
            app.WindowScaleSlider = uislider(app.MovieTab);
            app.WindowScaleSlider.Limits = [-32767 32767];
            app.WindowScaleSlider.MajorTicks = [];
            app.WindowScaleSlider.ValueChangedFcn = createCallbackFcn(app, @WindowScaleSliderValueChanged, true);
            app.WindowScaleSlider.MinorTicks = [];
            app.WindowScaleSlider.Enable = 'off';
            app.WindowScaleSlider.Tooltip = {'Window'};
            app.WindowScaleSlider.Value = 1;
            app.LLabel = uilabel(app.MovieTab);
            app.LLabel.Tooltip = {'Intensity scale adjustment.'};
            app.LLabel.Text = 'L';
            app.LevelScaleSlider = uislider(app.MovieTab);
            app.LevelScaleSlider.Limits = [-32767 32767];
            app.LevelScaleSlider.MajorTicks = [];
            app.LevelScaleSlider.ValueChangedFcn = createCallbackFcn(app, @LevelScaleSliderValueChanged, true);
            app.LevelScaleSlider.MinorTicks = [];
            app.LevelScaleSlider.Enable = 'off';
            app.LevelScaleSlider.Tooltip = {'Level'};
            app.LevelScaleSlider.Value = 1;
            app.MovieDynEditField = uieditfield(app.MovieTab, 'numeric');
            app.MovieDynEditField.Limits = [1 1024];
            app.MovieDynEditField.ValueDisplayFormat = '%.0f';
            app.MovieDynEditField.ValueChangedFcn = createCallbackFcn(app, @MovieDynEditFieldValueChanged, true);
            app.MovieDynEditField.Enable = 'off';
            app.MovieDynEditField.Tooltip = {'Dynamic or flip-angle'};
            app.MovieDynEditField.Value = 1;
            app.DynamicLabel = uilabel(app.MovieTab);
            app.DynamicLabel.Text = 'dynamic';
            app.LcurveTab = uitab(app.TabGroup4);
            app.LcurveTab.Title = 'L-curve';
            app.LcurveFig = uiaxes(app.LcurveTab);
            title(app.LcurveFig, 'L-curve')
            xlabel(app.LcurveFig, '|| F^{-1}x_{\lambda} - y ||_2')
            ylabel(app.LcurveFig, '|| TVcine x_{\lambda} ||_2')
            app.LcurveFig.XLim = [0 100];
            app.LcurveFig.YLim = [0 1000];
            app.LcurveModelErrorFig = uiaxes(app.LcurveTab);
            title(app.LcurveModelErrorFig, 'Model error')
            xlabel(app.LcurveModelErrorFig, '\lambda')
            ylabel(app.LcurveModelErrorFig, ' || F^{-1}x_{\lambda} - y ||_2')
            app.LcurveModelErrorFig.XLim = [0 100];
            app.LcurveModelErrorFig.YLim = [0 1000];
            app.LcurveStartButton = uibutton(app.LcurveTab, 'push');
            app.LcurveStartButton.ButtonPushedFcn = createCallbackFcn(app, @LcurveStartButtonPushed, true);
            app.LcurveStartButton.Enable = 'off';
            app.LcurveStartButton.Text = 'Start';
            app.lowerEditFieldLabel = uilabel(app.LcurveTab);
            app.lowerEditFieldLabel.Text = 'lower';
            app.Lambda1EditField = uieditfield(app.LcurveTab, 'numeric');
            app.Lambda1EditField.Limits = [0 0.1];
            app.Lambda1EditField.Value = 0.001;
            app.upperEditFieldLabel = uilabel(app.LcurveTab);
            app.upperEditFieldLabel.Text = 'upper';
            app.Lambda2EditField = uieditfield(app.LcurveTab, 'numeric');
            app.Lambda2EditField.Limits = [0.11 10];
            app.Lambda2EditField.Value = 1;
            app.stepsEditFieldLabel = uilabel(app.LcurveTab);
            app.stepsEditFieldLabel.Text = '#steps';
            app.LambdaStepsEditField = uieditfield(app.LcurveTab, 'numeric');
            app.LambdaStepsEditField.Limits = [6 30];
            app.LambdaStepsEditField.ValueDisplayFormat = '%.0f';
            app.LambdaStepsEditField.Value = 12;
            app.iterLabel = uilabel(app.LcurveTab);
            app.iterLabel.Text = '#iter';
            app.LambdaIterationsEditField = uieditfield(app.LcurveTab, 'numeric');
            app.LambdaIterationsEditField.Limits = [1 10];
            app.LambdaIterationsEditField.ValueDisplayFormat = '%.0f';
            app.LambdaIterationsEditField.Value = 1;
            app.LambdaLabel = uilabel(app.LcurveTab);
            app.LambdaLabel.Text = 'Lambda';
            app.LcurveStopButton = uibutton(app.LcurveTab, 'push');
            app.LcurveStopButton.ButtonPushedFcn = createCallbackFcn(app, @LcurveStopButtonPushed, true);
            app.LcurveStopButton.Enable = 'off';
            app.LcurveStopButton.Text = 'Stop';
            app.LcurveWVxyzCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveWVxyzCheckBox.Text = '';
            app.LcurveTVcineCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveTVcineCheckBox.Text = '';
            app.LcurveTVcineCheckBox.Value = true;
            app.LcurveTVxyzCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveTVxyzCheckBox.Text = '';
            app.LcurveTVdynCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveTVdynCheckBox.Text = '';
            app.WVxyzLabel = uilabel(app.LcurveTab);
            app.WVxyzLabel.Text = 'WVxyz';
            app.TVcineLabel = uilabel(app.LcurveTab);
            app.TVcineLabel.Text = 'TVcine';
            app.TVxyzLabel = uilabel(app.LcurveTab);
            app.TVxyzLabel.Text = 'TVxyz';
            app.TVdynLabel = uilabel(app.LcurveTab);
            app.TVdynLabel.Text = 'TVdyn';
            app.RegularizationLabel = uilabel(app.LcurveTab);
            app.RegularizationLabel.Text = 'Regularization';
            app.LRxyzLabel2 = uilabel(app.LcurveTab);
            app.LRxyzLabel2.Text = 'LRxyz';
            app.LcurveLRxyzCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveLRxyzCheckBox.Text = '';
            app.RadialTab = uitab(app.TabGroup4);
            app.RadialTab.Title = 'Radial';
            app.RadialConstructionImage = uiimage(app.RadialTab);
            app.UnderConstructionLabel = uilabel(app.RadialTab);
            app.UnderConstructionLabel.Text = 'Under Construction';
            app.TabGroup3 = uitabgroup(app.RetrospectiveUIFigure);
            app.AcquisitionparametersTab = uitab(app.TabGroup3);
            app.AcquisitionparametersTab.Title = 'Acquisition parameters';
            app.TrajectoryFig = uiaxes(app.AcquisitionparametersTab);
            app.TrajectoryFig.XTick = [];
            app.TrajectoryFig.YTick = [];
            app.DataFile = uitextarea(app.AcquisitionparametersTab);
            app.DataFile.ValueChangedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.DataFile.Editable = 'off';
            app.DataFile.Tooltip = {'Data file'};
            app.DataFile.Value = {' '};
            app.DatafileLabel = uilabel(app.AcquisitionparametersTab);
            app.DatafileLabel.Text = 'data file';
            app.phaseNyLabel = uilabel(app.AcquisitionparametersTab);
            app.phaseNyLabel.Text = 'phase  Ny';
            app.NyViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.NyViewField.Limits = [0 Inf];
            app.NyViewField.ValueDisplayFormat = '%.0f';
            app.NyViewField.Editable = 'off';
            app.NyViewField.Tooltip = {'Number of pixels in phase-encoding direction'};
            app.freqNxLabel = uilabel(app.AcquisitionparametersTab);
            app.freqNxLabel.Text = 'freq  Nx';
            app.NxViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.NxViewField.Limits = [0 Inf];
            app.NxViewField.ValueDisplayFormat = '%.0f';
            app.NxViewField.Editable = 'off';
            app.NxViewField.Tooltip = {'Number of pixels in readout direction'};
            app.sizeLabel = uilabel(app.AcquisitionparametersTab);
            app.sizeLabel.Text = 'size';
            app.kLinesRepetitionViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.kLinesRepetitionViewField.Limits = [0 Inf];
            app.kLinesRepetitionViewField.ValueDisplayFormat = '%.0f';
            app.kLinesRepetitionViewField.Editable = 'off';
            app.kLinesRepetitionViewField.Tooltip = {'Length of the trajectory'};
            app.repetitionsLabel = uilabel(app.AcquisitionparametersTab);
            app.repetitionsLabel.Text = '#repetitions';
            app.RepetitionsViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.RepetitionsViewField.Limits = [0 Inf];
            app.RepetitionsViewField.RoundFractionalValues = 'on';
            app.RepetitionsViewField.ValueDisplayFormat = '%.0f';
            app.RepetitionsViewField.Editable = 'off';
            app.RepetitionsViewField.Tooltip = {'Number of k-space repetitions'};
            app.klinesLabel = uilabel(app.AcquisitionparametersTab);
            app.klinesLabel.Text = ' # k-lines';
            app.NrKlinesViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.NrKlinesViewField.Limits = [0 Inf];
            app.NrKlinesViewField.ValueDisplayFormat = '%.0f';
            app.NrKlinesViewField.Editable = 'off';
            app.NrKlinesViewField.Tooltip = {'Total number of acquired/reconstructed k-lines'};
            app.averagesEditFieldLabel = uilabel(app.AcquisitionparametersTab);
            app.averagesEditFieldLabel.Text = 'averages';
            app.AveragesViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.AveragesViewField.Limits = [0 Inf];
            app.AveragesViewField.Editable = 'off';
            app.AveragesViewField.Tooltip = {'Mean number of signal averages'};
            app.fillingLabel = uilabel(app.AcquisitionparametersTab);
            app.fillingLabel.Text = 'filling (%)';
            app.FillingViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.FillingViewField.Limits = [0 100];
            app.FillingViewField.ValueDisplayFormat = '%.0f';
            app.FillingViewField.Editable = 'off';
            app.FillingViewField.Tooltip = {'K-space filling percentage'};
            app.TRmsLabel = uilabel(app.AcquisitionparametersTab);
            app.TRmsLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.ValueDisplayFormat = '%.2f';
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.trajectoryEditFieldLabel = uilabel(app.AcquisitionparametersTab);
            app.trajectoryEditFieldLabel.Text = 'trajectory';
            app.TrajectoryViewField = uieditfield(app.AcquisitionparametersTab, 'text');
            app.TrajectoryViewField.Editable = 'off';
            app.TrajectoryViewField.Tooltip = {'K-space filling trajectory'};
            app.slicesNzLabel = uilabel(app.AcquisitionparametersTab);
            app.slicesNzLabel.Text = 'slices  Nz';
            app.NzViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.NzViewField.Limits = [0 Inf];
            app.NzViewField.ValueDisplayFormat = '%.0f';
            app.NzViewField.Editable = 'off';
            app.NzViewField.Tooltip = {'Number of slices'};
            app.coilsNcLabel = uilabel(app.AcquisitionparametersTab);
            app.coilsNcLabel.Text = 'coils  Nc';
            app.NcViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.NcViewField.Limits = [0 Inf];
            app.NcViewField.ValueDisplayFormat = '%.0f';
            app.NcViewField.Editable = 'off';
            app.NcViewField.Tooltip = {'Number of receiver coils'};
            app.scantimeEditFieldLabel = uilabel(app.AcquisitionparametersTab);
            app.scantimeEditFieldLabel.Text = 'scan time';
            app.ScanTimeViewField = uieditfield(app.AcquisitionparametersTab, 'text');
            app.ScanTimeViewField.Editable = 'off';
            app.ScanTimeViewField.Tooltip = {'Total scan time'};
            app.timedynamicLabel = uilabel(app.AcquisitionparametersTab);
            app.timedynamicLabel.Text = 'time / dynamic';
            app.TimeDynViewField = uieditfield(app.AcquisitionparametersTab, 'text');
            app.TimeDynViewField.Editable = 'off';
            app.TimeDynViewField.Tooltip = {'Scan time per dynamic'};
            app.flipanglesLabel = uilabel(app.AcquisitionparametersTab);
            app.flipanglesLabel.Text = '#flip-angles';
            app.FAViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.FAViewField.Limits = [0 100];
            app.FAViewField.ValueDisplayFormat = '%.0f';
            app.FAViewField.Editable = 'off';
            app.FAViewField.Tooltip = {'Number of flip-angles for T1-mapping'};
            app.PresetsTab = uitab(app.TabGroup3);
            app.PresetsTab.Title = 'Presets';
            app.SpeciesButton = uibuttongroup(app.PresetsTab);
            app.SpeciesButton.SelectionChangedFcn = createCallbackFcn(app, @SpeciesButtonSelectionChanged, true);
            app.SpeciesButton.Tooltip = {'Preset navigator filter values for mouse and rat'};
            app.SpeciesButton.ButtonDownFcn = createCallbackFcn(app, @SpeciesButtonButtonDown, true);
            app.MouseButton = uiradiobutton(app.SpeciesButton);
            app.MouseButton.Enable = 'off';
            app.MouseButton.Tooltip = {'Mouse preset navigator filter settings'};
            app.MouseButton.Text = 'mouse';
            app.MouseButton.Value = true;
            app.RatButton = uiradiobutton(app.SpeciesButton);
            app.RatButton.Enable = 'off';
            app.RatButton.Tooltip = {'Rat preset navigator filter settings'};
            app.RatButton.Text = 'rat';
            app.SpeciesLabel = uilabel(app.PresetsTab);
            app.SpeciesLabel.Text = 'Species';
            app.RecoPresetsButtonGroup = uibuttongroup(app.PresetsTab);
            app.RecoPresetsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RecoPresetsButtonGroupSelectionChanged, true);
            app.RecoPresetsButtonGroup.Tooltip = {'Preset reconstruction settings'};
            app.RecoPresetsButtonGroup.ButtonDownFcn = createCallbackFcn(app, @RecoPresetsButtonGroupButtonDown, true);
            app.ScoutButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.ScoutButton.Enable = 'off';
            app.ScoutButton.Tooltip = {'Single frame scout reconstruction'};
            app.ScoutButton.Text = 'scout';
            app.SystolicFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.SystolicFunctionButton.Enable = 'off';
            app.SystolicFunctionButton.Tooltip = {'Suggested reconstruction settings for measuring systolic function'};
            app.SystolicFunctionButton.Text = 'systolic function';
            app.SystolicFunctionButton.Value = true;
            app.DiastolicFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.DiastolicFunctionButton.Enable = 'off';
            app.DiastolicFunctionButton.Tooltip = {'Suggested reconstruction settings for measuring diastolic function'};
            app.DiastolicFunctionButton.Text = 'diastolic function';
            app.RespiratoryFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.RespiratoryFunctionButton.Enable = 'off';
            app.RespiratoryFunctionButton.Tooltip = {'Suggested reconstruction settings for measuring respiratory motion'};
            app.RespiratoryFunctionButton.Text = 'respiratory function';
            app.RealTimeFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.RealTimeFunctionButton.Enable = 'off';
            app.RealTimeFunctionButton.Tooltip = {'Suggested reconstruction settings for realtime imaging = CINE movie per heartbeat'};
            app.RealTimeFunctionButton.Text = 'realtime';
            app.VfaFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.VfaFunctionButton.Enable = 'off';
            app.VfaFunctionButton.Tooltip = {'Suggested reconstruction settings for T1-mapping with the variable-flip-angle method'};
            app.VfaFunctionButton.Text = 'variable flip-angle';
            app.ReconstructionpresetsLabel = uilabel(app.PresetsTab);
            app.ReconstructionpresetsLabel.Text = 'Reconstruction presets';
            app.OtherLabel = uilabel(app.PresetsTab);
            app.OtherLabel.Text = 'Other';
            app.AutoGuessRecoCheckBox = uicheckbox(app.PresetsTab);
            app.AutoGuessRecoCheckBox.Text = 'guess reco';
            app.AutoGuessRecoCheckBox.Value = true;
            app.AutoSoundsCheckBox = uicheckbox(app.PresetsTab);
            app.AutoSoundsCheckBox.Text = 'sounds';
            app.AutoSoundsCheckBox.Value = true;
            app.TabGroup2 = uitabgroup(app.RetrospectiveUIFigure);
            app.NavigatoranalysisTab = uitab(app.TabGroup2);
            app.NavigatoranalysisTab.Title = 'Navigator analysis';
            app.CardiacNavFig = uiaxes(app.NavigatoranalysisTab);
            title(app.CardiacNavFig, 'Heart navigator')
            xlabel(app.CardiacNavFig, 'Samples')
            ylabel(app.CardiacNavFig, 'Signal')
            app.RespirationNavFig = uiaxes(app.NavigatoranalysisTab);
            title(app.RespirationNavFig, 'Respiration navigator')
            xlabel(app.RespirationNavFig, 'Samples')
            ylabel(app.RespirationNavFig, 'Signal')
            app.HeartRateTimeFig = uiaxes(app.NavigatoranalysisTab);
            title(app.HeartRateTimeFig, 'Heart rate')
            xlabel(app.HeartRateTimeFig, 'Time (s)')
            ylabel(app.HeartRateTimeFig, 'Rate (bpm)')
            app.HeartRateTimeFig.XLim = [0 100];
            app.HeartRateTimeFig.YLim = [0 1000];
            app.RespRateTimeFig = uiaxes(app.NavigatoranalysisTab);
            title(app.RespRateTimeFig, 'Respiration rate')
            xlabel(app.RespRateTimeFig, 'Time (s)')
            ylabel(app.RespRateTimeFig, 'Rate (bpm)')
            app.RespRateTimeFig.XLim = [0 100];
            app.RespRateTimeFig.YLim = [0 100];
            app.DisplayRangeLabel = uilabel(app.NavigatoranalysisTab);
            app.DisplayRangeLabel.Tooltip = {'Selects the starting point and range of the heart- and respiratory-navigator windows'};
            app.DisplayRangeLabel.Text = 'Display range';
            app.StartSlider = uislider(app.NavigatoranalysisTab);
            app.StartSlider.Limits = [1 6000];
            app.StartSlider.MajorTicks = [];
            app.StartSlider.MajorTickLabels = {'1', '6000'};
            app.StartSlider.ValueChangedFcn = createCallbackFcn(app, @DisplaySlidersValueChanged, true);
            app.StartSlider.MinorTicks = [];
            app.StartSlider.Enable = 'off';
            app.StartSlider.Value = 1000;
            app.RangeSlider = uislider(app.NavigatoranalysisTab);
            app.RangeSlider.Limits = [1 2000];
            app.RangeSlider.MajorTicks = [];
            app.RangeSlider.MajorTickLabels = {'1', '6000'};
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @DisplaySlidersValueChanged, true);
            app.RangeSlider.MinorTicks = [];
            app.RangeSlider.Enable = 'off';
            app.RangeSlider.Value = 600;
            app.IncludeCheckBox = uicheckbox(app.NavigatoranalysisTab);
            app.IncludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeCheckBoxValueChanged, true);
            app.IncludeCheckBox.Enable = 'off';
            app.IncludeCheckBox.Tooltip = {'Switch include window on/off'};
            app.IncludeCheckBox.Text = '';
            app.IncludeStartSlider = uislider(app.NavigatoranalysisTab);
            app.IncludeStartSlider.Limits = [1 6000];
            app.IncludeStartSlider.MajorTicks = [];
            app.IncludeStartSlider.MajorTickLabels = {'1', '6000'};
            app.IncludeStartSlider.ValueChangedFcn = createCallbackFcn(app, @IncludeSliderValueChanged, true);
            app.IncludeStartSlider.MinorTicks = [];
            app.IncludeStartSlider.Enable = 'off';
            app.IncludeStartSlider.Value = 1000;
            app.IncludeEndSlider = uislider(app.NavigatoranalysisTab);
            app.IncludeEndSlider.Limits = [1 6000];
            app.IncludeEndSlider.MajorTicks = [];
            app.IncludeEndSlider.MajorTickLabels = {'1', '6000'};
            app.IncludeEndSlider.ValueChangedFcn = createCallbackFcn(app, @IncludeSliderValueChanged, true);
            app.IncludeEndSlider.MinorTicks = [];
            app.IncludeEndSlider.Enable = 'off';
            app.IncludeEndSlider.Value = 1000;
            app.IncludeLabel = uilabel(app.NavigatoranalysisTab);
            app.IncludeLabel.Tooltip = {'Windows the data to reconstruct only part of the acquisition. '; 'Only the green selection area will be included. '; 'Window can be adjusted with the sliders.'};
            app.IncludeLabel.Text = 'Include';
            app.ExcludeCheckBox = uicheckbox(app.NavigatoranalysisTab);
            app.ExcludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeCheckBoxValueChanged, true);
            app.ExcludeCheckBox.Enable = 'off';
            app.ExcludeCheckBox.Tooltip = {'Switch include window on/off'};
            app.ExcludeCheckBox.Text = '';
            app.ExcludeStartSlider = uislider(app.NavigatoranalysisTab);
            app.ExcludeStartSlider.Limits = [1 6000];
            app.ExcludeStartSlider.MajorTicks = [];
            app.ExcludeStartSlider.MajorTickLabels = {'1', '6000'};
            app.ExcludeStartSlider.ValueChangedFcn = createCallbackFcn(app, @ExcludeSliderValueChanged, true);
            app.ExcludeStartSlider.MinorTicks = [];
            app.ExcludeStartSlider.Enable = 'off';
            app.ExcludeStartSlider.Value = 1000;
            app.ExcludeEndSlider = uislider(app.NavigatoranalysisTab);
            app.ExcludeEndSlider.Limits = [1 6000];
            app.ExcludeEndSlider.MajorTicks = [];
            app.ExcludeEndSlider.MajorTickLabels = {'1', '6000'};
            app.ExcludeEndSlider.ValueChangedFcn = createCallbackFcn(app, @ExcludeSliderValueChanged, true);
            app.ExcludeEndSlider.MinorTicks = [];
            app.ExcludeEndSlider.Enable = 'off';
            app.ExcludeEndSlider.Value = 1000;
            app.ExcludeLabel = uilabel(app.NavigatoranalysisTab);
            app.ExcludeLabel.Tooltip = {'Windows the data to exclude part of the acquisition. '; 'The red selection area will be excluded. '; 'Window can be adjusted with the sliders.'};
            app.ExcludeLabel.Text = 'Exclude';
            app.NavStartButton = uibutton(app.NavigatoranalysisTab, 'push');
            app.NavStartButton.ButtonPushedFcn = createCallbackFcn(app, @NavStartButtonPushed, true);
            app.NavStartButton.Enable = 'off';
            app.NavStartButton.Tooltip = {'Start navigator movie'};
            app.NavStartButton.Text = ' ';
            app.NavStopButton = uibutton(app.NavigatoranalysisTab, 'push');
            app.NavStopButton.ButtonPushedFcn = createCallbackFcn(app, @NavStopButtonPushed, true);
            app.NavStopButton.Enable = 'off';
            app.NavStopButton.Tooltip = {'Stop navigator movie'};
            app.NavStopButton.Text = ' ';
            app.NavFastSlowSwitch = uiswitch(app.NavigatoranalysisTab, 'rocker');
            app.NavFastSlowSwitch.Items = {'slow', 'fast'};
            app.NavFastSlowSwitch.ValueChangedFcn = createCallbackFcn(app, @NavFastSlowSwitchValueChanged, true);
            app.NavFastSlowSwitch.Enable = 'off';
            app.NavFastSlowSwitch.Tooltip = {'Navigator movie fast or slow'};
            app.NavFastSlowSwitch.Value = 'slow';
            app.startEditFieldLabel = uilabel(app.NavigatoranalysisTab);
            app.startEditFieldLabel.Tooltip = {''};
            app.startEditFieldLabel.Text = 'start';
            app.StartEditField = uieditfield(app.NavigatoranalysisTab, 'numeric');
            app.StartEditField.Limits = [1 Inf];
            app.StartEditField.RoundFractionalValues = 'on';
            app.StartEditField.ValueDisplayFormat = '%.0f';
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValuesChanged, true);
            app.StartEditField.Enable = 'off';
            app.StartEditField.Tooltip = {'Start of plotted navigator window'};
            app.StartEditField.Value = 1000;
            app.rangeEditFieldLabel = uilabel(app.NavigatoranalysisTab);
            app.rangeEditFieldLabel.Tooltip = {''};
            app.rangeEditFieldLabel.Text = 'range';
            app.RangeEditField = uieditfield(app.NavigatoranalysisTab, 'numeric');
            app.RangeEditField.Limits = [1 2000];
            app.RangeEditField.RoundFractionalValues = 'on';
            app.RangeEditField.ValueDisplayFormat = '%.0f';
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValuesChanged, true);
            app.RangeEditField.Enable = 'off';
            app.RangeEditField.Tooltip = {'Range of plotted navigator window'};
            app.RangeEditField.Value = 600;
            app.TabGroup1 = uitabgroup(app.RetrospectiveUIFigure);
            app.NavigatorTab = uitab(app.TabGroup1);
            app.NavigatorTab.Title = 'Navigator';
            app.RawNavFig = uiaxes(app.NavigatorTab);
            title(app.RawNavFig, 'Raw navigator')
            xlabel(app.RawNavFig, 'Samples')
            ylabel(app.RawNavFig, 'Signal')
            app.SpectrumFig = uiaxes(app.NavigatorTab);
            title(app.SpectrumFig, 'Navigator Spectrum')
            xlabel(app.SpectrumFig, 'Frequency (bpm)')
            ylabel(app.SpectrumFig, 'Power')
            app.CoilSpinner = uispinner(app.NavigatorTab);
            app.CoilSpinner.Limits = [1 16];
            app.CoilSpinner.ValueDisplayFormat = '%.0f';
            app.CoilSpinner.ValueChangedFcn = createCallbackFcn(app, @CoilSpinnerValueChanged, true);
            app.CoilSpinner.Enable = 'off';
            app.CoilSpinner.Tooltip = {'RF receiver coil number'};
            app.CoilSpinner.Value = 1;
            app.CoilLabel = uilabel(app.NavigatorTab);
            app.CoilLabel.Tooltip = {'Toggles between RF-coils to inspect the raw navigator signals.'};
            app.CoilLabel.Text = 'coil';
            app.LogLinButton = uibutton(app.NavigatorTab, 'push');
            app.LogLinButton.ButtonPushedFcn = createCallbackFcn(app, @LogLinButtonPushed, true);
            app.LogLinButton.Enable = 'off';
            app.LogLinButton.Text = 'lin';
            app.FlipSwitchLabel = uilabel(app.NavigatorTab);
            app.FlipSwitchLabel.Tooltip = {'Flips the navigator signal.'; 'Respiratory navigator signals should display positive peaks.'};
            app.FlipSwitchLabel.Text = 'Flip';
            app.NavigatorFlipSwitch = uiswitch(app.NavigatorTab, 'toggle');
            app.NavigatorFlipSwitch.Items = {'Down', 'Up'};
            app.NavigatorFlipSwitch.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavigatorFlipSwitch.Enable = 'off';
            app.NavigatorFlipSwitch.Tooltip = {'Flip navigator signal'};
            app.NavigatorFlipSwitch.Value = 'Up';
            app.AdvancedsettingsTab = uitab(app.TabGroup1);
            app.AdvancedsettingsTab.Title = 'Advanced settings';
            app.PhysioFilteringLabel = uilabel(app.AdvancedsettingsTab);
            app.PhysioFilteringLabel.Text = 'Physio filtering';
            app.minresprateEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.minresprateEditFieldLabel.Text = 'min resp rate';
            app.MinRespRateEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.MinRespRateEditField.Limits = [5 400];
            app.MinRespRateEditField.ValueDisplayFormat = '%.0f';
            app.MinRespRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MinRespRateEditField.Enable = 'off';
            app.MinRespRateEditField.Tooltip = {'Minimum search range respiration rate'};
            app.MinRespRateEditField.Value = 5;
            app.maxresprateEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.maxresprateEditFieldLabel.Text = 'max resp rate';
            app.MaxRespRateEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.MaxRespRateEditField.Limits = [5 400];
            app.MaxRespRateEditField.ValueDisplayFormat = '%.0f';
            app.MaxRespRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MaxRespRateEditField.Enable = 'off';
            app.MaxRespRateEditField.Tooltip = {'Maximum search range respiration rate'};
            app.MaxRespRateEditField.Value = 400;
            app.minheartrateEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.minheartrateEditFieldLabel.Text = 'min heart rate';
            app.MinHeartRateEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.MinHeartRateEditField.Limits = [100 1000];
            app.MinHeartRateEditField.ValueDisplayFormat = '%.0f';
            app.MinHeartRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MinHeartRateEditField.Enable = 'off';
            app.MinHeartRateEditField.Tooltip = {'Minimum of search range heart rate'};
            app.MinHeartRateEditField.Value = 100;
            app.maxheartrateEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.maxheartrateEditFieldLabel.Text = 'max heart rate';
            app.MaxHeartRateEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.MaxHeartRateEditField.Limits = [100 1000];
            app.MaxHeartRateEditField.ValueDisplayFormat = '%.0f';
            app.MaxHeartRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MaxHeartRateEditField.Enable = 'off';
            app.MaxHeartRateEditField.Tooltip = {'Maxiumum of search range heart rate'};
            app.MaxHeartRateEditField.Value = 1000;
            app.filterorderEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.filterorderEditFieldLabel.Text = 'filter order';
            app.FilterOrderEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.FilterOrderEditField.Limits = [1 6];
            app.FilterOrderEditField.ValueDisplayFormat = '%.0f';
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.FilterOrderEditField.Enable = 'off';
            app.FilterOrderEditField.Tooltip = {'Butterworth filter order'};
            app.FilterOrderEditField.Value = 4;
            app.DiscardEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.DiscardEditField.Limits = [0 1024];
            app.DiscardEditField.ValueDisplayFormat = '%.0f';
            app.DiscardEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.DiscardEditField.Enable = 'off';
            app.DiscardEditField.Tooltip = {'Number of frequency encoding points discarded after navigator. Increase if FID artifact is present in movie.'};
            app.DiscardEditField.Value = 35;
            app.FreqNxdiscardLabel = uilabel(app.AdvancedsettingsTab);
            app.FreqNxdiscardLabel.Text = 'freq Nx discard';
            app.NavigatorLabel = uilabel(app.AdvancedsettingsTab);
            app.NavigatorLabel.Text = 'Navigator';
            app.primaryLabel = uilabel(app.AdvancedsettingsTab);
            app.primaryLabel.Tooltip = {'Primary navigator point.'};
            app.primaryLabel.Text = 'primary';
            app.NavigatorEndEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.NavigatorEndEditField.Limits = [1 1024];
            app.NavigatorEndEditField.ValueDisplayFormat = '%.0f';
            app.NavigatorEndEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavigatorEndEditField.Enable = 'off';
            app.NavigatorEndEditField.Tooltip = {'Primary navigator point analyzed'};
            app.NavigatorEndEditField.Value = 10;
            app.pointsLabel = uilabel(app.AdvancedsettingsTab);
            app.pointsLabel.Tooltip = {'Number of points that are considered for navigator analysis.'};
            app.pointsLabel.Text = '# points';
            app.NavigatorNRPointsEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.NavigatorNRPointsEditField.Limits = [1 24];
            app.NavigatorNRPointsEditField.ValueDisplayFormat = '%.0f';
            app.NavigatorNRPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavigatorNRPointsEditField.Enable = 'off';
            app.NavigatorNRPointsEditField.Tooltip = {'Number of navigator points analyzed'};
            app.NavigatorNRPointsEditField.Value = 1;
            app.SpeciesButton2 = uibuttongroup(app.AdvancedsettingsTab);
            app.SpeciesButton2.SelectionChangedFcn = createCallbackFcn(app, @SpeciesButton2SelectionChanged, true);
            app.SpeciesButton2.Tooltip = {'Preset navigator filter values for mouse and rat'};
            app.SpeciesButton2.ButtonDownFcn = createCallbackFcn(app, @SpeciesButtonButtonDown, true);
            app.MouseButton2 = uiradiobutton(app.SpeciesButton2);
            app.MouseButton2.Enable = 'off';
            app.MouseButton2.Tooltip = {'Mouse preset navigator filter settings'};
            app.MouseButton2.Text = 'mouse';
            app.MouseButton2.Value = true;
            app.RatButton2 = uiradiobutton(app.SpeciesButton2);
            app.RatButton2.Enable = 'off';
            app.RatButton2.Tooltip = {'Rat preset navigator filter settings'};
            app.RatButton2.Text = 'rat';
            app.PresetsLabel2 = uilabel(app.AdvancedsettingsTab);
            app.PresetsLabel2.Text = 'Presets';
            app.framesLabel = uilabel(app.AdvancedsettingsTab);
            app.framesLabel.Text = '# frames';
            app.SharingEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.SharingEditField.Limits = [0 30];
            app.SharingEditField.ValueDisplayFormat = '%.0f';
            app.SharingEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.SharingEditField.Enable = 'off';
            app.SharingEditField.Tooltip = {'Sharing of k-lines from neighboring frames to improve k-space filling at the expense of temporal smoothing.'};
            app.SharingLabel = uilabel(app.AdvancedsettingsTab);
            app.SharingLabel.Text = 'Sharing';
            app.RespirationLabel = uilabel(app.AdvancedsettingsTab);
            app.RespirationLabel.Text = 'Respiration';
            app.RespirationToggleCheckBox = uicheckbox(app.AdvancedsettingsTab);
            app.RespirationToggleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RespirationToggleCheckBoxValueChanged, true);
            app.RespirationToggleCheckBox.Enable = 'off';
            app.RespirationToggleCheckBox.Tooltip = {'Switch include window on/off'};
            app.RespirationToggleCheckBox.Text = '';
            app.InExpirationToggleLabel = uilabel(app.AdvancedsettingsTab);
            app.InExpirationToggleLabel.Tooltip = {'Reconstruct cardiac CINE frames in inspiration (not checked) or expiration (checked)'};
            app.InExpirationToggleLabel.Text = 'toggle in/expiration';
            app.TabGroup0 = uitabgroup(app.RetrospectiveUIFigure);
            app.DashboardTab = uitab(app.TabGroup0);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDButton.Enable = 'off';
            app.LoadMRDButton.Tooltip = {'Select MRD file - '; 'for multicoil data select one of the MRD files'};
            app.LoadMRDButton.Text = ' Load MRD';
            app.DetectedratesBPMPanel = uipanel(app.DashboardTab);
            app.DetectedratesBPMPanel.Title = 'Heart / respiration filters';
            app.respbpmLabel_3 = uilabel(app.DetectedratesBPMPanel);
            app.respbpmLabel_3.Tooltip = {'Center frequency and width used as a starting point for filtering the navigator data for respiratory motion. '; 'Initially automatically determined but can be changed manually.'};
            app.respbpmLabel_3.Text = 'resp. (bpm)';
            app.RespirationEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.RespirationEditField.Limits = [5 400];
            app.RespirationEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespirationEditField.Enable = 'off';
            app.RespirationEditField.Value = 50;
            app.heartbpmLabel_3 = uilabel(app.DetectedratesBPMPanel);
            app.heartbpmLabel_3.Tooltip = {'Center frequency and width used as a starting point for filtering the navigator data for cardiac motion. '; 'Initially automatically determined but can be changed manually.'};
            app.heartbpmLabel_3.Text = 'heart (bpm)';
            app.HeartEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.HeartEditField.Limits = [100 1000];
            app.HeartEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.HeartEditField.Enable = 'off';
            app.HeartEditField.Value = 500;
            app.windowLabel = uilabel(app.DetectedratesBPMPanel);
            app.windowLabel.Tooltip = {'Percentage of the data that is discarded during respiration.'};
            app.windowLabel.Text = 'window (%)';
            app.RespirationWindowEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.RespirationWindowEditField.Limits = [5 95];
            app.RespirationWindowEditField.ValueDisplayFormat = '%.0f';
            app.RespirationWindowEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespirationWindowEditField.Enable = 'off';
            app.RespirationWindowEditField.Value = 30;
            app.RespirationWidthEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.RespirationWidthEditField.Limits = [5 100];
            app.RespirationWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespirationWidthEditField.Enable = 'off';
            app.RespirationWidthEditField.Value = 40;
            app.HeartWidthEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.HeartWidthEditField.Limits = [20 300];
            app.HeartWidthEditField.ValueDisplayFormat = '%.0f';
            app.HeartWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.HeartWidthEditField.Enable = 'off';
            app.HeartWidthEditField.Value = 100;
            app.CenterLabel = uilabel(app.DetectedratesBPMPanel);
            app.CenterLabel.Text = 'center';
            app.WidthLabel = uilabel(app.DetectedratesBPMPanel);
            app.WidthLabel.Text = 'width';
            app.ResetFilterValuesButton = uibutton(app.DetectedratesBPMPanel, 'push');
            app.ResetFilterValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFilterValuesButtonPushed, true);
            app.ResetFilterValuesButton.Enable = 'off';
            app.ResetFilterValuesButton.Text = 'reset';
            app.FilterButton = uibutton(app.DashboardTab, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Enable = 'off';
            app.FilterButton.Tooltip = {'Filters the navigator signal to extract cardiac and respiratory triggering points'};
            app.FilterButton.Text = ' Filter';
            app.ReconstructButton = uibutton(app.DashboardTab, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Enable = 'off';
            app.ReconstructButton.Tooltip = {'Perform a compressed-sensing reconstruction of the data with the Bart-toolbox (if available) or Matlab'};
            app.ReconstructButton.Text = 'Reconstruct';
            app.CSrecoparametersPanel = uipanel(app.DashboardTab);
            app.CSrecoparametersPanel.Title = 'CS reco parameters';
            app.TVcineEditFieldLabel = uilabel(app.CSrecoparametersPanel);
            app.TVcineEditFieldLabel.Tooltip = {'Total-variation regularization in the CINE dimension'};
            app.TVcineEditFieldLabel.Text = 'TVcine';
            app.TVcineEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.TVcineEditField.Limits = [0 20];
            app.TVcineEditField.ValueDisplayFormat = '%.3f';
            app.TVcineEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVcineEditField.Enable = 'off';
            app.TVcineEditField.Tooltip = {'Total Variation constraint in CINE dimension'};
            app.TVcineEditField.Value = 0.1;
            app.TVxyzEditFieldLabel = uilabel(app.CSrecoparametersPanel);
            app.TVxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVxyzEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.TVxyzEditField.Limits = [0 20];
            app.TVxyzEditField.ValueDisplayFormat = '%.3f';
            app.TVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVxyzEditField.Enable = 'off';
            app.TVxyzEditField.Tooltip = {'Total Variation constraint in spatial domain'};
            app.ProgressGauge = uigauge(app.CSrecoparametersPanel, 'linear');
            app.ProgressGauge.MajorTicks = [0 25 50 75 100];
            app.WVxyzEditFieldLabel = uilabel(app.CSrecoparametersPanel);
            app.WVxyzEditFieldLabel.Tooltip = {'Wavelet L1-norm regularization in the spatial dimensions'};
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.WVxyzEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.WVxyzEditField.Limits = [0 20];
            app.WVxyzEditField.ValueDisplayFormat = '%.3f';
            app.WVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.WVxyzEditField.Enable = 'off';
            app.WVxyzEditField.Tooltip = {'Wavelet L1 regularization in spatial domain'};
            app.ESPIRiTCheckBox = uicheckbox(app.CSrecoparametersPanel);
            app.ESPIRiTCheckBox.Enable = 'off';
            app.ESPIRiTCheckBox.Tooltip = {'ESPIRiT reconstruction.'; '(Magn Reson Med. 2014 Mar 71(3): 990–1001.)'};
            app.ESPIRiTCheckBox.Text = '';
            app.ESPIRiTLabel = uilabel(app.CSrecoparametersPanel);
            app.ESPIRiTLabel.Tooltip = {'ESPIRiT reconstruction.'; '(Magn Reson Med. 2014 Mar 71(3): 990–1001.)'};
            app.ESPIRiTLabel.Text = 'ESPIRiT';
            app.TVdynEditFieldLabel = uilabel(app.CSrecoparametersPanel);
            app.TVdynEditFieldLabel.Tooltip = {'Total-variation regularization in the dynamic dimension'};
            app.TVdynEditFieldLabel.Text = 'TVdyn';
            app.TVdynEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.TVdynEditField.Limits = [0 20];
            app.TVdynEditField.ValueDisplayFormat = '%.3f';
            app.TVdynEditField.Enable = 'off';
            app.TVdynEditField.Tooltip = {'Total Variation constraint in dynamic dimension'};
            app.LLRxyzEditFieldLabel = uilabel(app.CSrecoparametersPanel);
            app.LLRxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.LLRxyzEditFieldLabel.Text = 'LLRxyz';
            app.LLRxyzEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.LLRxyzEditField.Limits = [0 20];
            app.LLRxyzEditField.ValueDisplayFormat = '%.3f';
            app.LLRxyzEditField.Enable = 'off';
            app.LLRxyzEditField.Tooltip = {'Locally Low-Rank in spatial domain'};
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Tooltip = {'Export the movie to Dicom'};
            app.ExportDCMButton.Text = 'Export DCM';
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = '     Exit';
            app.ExportGIFButton = uibutton(app.DashboardTab, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Tooltip = {'Export the movie to animated gif'};
            app.ExportGIFButton.Text = 'Export GIF';
            app.BusyImage4 = uiimage(app.DashboardTab);
            app.Lamp4 = uilamp(app.DashboardTab);
            app.BusyImage1 = uiimage(app.DashboardTab);
            app.Lamp1 = uilamp(app.DashboardTab);
            app.BusyImage2 = uiimage(app.DashboardTab);
            app.Lamp2 = uilamp(app.DashboardTab);
            app.BusyImage5 = uiimage(app.DashboardTab);
            app.Lamp5 = uilamp(app.DashboardTab);
            app.BusyImage6 = uiimage(app.DashboardTab);
            app.Lamp6 = uilamp(app.DashboardTab);
            app.ExportMRDButton = uibutton(app.DashboardTab, 'push');
            app.ExportMRDButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMRDButtonPushed, true);
            app.ExportMRDButton.Enable = 'off';
            app.ExportMRDButton.Tooltip = {'Export the movie to a new MRD file'};
            app.ExportMRDButton.Text = 'Export MRD';
            app.BusyImage7 = uiimage(app.DashboardTab);
            app.Lamp7 = uilamp(app.DashboardTab);
            app.SortKspaceButton = uibutton(app.DashboardTab, 'push');
            app.SortKspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SortKspaceButtonPushed, true);
            app.SortKspaceButton.Enable = 'off';
            app.SortKspaceButton.Tooltip = {'Sort the dat in the desired number of cardiac or respiratory frames'};
            app.SortKspaceButton.Text = 'Sort k-space';
            app.CinePanel = uipanel(app.DashboardTab);
            app.CinePanel.Title = 'Cine';
            app.CINEframesLabel = uilabel(app.CinePanel);
            app.CINEframesLabel.Tooltip = {'Number of desired CINE frames'};
            app.CINEframesLabel.Text = '# CINE frames';
            app.FramesEditField = uieditfield(app.CinePanel, 'numeric');
            app.FramesEditField.Limits = [1 240];
            app.FramesEditField.RoundFractionalValues = 'on';
            app.FramesEditField.ValueDisplayFormat = '%.0f';
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.FramesEditField.Enable = 'off';
            app.FramesEditField.Value = 12;
            app.movieDropDownLabel = uilabel(app.CinePanel);
            app.movieDropDownLabel.Text = 'movie';
            app.RecoTypeDropDown = uidropdown(app.CinePanel);
            app.RecoTypeDropDown.Items = {'cardiac', 'cardiac abs', 'respiratory', 'realtime'};
            app.RecoTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.RecoTypeDropDown.Enable = 'off';
            app.RecoTypeDropDown.Tooltip = {'Data sorting:'; '- cardiac phase binning'; '- cardiac absolute binning'; '- respiratory phase binning'; '- realtime, 1 dynamic per heart beat'};
            app.RecoTypeDropDown.Value = 'cardiac';
            app.DynamicsEditField = uieditfield(app.CinePanel, 'numeric');
            app.DynamicsEditField.Limits = [1 Inf];
            app.DynamicsEditField.RoundFractionalValues = 'on';
            app.DynamicsEditField.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.DynamicsEditField.Enable = 'off';
            app.DynamicsEditField.Value = 1;
            app.nrDynamicsLabel = uilabel(app.CinePanel);
            app.nrDynamicsLabel.Text = '# dynamics';
            app.BusyImage3 = uiimage(app.DashboardTab);
            app.Lamp3 = uilamp(app.DashboardTab);
            app.AutoRecoCheckBox = uicheckbox(app.DashboardTab);
            app.AutoRecoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRecoCheckBoxValueChanged, true);
            app.AutoRecoCheckBox.Text = '';
            app.AutoRecoLabel = uilabel(app.DashboardTab);
            app.AutoRecoLabel.Text = 'auto reco';
            app.Hyperlink = uihyperlink(app.RetrospectiveUIFigure);
            app.Hyperlink.URL = 'https://www.jove.com/t/62595/quantification-mouse-heart-left-ventricular-function-myocardial';
            app.Hyperlink.Text = 'JOVE';
            app.RetrospectiveCreditLabel = uilabel(app.RetrospectiveUIFigure);
            app.RetrospectiveCreditLabel.Text = 'Retrospective can be credited by citing this paper:';
            % Show the figure after all components are created
            app.RetrospectiveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retrospective

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RetrospectiveUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.RetrospectiveUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RetrospectiveUIFigure)
        end
    end
end